classdef AnalysisTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisToolUIFigure            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfigurationTab                matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        LuNARTLabel                     matlab.ui.control.Label
        VisualizationandAnalysisToolfortheLuGREMissionLabel_5  matlab.ui.control.Label
        LuGRENavigationAnalysisandReportingToolLabel  matlab.ui.control.Label
        Image_4                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Panel_18                        matlab.ui.container.Panel
        ConsolemessagesLogPanel         matlab.ui.container.Panel
        consoleWindow                   matlab.ui.control.TextArea
        ToolstatusPanel                 matlab.ui.container.Panel
        ResetSessionButton              matlab.ui.control.Button
        SessionsettingsLamp             matlab.ui.control.Lamp
        SessionsettingsdatetimeoperatorLabel  matlab.ui.control.Label
        ReadyforexperimentsLamp         matlab.ui.control.Lamp
        ReadyforexperimentsLabel        matlab.ui.control.Label
        TelemetrydataloadingLamp        matlab.ui.control.Lamp
        InputDataLoadingLabel           matlab.ui.control.Label
        IQSdataloadingLamp              matlab.ui.control.Lamp
        ReferenceDataLoadingLabel       matlab.ui.control.Label
        SessionsettingsPanel            matlab.ui.container.Panel
        L5BitDepthEditField             matlab.ui.control.EditField
        BitDepthEditField_2Label        matlab.ui.control.Label
        L5SamplingFreqEditField         matlab.ui.control.EditField
        SamplingFrequencyMspsEditField_2Label  matlab.ui.control.Label
        L5DurationEditField             matlab.ui.control.EditField
        DurationmsEditField_2Label      matlab.ui.control.Label
        L1DurationEditField             matlab.ui.control.EditField
        DurationmsEditFieldLabel        matlab.ui.control.Label
        L1SamplingFreqEditField         matlab.ui.control.EditField
        SamplingFrequencyMspsEditFieldLabel  matlab.ui.control.Label
        L1BitDepthEditField             matlab.ui.control.EditField
        BitDepthEditFieldLabel          matlab.ui.control.Label
        SCBandsDropDown                 matlab.ui.control.DropDown
        SCBandsDropDownLabel            matlab.ui.control.Label
        SCStartEditField                matlab.ui.control.EditField
        SCStartEditFieldLabel           matlab.ui.control.Label
        RTPEndEditField                 matlab.ui.control.EditField
        RTPEndEditFieldLabel            matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        PhaseDropDown                   matlab.ui.control.DropDown
        PhaseDropDownLabel              matlab.ui.control.Label
        OPStartEditField                matlab.ui.control.EditField
        OPStartEditFieldLabel           matlab.ui.control.Label
        RTPStartEditField               matlab.ui.control.EditField
        RTPStartEditFieldLabel          matlab.ui.control.Label
        InitialAltitudeREEditField      matlab.ui.control.EditField
        InitialAltitudeREEditFieldLabel  matlab.ui.control.Label
        OperationWindowDropDown         matlab.ui.control.DropDown
        OperationWindowLabel            matlab.ui.control.Label
        L5CheckBox                      matlab.ui.control.CheckBox
        L1CheckBox                      matlab.ui.control.CheckBox
        RTPCheckBox                     matlab.ui.control.CheckBox
        L5E5Label                       matlab.ui.control.Label
        L1E1Label                       matlab.ui.control.Label
        SampleCaptureLabel              matlab.ui.control.Label
        RealTimeProcessingLabel         matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.StateButton
        OPInfoLabel                     matlab.ui.control.Label
        LoadMissionDataLabel            matlab.ui.control.Label
        Panel_17                        matlab.ui.container.Panel
        OutputDataPathLamp              matlab.ui.control.Lamp
        inspectOverrideOutputFolder_2   matlab.ui.control.Button
        OperatornameIDDropDownLabel_4   matlab.ui.control.Label
        outputDataEditField             matlab.ui.control.TextArea
        InputDataPathLamp               matlab.ui.control.Lamp
        OperatorDropDown                matlab.ui.control.DropDown
        OperatorDropDownLabel           matlab.ui.control.Label
        OperatornameIDDropDownLabel_3   matlab.ui.control.Label
        inputDataEditField              matlab.ui.control.TextArea
        inspectOverrideInputFolder      matlab.ui.control.Button
        LuGRENavigationAnalysisandReportingToolQuickLookToolLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Label_11                        matlab.ui.control.Label
        ScientificExperimentsTab        matlab.ui.container.Tab
        Q3b_completed                   matlab.ui.control.Lamp
        Q2d_completed                   matlab.ui.control.Lamp
        Q1f_completed                   matlab.ui.control.Lamp
        Q2a_completed                   matlab.ui.control.Lamp
        Q1e_completed                   matlab.ui.control.Lamp
        Q1b_completed                   matlab.ui.control.Lamp
        Q1c_completed                   matlab.ui.control.Lamp
        Q1d_completed                   matlab.ui.control.Lamp
        Q1a_completed                   matlab.ui.control.Lamp
        expOperatorField                matlab.ui.control.EditField
        OperatorEditFieldLabel_2        matlab.ui.control.Label
        expOpsWinField                  matlab.ui.control.EditField
        OpsWindowLabel                  matlab.ui.control.Label
        VisualizationandAnalysisToolfortheLuGREMissionLabel_9  matlab.ui.control.Label
        LuGRENavigationAnalysisandReportingToolLabel_2  matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        Q1aTab                          matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        ExternalInputLamp_2Label_2      matlab.ui.control.Label
        TelemetryInputLampLabel_2       matlab.ui.control.Label
        Label_123                       matlab.ui.control.Label
        Label_122                       matlab.ui.control.Label
        runPanel                        matlab.ui.container.Panel
        RunEditField_Q1a                matlab.ui.control.NumericEditField
        RunEditFieldLabel               matlab.ui.control.Label
        Label_124                       matlab.ui.control.Label
        MethodologyLabel_9              matlab.ui.control.Label
        Experiment1bLabel_8             matlab.ui.control.Label
        Label_121                       matlab.ui.control.Label
        Experiment1aLabel_19            matlab.ui.control.Label
        Experiment1aLabel_18            matlab.ui.control.Label
        Label_119                       matlab.ui.control.Label
        OperatorCommentincludedinthereportPanel  matlab.ui.container.Panel
        CommentArea_Q1a                 matlab.ui.control.TextArea
        ResultsLamp_Q1a                 matlab.ui.control.Lamp
        SaveResultsButton_Q1a           matlab.ui.control.Button
        ReportLamp_Q1a                  matlab.ui.control.Lamp
        ExperimentSuccessLamp_Q1a       matlab.ui.control.Lamp
        GenerateReportButton_Q1a        matlab.ui.control.Button
        OutputPanel_15                  matlab.ui.container.Panel
        TextArea_Q1a                    matlab.ui.control.TextArea
        RunExperimentButton_Q1a         matlab.ui.control.Button
        Label_12                        matlab.ui.control.Label
        Q1bTab                          matlab.ui.container.Tab
        ScienceProductLabel_20          matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        ExternalInputLamp_2Label        matlab.ui.control.Label
        TelemetryInputLampLabel         matlab.ui.control.Label
        ExternalInputLabel_3            matlab.ui.control.Label
        TelemetryInputLabel_3           matlab.ui.control.Label
        runPanel_Q1b                    matlab.ui.container.Panel
        RunEditField_Q1b                matlab.ui.control.NumericEditField
        RunEditField_2Label             matlab.ui.control.Label
        Experiment1bLabel_4             matlab.ui.control.Label
        SaveResultsButton_Q1b           matlab.ui.control.Button
        ReportLamp_Q1b                  matlab.ui.control.Lamp
        ResultsLamp_Q1b                 matlab.ui.control.Lamp
        OperatorCommentincludedinthereportPanel_6  matlab.ui.container.Panel
        CommentArea_Q1b                 matlab.ui.control.TextArea
        SettingsCNoThresholdPanel       matlab.ui.container.Panel
        LinkvaluesCheckBox_2            matlab.ui.control.CheckBox
        LinkvaluesCheckBox              matlab.ui.control.CheckBox
        Label_94                        matlab.ui.control.Label
        GALE5Spinner_2                  matlab.ui.control.Spinner
        GALL5Spinner_2Label_2           matlab.ui.control.Label
        GALE1Spinner_2                  matlab.ui.control.Spinner
        GALE1Label_2                    matlab.ui.control.Label
        GPSL5Spinner_2                  matlab.ui.control.Spinner
        GPSL5Spinner_2Label             matlab.ui.control.Label
        GPSL1Spinner_2                  matlab.ui.control.Spinner
        GPSL1Spinner_2Label             matlab.ui.control.Label
        Label_93                        matlab.ui.control.Label
        Label_92                        matlab.ui.control.Label
        Label_91                        matlab.ui.control.Label
        Label_90                        matlab.ui.control.Label
        Label_89                        matlab.ui.control.Label
        Label_88                        matlab.ui.control.Label
        Label_87                        matlab.ui.control.Label
        Label_86                        matlab.ui.control.Label
        Label_85                        matlab.ui.control.Label
        GALE5Spinner                    matlab.ui.control.Spinner
        GALE5Label                      matlab.ui.control.Label
        GALE1Spinner                    matlab.ui.control.Spinner
        GALE1Label                      matlab.ui.control.Label
        GPSL5Spinner                    matlab.ui.control.Spinner
        GPSL5SpinnerLabel               matlab.ui.control.Label
        GPSL1Spinner                    matlab.ui.control.Spinner
        GPSL1SpinnerLabel               matlab.ui.control.Label
        GenerateReportButton_Q1b        matlab.ui.control.Button
        RunExperimentButton_Q1b         matlab.ui.control.Button
        ExperimentSuccessLamp_Q1b       matlab.ui.control.Lamp
        OutputPanel_2                   matlab.ui.container.Panel
        TextArea_Q1b                    matlab.ui.control.TextArea
        Label_20                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        MethodologyLabel_2              matlab.ui.control.Label
        Experiment1bLabel               matlab.ui.control.Label
        Experiment1aLabel_3             matlab.ui.control.Label
        Q1cTab                          matlab.ui.container.Tab
        Panel_7                         matlab.ui.container.Panel
        ExternalInputLampLabel          matlab.ui.control.Label
        TelemetryInputLamp_2Label       matlab.ui.control.Label
        ExternalInputLabel_16           matlab.ui.control.Label
        TelemetryInputLabel_4           matlab.ui.control.Label
        runPanel_Q1c                    matlab.ui.container.Panel
        RunEditField_Q1c                matlab.ui.control.NumericEditField
        RunEditField_3Label             matlab.ui.control.Label
        Experiment1bLabel_5             matlab.ui.control.Label
        ScienceProductLabel_4           matlab.ui.control.Label
        Label_106                       matlab.ui.control.Label
        SaveResultsButton_Q1c           matlab.ui.control.Button
        ReportLamp_Q1c                  matlab.ui.control.Lamp
        ResultsLamp_Q1c                 matlab.ui.control.Lamp
        OperatorCommentincludedinthereportPanel_7  matlab.ui.container.Panel
        CommentArea_Q1c                 matlab.ui.control.TextArea
        Label_96                        matlab.ui.control.Label
        GenerateReportButton_Q1c        matlab.ui.control.Button
        RunExperimentButton_Q1c         matlab.ui.control.Button
        MethodologyLabel_3              matlab.ui.control.Label
        ExperimentSuccessLamp_Q1c       matlab.ui.control.Lamp
        OutputPanel_3                   matlab.ui.container.Panel
        TextArea_Q1c                    matlab.ui.control.TextArea
        Experiment1cLabel               matlab.ui.control.Label
        Experiment1aLabel_4             matlab.ui.control.Label
        Q1dTab                          matlab.ui.container.Tab
        RunExperimentButton_Q1d         matlab.ui.control.Button
        Panel_8                         matlab.ui.container.Panel
        TelemetryInputLamp_2Label_2     matlab.ui.control.Label
        TelemetryInputLamp_3Label_2     matlab.ui.control.Label
        ExternalInputLabel              matlab.ui.control.Label
        TelemetryInputLabel             matlab.ui.control.Label
        Label_126                       matlab.ui.control.Label
        runPanel_Q1d                    matlab.ui.container.Panel
        RunEditField_Q1d                matlab.ui.control.NumericEditField
        RunEditField_4Label             matlab.ui.control.Label
        Experiment1bLabel_7             matlab.ui.control.Label
        Experiment1bLabel_6             matlab.ui.control.Label
        UITable_Q1d                     matlab.ui.control.Table
        PseudorangeerroranalysisLabel   matlab.ui.control.Label
        OperatorComment_4               matlab.ui.container.Panel
        CommentArea_Q1d                 matlab.ui.control.TextArea
        ResultsLamp_Q1d                 matlab.ui.control.Lamp
        SaveResultsButton_Q1d           matlab.ui.control.Button
        ReportLamp_Q1d                  matlab.ui.control.Lamp
        GenerateReportButton_Q1d        matlab.ui.control.Button
        ScienceProductLabel             matlab.ui.control.Label
        ExperimentSuccessLamp_Q1d       matlab.ui.control.Lamp
        OutputPanel_4                   matlab.ui.container.Panel
        TextArea_Q1d                    matlab.ui.control.TextArea
        Label_24                        matlab.ui.control.Label
        Experiment1dLabel               matlab.ui.control.Label
        Experiment1aLabel_5             matlab.ui.control.Label
        Q1eTab                          matlab.ui.container.Tab
        Panel_9                         matlab.ui.container.Panel
        TelemetryInputLamp_2Label_3     matlab.ui.control.Label
        TelemetryInputLamp_3Label_3     matlab.ui.control.Label
        ExternalInputLabel_17           matlab.ui.control.Label
        TelemetryInputLabel_18          matlab.ui.control.Label
        Experiment1bLabel_9             matlab.ui.control.Label
        ScienceProductLabel_16          matlab.ui.control.Label
        runPanel_Q1e                    matlab.ui.container.Panel
        RunEditField_Q1e                matlab.ui.control.NumericEditField
        RunEditField_5Label             matlab.ui.control.Label
        Label_107                       matlab.ui.control.Label
        SaveResultsButton_Q1e           matlab.ui.control.Button
        ResultsLamp_Q1e                 matlab.ui.control.Lamp
        ReportLamp_Q1e                  matlab.ui.control.Lamp
        OperatorCommentincludedinthereportPanel_10  matlab.ui.container.Panel
        CommentArea_Q1e                 matlab.ui.control.TextArea
        GenerateReportButton_Q1e        matlab.ui.control.Button
        RunExperimentButton_Q1e         matlab.ui.control.Button
        Label_42                        matlab.ui.control.Label
        MethodologyLabel_5              matlab.ui.control.Label
        ExperimentSuccessLamp_Q1e       matlab.ui.control.Lamp
        OutputPanel_5                   matlab.ui.container.Panel
        TextArea_Q1e                    matlab.ui.control.TextArea
        Experiment1eLabel               matlab.ui.control.Label
        Experiment1aLabel_6             matlab.ui.control.Label
        Q1fTab                          matlab.ui.container.Tab
        L5E5OutliersLabel               matlab.ui.control.Label
        L1E1OutliersLabel               matlab.ui.control.Label
        UITable_Q1f_L5                  matlab.ui.control.Table
        UITable_Q1f_L1                  matlab.ui.control.Table
        SettingsPanel                   matlab.ui.container.Panel
        OutliersdetectionButtonGroup    matlab.ui.container.ButtonGroup
        ThresholdSpinner                matlab.ui.control.Spinner
        ThresholdSpinnerLabel           matlab.ui.control.Label
        WindowSpinner                   matlab.ui.control.Spinner
        WindowSpinnerLabel              matlab.ui.control.Label
        FCMCButtonGroup                 matlab.ui.container.ButtonGroup
        DetrendedButton                 matlab.ui.control.RadioButton
        RawButton                       matlab.ui.control.RadioButton
        Label_141                       matlab.ui.control.Label
        SaveResultsButton_Q1f           matlab.ui.control.Button
        ResultsLamp_Q1f                 matlab.ui.control.Lamp
        ReportLamp_Q1f                  matlab.ui.control.Lamp
        GenerateReportButton_Q1f        matlab.ui.control.Button
        OperatorCommentincludedinthereportPanel_11  matlab.ui.container.Panel
        CommentArea_Q1f                 matlab.ui.control.TextArea
        OutputPanel_19                  matlab.ui.container.Panel
        TextArea_Q1f                    matlab.ui.control.TextArea
        runPanel_Q1f                    matlab.ui.container.Panel
        RunEditField_Q1f                matlab.ui.control.NumericEditField
        RunEditField_5Label_2           matlab.ui.control.Label
        Experiment1bLabel_14            matlab.ui.control.Label
        MethodologyLabel_14             matlab.ui.control.Label
        Panel_14                        matlab.ui.container.Panel
        ExternalInputLabel_8            matlab.ui.control.Label
        TelemetryInputLabel_8           matlab.ui.control.Label
        TelemetryInputLamp_2Label_6     matlab.ui.control.Label
        TelemetryInputLamp_3Label_4     matlab.ui.control.Label
        ScienceProductLabel_8           matlab.ui.control.Label
        Label_109                       matlab.ui.control.Label
        RunExperimentButton_Q1f         matlab.ui.control.Button
        ExperimentSuccessLamp_Q1f       matlab.ui.control.Lamp
        Experiment1fLabel               matlab.ui.control.Label
        Experiment1aLabel_7             matlab.ui.control.Label
        Q2aTab                          matlab.ui.container.Tab
        ReferenceFrame_Q2a              matlab.ui.control.Switch
        ReferenceFrameSwitchLabel       matlab.ui.control.Label
        runPanel_6                      matlab.ui.container.Panel
        RunEditField_Q2a                matlab.ui.control.NumericEditField
        RunEditField_6Label             matlab.ui.control.Label
        Experiment1bLabel_10            matlab.ui.control.Label
        ScienceProductLabel_17          matlab.ui.control.Label
        Label_127                       matlab.ui.control.Label
        MethodologyLabel_10             matlab.ui.control.Label
        Panel_10                        matlab.ui.container.Panel
        TelemetryInputLamp_2Label_4     matlab.ui.control.Label
        ExternalInputLamp_5Label        matlab.ui.control.Label
        ExternalInputLabel_18           matlab.ui.control.Label
        TelemetryInputLabel_21          matlab.ui.control.Label
        Label_108                       matlab.ui.control.Label
        SaveResultsButton_Q2a           matlab.ui.control.Button
        ReportLamp_Q2a                  matlab.ui.control.Lamp
        ResultsLamp_Q2a                 matlab.ui.control.Lamp
        OperatorCommentincludedinthereportPanel_12  matlab.ui.container.Panel
        CommentArea_Q2a                 matlab.ui.control.TextArea
        GenerateReportButton_Q2a        matlab.ui.control.Button
        RunExperimentButton_Q2a         matlab.ui.control.Button
        ExperimentSuccessLamp_Q2a       matlab.ui.control.Lamp
        OutputPanel_8                   matlab.ui.container.Panel
        TextArea_Q2a                    matlab.ui.control.TextArea
        Experiment2aLabel               matlab.ui.control.Label
        Experiment1aLabel_9             matlab.ui.control.Label
        Q2dTab                          matlab.ui.container.Tab
        TimeseriesofDOPmetricsfromtheTelemetryisplottedLabel  matlab.ui.control.Label
        MethodologyLabel_12             matlab.ui.control.Label
        runPanel_8                      matlab.ui.container.Panel
        SettingsLabel                   matlab.ui.control.Label
        StartingEpochEditField          matlab.ui.control.NumericEditField
        StartingEpochEditFieldLabel     matlab.ui.control.Label
        Experiment1bLabel_11            matlab.ui.control.Label
        Label_135                       matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        ExternalInputLamp_7Label        matlab.ui.control.Label
        TelemetryInputLamp_8Label       matlab.ui.control.Label
        Label_134                       matlab.ui.control.Label
        Label_133                       matlab.ui.control.Label
        runPanel_Q2d                    matlab.ui.container.Panel
        RunEditField_Q2d                matlab.ui.control.NumericEditField
        RunEditField_8Label             matlab.ui.control.Label
        SaveResultsButton_Q2d           matlab.ui.control.Button
        ReportLamp_Q2d                  matlab.ui.control.Lamp
        ResultsLamp_Q2d                 matlab.ui.control.Lamp
        GenerateReportButton_Q2d        matlab.ui.control.Button
        RunExperimentButton_Q2d         matlab.ui.control.Button
        ExperimentSuccessLamp_Q2d       matlab.ui.control.Lamp
        OperatorCommentincludedinthereportPanel_3  matlab.ui.container.Panel
        CommentArea_Q2d                 matlab.ui.control.TextArea
        OutputPanel_16                  matlab.ui.container.Panel
        TextArea_Q2d                    matlab.ui.control.TextArea
        Label_98                        matlab.ui.control.Label
        Experiment2dLabel               matlab.ui.control.Label
        Experiment1aLabel_16            matlab.ui.control.Label
        Q3bTab                          matlab.ui.container.Tab
        WARNINGLabel_q1d_3              matlab.ui.control.Label
        GalE1bLabel                     matlab.ui.control.Label
        GPSL1CALabel                    matlab.ui.control.Label
        UITable_Q3b_Galileo             matlab.ui.control.Table
        UITable_Q3b_GPS                 matlab.ui.control.Table
        GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2  matlab.ui.container.Panel
        CodeDopplerCheckBoxE1B          matlab.ui.control.CheckBox
        CodeDopplerCheckBoxG1C          matlab.ui.control.CheckBox
        CodeDopplerSearchLabel          matlab.ui.control.Label
        DopplerrangeHzEditField         matlab.ui.control.NumericEditField
        DopplerrangeHzEditFieldLabel    matlab.ui.control.Label
        DopplerStepEditField_23         matlab.ui.control.NumericEditField
        DopplercenteroffsetHzEditField  matlab.ui.control.NumericEditField
        DopplercenteroffsetHzEditFieldLabel  matlab.ui.control.Label
        DopplerStepEditField_22         matlab.ui.control.NumericEditField
        TelemetryInputLamp_4Label_7     matlab.ui.control.Label
        TelemetryInputLamp_4Label_6     matlab.ui.control.Label
        SelectGNSSsignalsandacquisitionparametersLabel_2  matlab.ui.control.Label
        FalseAlarmProbabilityEditField_12  matlab.ui.control.NumericEditField
        CoherentIntegrationtimeEditField_12  matlab.ui.control.NumericEditField
        NonCoherentsumsEditField_12     matlab.ui.control.NumericEditField
        DopplerStepEditField_12         matlab.ui.control.NumericEditField
        E1BCheckBox_2                   matlab.ui.control.CheckBox
        G1CCheckBox_2                   matlab.ui.control.CheckBox
        FalseAlarmProbabilityEditField_8  matlab.ui.control.NumericEditField
        FalseAlarmProbabilityEditField_8Label  matlab.ui.control.Label
        CoherentIntegrationtimesEditField  matlab.ui.control.NumericEditField
        CoherentIntegrationtimesEditFieldLabel  matlab.ui.control.Label
        NonCoherentsumsEditField_8      matlab.ui.control.NumericEditField
        NonCoherentsumsEditField_8Label  matlab.ui.control.Label
        DopplerstepHzEditField          matlab.ui.control.NumericEditField
        DopplerstepHzEditFieldLabel     matlab.ui.control.Label
        Experiment1bLabel_16            matlab.ui.control.Label
        ScienceProductLabel_23          matlab.ui.control.Label
        Label_145                       matlab.ui.control.Label
        MethodologyLabel_16             matlab.ui.control.Label
        Panel_16                        matlab.ui.container.Panel
        ExternalInputLamp_2Label_3      matlab.ui.control.Label
        TelemetryInputLampLabel_3       matlab.ui.control.Label
        Label_144                       matlab.ui.control.Label
        Label_143                       matlab.ui.control.Label
        runPanel_Q3b                    matlab.ui.container.Panel
        RunEditField_Q3b                matlab.ui.control.NumericEditField
        RunEditField_8Label_3           matlab.ui.control.Label
        SaveResultsButton_Q3b           matlab.ui.control.Button
        ReportLamp_Q3b                  matlab.ui.control.Lamp
        ResultsLamp_Q3b                 matlab.ui.control.Lamp
        GenerateReportButton_Q3b        matlab.ui.control.Button
        OperatorCommentincludedinthereportPanel_9  matlab.ui.container.Panel
        CommentArea_Q3b                 matlab.ui.control.TextArea
        OutputPanel_21                  matlab.ui.container.Panel
        TextArea_Q3b                    matlab.ui.control.TextArea
        Label_115                       matlab.ui.control.Label
        RunExperimentButton_Q3b         matlab.ui.control.Button
        ExperimentSuccessLamp_Q3b       matlab.ui.control.Lamp
        Experiment3bLabel               matlab.ui.control.Label
        Experiment1aLabel_14            matlab.ui.control.Label
        OperationSummaryReportOSRTab    matlab.ui.container.Tab
        VisualizationandAnalysisToolfortheLuGREMissionLabel_14  matlab.ui.control.Label
        LuGRENavigationAnalysisandReportingToolLabel_3  matlab.ui.control.Label
        expOpsWinField_2                matlab.ui.control.EditField
        OpsWindowLabel_2                matlab.ui.control.Label
        expOperatorField_2              matlab.ui.control.EditField
        OperatorEditFieldLabel_3        matlab.ui.control.Label
        OPSSummaryReportOSRPanel        matlab.ui.container.Panel
        OSR_operatorComments            matlab.ui.container.Panel
        OSR_comments                    matlab.ui.control.TextArea
        CheckBox_Q1c                    matlab.ui.control.CheckBox
        EditField_Q1d_OSR               matlab.ui.control.EditField
        EditField_Q2d_OSR               matlab.ui.control.EditField
        CheckBox_Q2d                    matlab.ui.control.CheckBox
        EditField_Q3b_OSR               matlab.ui.control.EditField
        EditField_Q3a_OSR               matlab.ui.control.EditField
        EditField_Q2c_OSR               matlab.ui.control.EditField
        EditField_Q2a_OSR               matlab.ui.control.EditField
        EditField_Q1f_OSR               matlab.ui.control.EditField
        EditField_Q1e_OSR               matlab.ui.control.EditField
        EditField_Q1c_OSR               matlab.ui.control.EditField
        EditField_Q1b_OSR               matlab.ui.control.EditField
        EditField_Q1a_OSR               matlab.ui.control.EditField
        Objective1Label_2               matlab.ui.control.Label
        Objective2Label_2               matlab.ui.control.Label
        Objective3Label_3               matlab.ui.control.Label
        CheckBox_Q3b                    matlab.ui.control.CheckBox
        CheckBox_Q3a                    matlab.ui.control.CheckBox
        CheckBox_Q2c                    matlab.ui.control.CheckBox
        CheckBox_Q2a                    matlab.ui.control.CheckBox
        CheckBox_Q1f                    matlab.ui.control.CheckBox
        CheckBox_Q1e                    matlab.ui.control.CheckBox
        CheckBox_Q1d                    matlab.ui.control.CheckBox
        CheckBox_Q1b                    matlab.ui.control.CheckBox
        CheckBox_Q1a                    matlab.ui.control.CheckBox
        OSRLamp                         matlab.ui.control.Lamp
        GenerateReportButton_OSR        matlab.ui.control.Button
        ReportselectionPanel            matlab.ui.container.Panel
        RestorelistsButton              matlab.ui.control.Button
        Objective3Label                 matlab.ui.control.Label
        Objective2Label                 matlab.ui.control.Label
        Objective1Label                 matlab.ui.control.Label
        ListBox_Q3b                     matlab.ui.control.ListBox
        Q3bListBoxLabel                 matlab.ui.control.Label
        ListBox_Q3a                     matlab.ui.control.ListBox
        Q3aListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q3b                matlab.ui.control.Button
        AddReport_Q3b                   matlab.ui.control.Button
        OpenReport_Q3b                  matlab.ui.control.Button
        DeleteReport_Q3a                matlab.ui.control.Button
        AddReport_Q3a                   matlab.ui.control.Button
        OpenReport_Q3a                  matlab.ui.control.Button
        ListBox_Q2d                     matlab.ui.control.ListBox
        Q2dListBoxLabel                 matlab.ui.control.Label
        ListBox_Q2c                     matlab.ui.control.ListBox
        Q2cListBoxLabel                 matlab.ui.control.Label
        ListBox_Q2a                     matlab.ui.control.ListBox
        Q2aListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q2d                matlab.ui.control.Button
        AddReport_Q2d                   matlab.ui.control.Button
        OpenReport_Q2d                  matlab.ui.control.Button
        DeleteReport_Q2c                matlab.ui.control.Button
        AddReport_Q2c                   matlab.ui.control.Button
        OpenReport_Q2c                  matlab.ui.control.Button
        DeleteReport_Q2a                matlab.ui.control.Button
        AddReport_Q2a                   matlab.ui.control.Button
        OpenReport_Q2a                  matlab.ui.control.Button
        ListBox_Q1f                     matlab.ui.control.ListBox
        Q1fListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q1f                matlab.ui.control.Button
        AddReport_Q1f                   matlab.ui.control.Button
        OpenReport_Q1f                  matlab.ui.control.Button
        ListBox_Q1e                     matlab.ui.control.ListBox
        Q1eListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q1e                matlab.ui.control.Button
        AddReport_Q1e                   matlab.ui.control.Button
        OpenReport_Q1e                  matlab.ui.control.Button
        ListBox_Q1d                     matlab.ui.control.ListBox
        Q1dListBoxLabel                 matlab.ui.control.Label
        DeleteReportQ1d                 matlab.ui.control.Button
        AddReport_Q1d                   matlab.ui.control.Button
        OpenReport_Q1d                  matlab.ui.control.Button
        ListBox_Q1c                     matlab.ui.control.ListBox
        Q1cListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q1c                matlab.ui.control.Button
        AddReport_Q1c                   matlab.ui.control.Button
        OpenReport_Q1c                  matlab.ui.control.Button
        ListBox_Q1b                     matlab.ui.control.ListBox
        Q1bListBoxLabel                 matlab.ui.control.Label
        DeleteReport_Q1b                matlab.ui.control.Button
        AddReport_Q1b                   matlab.ui.control.Button
        OpenReport_Q1b                  matlab.ui.control.Button
        DeleteReport_Q1a                matlab.ui.control.Button
        AddReport_Q1a                   matlab.ui.control.Button
        OpenReport_Q1a                  matlab.ui.control.Button
        ListBox_Q1a                     matlab.ui.control.ListBox
        Q1aListBoxLabel                 matlab.ui.control.Label
    end

    properties (Access = private)
        %--- Names of files of parsed data
        navFileName         % navSolutions file name
        obsFileName         % obsSolutions file name
        acqFileName         % acqSolutions file name
        folderName          % temporary folder for all parsed data
        iqsFileList         % List of IQS files

        %--- Reference data structs
        refObsFileName      % obsSolutions file name for reference data
        refNavFileName      % navSolutions file name for reference data

        %--- Plot informations
        PRN                 % struct: PRN and the color triplet
        maxLimit            % keep track of max epochs
        epochDate           % table of the Epochs dates
        ticksVector         % vector with the values of the slider ticks

        %--- Colors
        colorGreen          % Global lamp color name
        colorYellow         % Global lamp color name
        colorRed            % Global lamp color name
        colorGray           % Global lamp color name
        colorBgGray         % Global background color name

        %--- Session information
        figurePaths         % Global repo for experiment outputs
        currentOperator     % Selected operator name/id for the current session
        currentOpsWindow    % Selected ops windows to be investigated in the current session
        opsWinNum           % Numerical identifier for run counters
        sessionSettings     % Tool configuration (hard coded on .mat file)
        expCounters         % It counts the number of runs for each experiment
        expCompleted        % Keeps track of experiments sent to POC for each window
        logFileId           % Log file identifier (fopen)
        tmpConfigData       % Temporary container for experiment configuration data
    end

    properties (Access = public)
        OSR                       % It cointains metadata of relevant quick experiment reports (QER) to be included in the OSR
        OSR_reportList = [];      % Contains the path of the selected quick experiment reports (QER) to be concatenated
        currentWindowOSR;         % It stores the name of the current OP's OSR generated
    end

    methods (Access = private)

        %--- Function that updates the Console Message Text Box
        function updateConsoleBox(app,newMessage)
            consoleText = app.consoleWindow.Value;
            messageDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"] ");

            %--- Update console
            if isempty(consoleText{1})
                app.consoleWindow.Value = sprintf(strcat(messageDate,newMessage));
            else
                app.consoleWindow.Value = [app.consoleWindow.Value; strcat(messageDate,newMessage)];
            end
            %--- Print on the log file
            fprintf(app.logFileId, strcat(messageDate,newMessage,'\n'));
            %--- Scroll text area to the bottom
            scroll(app.consoleWindow, 'bottom')
        end

        % Functions to reset GUI panels
        function resetOpsWinInputDataPanel(app)
            app.RTPCheckBox.Value = false;
            app.L1CheckBox.Value  = false;
            app.L5CheckBox.Value  = false;
        end


        function resetExpButtons(app)
            app.SaveResultsButton_Q1a.Enable = "off";
            app.GenerateReportButton_Q1a.Enable = "off";

            app.SaveResultsButton_Q1b.Enable = "off";
            app.GenerateReportButton_Q1b.Enable = "off";

            app.SaveResultsButton_Q1c.Enable = "off";
            app.GenerateReportButton_Q1c.Enable = "off";

            app.SaveResultsButton_Q1d.Enable = "off";
            app.GenerateReportButton_Q1d.Enable = "off";

            app.SaveResultsButton_Q1e.Enable = "off";
            app.GenerateReportButton_Q1e.Enable = "off";

            app.SaveResultsButton_Q1f.Enable = "off";
            app.GenerateReportButton_Q1f.Enable = "off";

            app.SaveResultsButton_Q2a.Enable = "off";
            app.GenerateReportButton_Q2a.Enable = "off";

            app.SaveResultsButton_Q2d.Enable = "off";
            app.GenerateReportButton_Q2d.Enable = "off";

            app.SaveResultsButton_Q3b.Enable = "off";
            app.GenerateReportButton_Q3b.Enable = "off";
        end

        function resetExpLamps(app)
            app.ExperimentSuccessLamp_Q1a.Color = app.colorGray;
            app.ResultsLamp_Q1a.Color = app.colorGray;
            app.ReportLamp_Q1a.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1b.Color = app.colorGray;
            app.ResultsLamp_Q1b.Color = app.colorGray;
            app.ReportLamp_Q1b.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1c.Color = app.colorGray;
            app.ResultsLamp_Q1c.Color = app.colorGray;
            app.ReportLamp_Q1c.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1d.Color = app.colorGray;
            app.ResultsLamp_Q1d.Color = app.colorGray;
            app.ReportLamp_Q1d.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1e.Color = app.colorGray;
            app.ResultsLamp_Q1e.Color = app.colorGray;
            app.ReportLamp_Q1e.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q1f.Color = app.colorGray;
            app.ResultsLamp_Q1f.Color = app.colorGray;
            app.ReportLamp_Q1f.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q2a.Color = app.colorGray;
            app.ResultsLamp_Q2a.Color = app.colorGray;
            app.ReportLamp_Q2a.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q2d.Color = app.colorGray;
            app.ResultsLamp_Q2d.Color = app.colorGray;
            app.ReportLamp_Q2d.Color = app.colorGray;

            app.ExperimentSuccessLamp_Q3b.Color = app.colorGray;
            app.ResultsLamp_Q3b.Color = app.colorGray;
            app.ReportLamp_Q3b.Color = app.colorGray;
        end

        function resetExpText(app)
            app.TextArea_Q1a.Value = strings;
            app.CommentArea_Q1a.Value = "No relevant comments.";

            app.TextArea_Q1b.Value = strings;
            app.CommentArea_Q1b.Value = "No relevant comments.";

            app.TextArea_Q1c.Value = strings;
            app.CommentArea_Q1c.Value = "No relevant comments.";

            app.TextArea_Q1d.Value = strings;
            app.CommentArea_Q1d.Value = "No relevant comments.";

            app.TextArea_Q1e.Value = strings;
            app.CommentArea_Q1e.Value = "No relevant comments.";

            app.TextArea_Q1f.Value = strings;
            app.CommentArea_Q1f.Value = "No relevant comments.";

            app.TextArea_Q2a.Value = strings;
            app.CommentArea_Q2a.Value = "No relevant comments.";

            app.TextArea_Q2d.Value = strings;
            app.CommentArea_Q2d.Value = "No relevant comments.";

            app.TextArea_Q3b.Value = strings;
            app.CommentArea_Q3b.Value = "No relevant comments.";
        end

        function loadCountersCompleted(app)
            %--- Update all run IDs in experiment tabs
            app.RunEditField_Q1a.Value = app.expCounters(app.opsWinNum,1);
            app.RunEditField_Q1b.Value = app.expCounters(app.opsWinNum,2);
            app.RunEditField_Q1c.Value = app.expCounters(app.opsWinNum,3);
            app.RunEditField_Q1d.Value = app.expCounters(app.opsWinNum,4);
            app.RunEditField_Q1e.Value = app.expCounters(app.opsWinNum,5);
            app.RunEditField_Q1f.Value = app.expCounters(app.opsWinNum,6);
            app.RunEditField_Q2a.Value = app.expCounters(app.opsWinNum,8);
            app.RunEditField_Q2d.Value = app.expCounters(app.opsWinNum,11);
            app.RunEditField_Q3b.Value = app.expCounters(app.opsWinNum,15);

            % color all lamps if exp have been completed already
            if app.expCompleted(app.opsWinNum,1) == 1
                app.Q1a_completed.Color = app.colorGreen;
            else
                app.Q1a_completed.Color = app.colorBgGray;
            end
            if app.expCompleted(app.opsWinNum,2) == 1
                app.Q1b_completed.Color = app.colorGreen;
            else
                app.Q1b_completed.Color = app.colorBgGray;
            end
            if app.expCompleted(app.opsWinNum,3) == 1
                app.Q1c_completed.Color = app.colorGreen;
            else
                app.Q1c_completed.Color = app.colorBgGray;
            end
            if app.expCompleted(app.opsWinNum,4) == 1
                app.Q1d_completed.Color = app.colorGreen;
            else
                app.Q1d_completed.Color = app.colorBgGray;
            end
            if app.expCompleted(app.opsWinNum,5) == 1
                app.Q1e_completed.Color = app.colorGreen;
            else
                app.Q1e_completed.Color = app.colorBgGray;
            end
            if app.expCompleted(app.opsWinNum,6) == 1
                app.Q1f_completed.Color = app.colorGreen;
            else
                app.Q1f_completed.Color = app.colorBgGray;
            end
            if app.expCompleted(app.opsWinNum,8) == 1
                app.Q2a_completed.Color = app.colorGreen;
            else
                app.Q2a_completed.Color = app.colorBgGray;
            end
            if app.expCompleted(app.opsWinNum,11) == 1
                app.Q2d_completed.Color = app.colorGreen;
            else
                app.Q2d_completed.Color = app.colorBgGray;
            end
            if app.expCompleted(app.opsWinNum,15) == 1
                app.Q3b_completed.Color = app.colorGreen;
            else
                app.Q3b_completed.Color = app.colorBgGray;
            end
        end

        function resetCountersCompleted(app)
            app.RunEditField_Q1a.Value = 0;
            app.Q1a_completed.Color = app.colorGray;

            app.RunEditField_Q1b.Value = 0;
            app.Q1b_completed.Color = app.colorGray;

            app.RunEditField_Q1c.Value = 0;
            app.Q1c_completed.Color = app.colorGray;

            app.RunEditField_Q1d.Value = 0;
            app.Q1d_completed.Color = app.colorGray;

            app.RunEditField_Q1e.Value = 0;
            app.Q1e_completed.Color = app.colorGray;

            app.RunEditField_Q1f.Value = 0;
            app.Q1f_completed.Color = app.colorGray;

            app.RunEditField_Q2a.Value = 0;
            app.Q2a_completed.Color = app.colorGray;

            app.RunEditField_Q2d.Value = 0;
            app.Q2d_completed.Color = app.colorGray;

            app.RunEditField_Q3b.Value = 0;
            app.Q3b_completed.Color = app.colorGray;
        end

        function loadReportList(app)
            try
                C = load(strcat("settings",filesep,"OSR.mat"));
                app.OSR = C.OSR_data;

                if ~isempty(app.OSR.(app.currentOpsWindow).Q1a)
                    app.ListBox_Q1a.Items = flipud(string(app.OSR.(app.currentOpsWindow).Q1a(:,4)));
                end
                if ~isempty(app.OSR.(app.currentOpsWindow).Q1b)
                    app.ListBox_Q1b.Items = flipud(string(app.OSR.(app.currentOpsWindow).Q1b(:,4)));
                end
                if ~isempty(app.OSR.(app.currentOpsWindow).Q1c)
                    app.ListBox_Q1c.Items = flipud(string(app.OSR.(app.currentOpsWindow).Q1c(:,4)));
                end
                if ~isempty(app.OSR.(app.currentOpsWindow).Q1d)
                    app.ListBox_Q1d.Items = flipud(string(app.OSR.(app.currentOpsWindow).Q1d(:,4)));
                end
                if ~isempty(app.OSR.(app.currentOpsWindow).Q1e)
                    app.ListBox_Q1e.Items = flipud(string(app.OSR.(app.currentOpsWindow).Q1e(:,4)));
                end
                if ~isempty(app.OSR.(app.currentOpsWindow).Q1f)
                    app.ListBox_Q1f.Items = flipud(string(app.OSR.(app.currentOpsWindow).Q1f(:,4)));
                end
                if ~isempty(app.OSR.(app.currentOpsWindow).Q2a)
                    app.ListBox_Q2a.Items = flipud(string(app.OSR.(app.currentOpsWindow).Q2a(:,4)));
                end
                if ~isempty(app.OSR.(app.currentOpsWindow).Q2d)
                    app.ListBox_Q2d.Items = flipud(string(app.OSR.(app.currentOpsWindow).Q2d(:,4)));
                end
                if ~isempty(app.OSR.(app.currentOpsWindow).Q3b)
                    app.ListBox_Q3b.Items = flipud(string(app.OSR.(app.currentOpsWindow).Q3b(:,4)));
                end
            catch
                app.OSR.(app.currentOpsWindow).Q1a = [];
                app.ListBox_Q1a.Items = {};
                app.OSR.(app.currentOpsWindow).Q1b = [];
                app.ListBox_Q1b.Items = {};
                app.OSR.(app.currentOpsWindow).Q1c = [];
                app.ListBox_Q1c.Items = {};
                app.OSR.(app.currentOpsWindow).Q1d = [];
                app.ListBox_Q1d.Items = {};
                app.OSR.(app.currentOpsWindow).Q1e = [];
                app.ListBox_Q1e.Items = {};
                app.OSR.(app.currentOpsWindow).Q1f = [];
                app.ListBox_Q1f.Items = {};
                app.OSR.(app.currentOpsWindow).Q2a = [];
                app.ListBox_Q2a.Items = {};
                app.OSR.(app.currentOpsWindow).Q2d = [];
                app.ListBox_Q2d.Items = {};
                app.OSR.(app.currentOpsWindow).Q3b = [];
                app.ListBox_Q3b.Items = {};
            end

            app.EditField_Q1a_OSR.Value = "";
            app.EditField_Q1b_OSR.Value = "";
            app.EditField_Q1c_OSR.Value = "";
            app.EditField_Q1d_OSR.Value = "";
            app.EditField_Q1e_OSR.Value = "";
            app.EditField_Q1f_OSR.Value = "";
            app.EditField_Q2a_OSR.Value = "";
            app.EditField_Q2d_OSR.Value = "";
            app.EditField_Q3b_OSR.Value = "";
        end

        function resetOSRPanel(app)

            app.ListBox_Q1a.Items = {};
            app.ListBox_Q1b.Items = {};
            app.ListBox_Q1c.Items = {};
            app.ListBox_Q1d.Items = {};
            app.ListBox_Q1e.Items = {};
            app.ListBox_Q1f.Items = {};
            app.ListBox_Q2a.Items = {};
            app.ListBox_Q2d.Items = {};
            app.ListBox_Q3b.Items = {};

            app.ListBox_Q1a.Value = {};
            app.ListBox_Q1b.Value = {};
            app.ListBox_Q1c.Value = {};
            app.ListBox_Q1d.Value = {};
            app.ListBox_Q1e.Value = {};
            app.ListBox_Q1f.Value = {};
            app.ListBox_Q2a.Value = {};
            app.ListBox_Q2d.Value = {};
            app.ListBox_Q3b.Value = {};

            app.CheckBox_Q1a.Value = false;
            app.CheckBox_Q1b.Value = false;
            app.CheckBox_Q1c.Value = false;
            app.CheckBox_Q1d.Value = false;
            app.CheckBox_Q1e.Value = false;
            app.CheckBox_Q1f.Value = false;
            app.CheckBox_Q2a.Value = false;
            app.CheckBox_Q2d.Value = false;
            app.CheckBox_Q3b.Value = false;

            app.EditField_Q1a_OSR.Value = "";
            app.EditField_Q1b_OSR.Value = "";
            app.EditField_Q1c_OSR.Value = "";
            app.EditField_Q1d_OSR.Value = "";
            app.EditField_Q1e_OSR.Value = "";
            app.EditField_Q1f_OSR.Value = "";
            app.EditField_Q2a_OSR.Value = "";
            app.EditField_Q2d_OSR.Value = "";
            app.EditField_Q3b_OSR.Value = "";

            app.OSR_comments.Value = "No relevant comments.";
        end

        function lockConfiguration(app)
            %--- Disable operator and window selection
            app.OperatorDropDown.Enable  = false;
            app.OperationWindowDropDown.Enable = false;
        end

        function enableExperimentsRunButtons(app)
            app.RunExperimentButton_Q1a.Enable = true;
            app.RunExperimentButton_Q1b.Enable = true;
            app.RunExperimentButton_Q1c.Enable = true;
            app.RunExperimentButton_Q1d.Enable = true;
            app.RunExperimentButton_Q1e.Enable = true;
            app.RunExperimentButton_Q1f.Enable = true;
            app.RunExperimentButton_Q2a.Enable = true;
            app.RunExperimentButton_Q2d.Enable = true;
            app.RunExperimentButton_Q3b.Enable = true;
        end

        function disableExperimentsRunButtons(app)
            app.RunExperimentButton_Q1a.Enable = false;
            app.RunExperimentButton_Q1b.Enable = false;
            app.RunExperimentButton_Q1c.Enable = false;
            app.RunExperimentButton_Q1d.Enable = false;
            app.RunExperimentButton_Q1e.Enable = false;
            app.RunExperimentButton_Q1f.Enable = false;
            app.RunExperimentButton_Q2a.Enable = false;
            app.RunExperimentButton_Q2d.Enable = false;
            app.RunExperimentButton_Q3b.Enable = false;
        end

        function updateProgressBar(app,event,d,value,msg)
            %--- Check if user clicked Cancel
            if d.CancelRequested
                %--- Close the dialog
                close(d);
                %--- Exit the loop or function
                disp('Process canceled by the user.');
                ResetSessionButtonPushed(app, event);
                return;
            else
                d.Value = value;
                d.Message = msg;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Add folders to path
            if (~isdeployed)
                addpath(genpath(['.' filesep]));
            end

            %% Open/create log file
            try
                %--- Open existing log file and append content
                app.logFileId = fopen(strcat(".",filesep,"utilities",filesep,"log.txt"),'a');

                messageDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"] ");
                fprintf(app.logFileId,"########################################################################################\n");
                fprintf(app.logFileId,strcat(messageDate,"LuNART-Q v0.1 - Activity Log\n"));
                fprintf(app.logFileId,"----------------------------------------------------------------------------------------\n");

                updateConsoleBox(app,"LuNART-Q is logging the session activities.");
            catch
                warndlg('Log file unavailable. You can still operate without log.','Warning');
                updateConsoleBox(app,"WARNING: LuNART-Q is not logging the session activities.");
            end

            %% Define colors (readiness/progress level)
            app.colorGreen = [0.00,0.56,0.06];
            app.colorYellow = [0.90,0.75,0.25];
            app.colorRed = [1.00,0.00,0.00];
            app.colorGray = [0.94,0.94,0.94];
            app.colorBgGray = [0.94,0.94,0.94];

            focus(app.OperationWindowDropDown);

            %% Load settings and status variables
            app.sessionSettings = parseConfigFile(strcat("settings",filesep,"config.txt"));

            %--- Set operators list from hard coded operator list
            app.OperatorDropDown.Items  = app.sessionSettings.spcOperators;
            app.OperatorDropDown.Enable = 'on';
            %--- Save current operator and window
            app.currentOperator = app.OperatorDropDown.Value;

            %--- Load exp counters from previous runs
            try
                B = load(strcat("settings",filesep,"expCounters.mat"));
                app.expCounters = B.expCounters;
            catch
                app.expCounters = zeros(200,20);
            end
            %--- Load exp completed 
            try
                B = load(strcat("settings",filesep,"expCompleted.mat"));
                app.expCompleted = B.expCompleted;
            catch
                app.expCompleted = false(200,20);
                resetCountersCompleted(app);
            end

            %% Check reading/writing user privileges through folder
            %--- Fill metadata for current op window
            OperationwindowDropDownValueChanged(app);

            %--- Check if there is input folder is accessible
            app.inputDataEditField.Value = app.sessionSettings.inputFilePath;

            if exist(app.sessionSettings.inputFilePath, 'dir')
                app.InputDataPathLamp.Color = app.colorGreen;
            else
                app.InputDataPathLamp.Color = app.colorRed;
                updateConsoleBox(app,"[CONGIF.ERR_00] Input data path not found or no writing permission.")
            end

            %--- Check if there is write permission on input folder
            app.outputDataEditField.Value = app.sessionSettings.outputFilePath;
            [~,outPathValues] = fileattrib(app.sessionSettings.outputFilePath);

            if exist(app.sessionSettings.outputFilePath, 'dir') && outPathValues.UserWrite
                app.OutputDataPathLamp.Color = app.colorGreen;
            else
                app.OutputDataPathLamp.Color = app.colorRed;
                updateConsoleBox(app,"[CONGIF.ERR_01] Output data path not found or no writing permission.")
            end

            %--- Notify user the tool is ready
            updateConsoleBox(app,("LuNART-Q is ready for session configuration."));
        end

        % Button pushed function: RunExperimentButton_Q1a
        function RunExperimentButton_Q1aPushed(app, event)
            %% Close previous plots, reset lamps and messages
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q1a.Value = "Running experiment...";
            app.CommentArea_Q1a.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1a.Color = app.colorYellow;

            app.ReportLamp_Q1a.Color = app.colorGray;
            app.ResultsLamp_Q1a.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Run experiment
            try
                msg100 = PlotCNo(app.folderName, app.obsFileName, app.PRN, app.maxLimit, app.epochDate, 100);

                if ~isempty(app.refObsFileName)
                    msg101 = PlotCNo(app.folderName, app.refObsFileName, app.PRN, app.maxLimit, app.epochDate, 101);
                    msg102 = PlotCNoDiff(app.folderName, app.obsFileName, app.refObsFileName, app.PRN, app.maxLimit, app.epochDate, 102);
                else
                    placeholderPlot('plotTitle',101);
                    placeholderPlot('plotTitle',102);
                    msg101 = "Warning: Reference observables not uploaded";
                    msg102 = "Cannot plot reference C/N0 and C/N0 differences";
                end

                app.ExperimentSuccessLamp_Q1a.Color = app.colorGreen;
                app.SaveResultsButton_Q1a.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1a.Value = sprintf("%s\n%s\n%s\n%s",expDate, msg100, msg101, msg102);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1a ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1a.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1a.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1a failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: RunExperimentButton_Q1b
        function RunExperimentButton_Q1bPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q1b.Value = "Running experiment...";
            app.CommentArea_Q1b.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1b.Color = app.colorYellow;

            app.ResultsLamp_Q1b.Color = app.colorGray;
            app.ReportLamp_Q1b.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Save settings to be passed to plot function
            set.mission.G1th = app.GPSL1Spinner.Value;
            set.mission.G5th = app.GPSL5Spinner.Value;
            set.mission.E1th = app.GALE1Spinner.Value;
            set.mission.E5th = app.GALE5Spinner.Value;

            set.reference.G1th = app.GPSL1Spinner_2.Value;
            set.reference.G5th = app.GPSL5Spinner_2.Value;
            set.reference.E1th = app.GALE1Spinner_2.Value;
            set.reference.E5th = app.GALE5Spinner_2.Value;

            app.tmpConfigData = [string(app.GPSL1Spinner.Value),(app.GPSL5Spinner.Value),(app.GALE1Spinner.Value),string(app.GALE5Spinner.Value), string(app.GPSL1Spinner_2.Value),string(app.GPSL5Spinner_2.Value),string(app.GALE1Spinner_2.Value),string(app.GALE5Spinner_2.Value)];

            %% Run experiment
            try
                if ~isempty(app.refObsFileName)
                    [msg100, msg101, msg102] = PlotAvailability(app.folderName, app.obsFileName, app.navFileName, app.maxLimit, app.epochDate, set, app.refObsFileName);
                    msg103 = "";
                    msg104 = sprintf("SETTINGS:\nC/N0 thresholds [dB-Hz]\nTLM - L1: %.1f, L5: %.1f, E1: %.1f, E5: %.1f\nREF - L1: %.1f, L5: %.1f, E1: %.1f, E5: %.1f", set.mission.G1th, set.mission.G5th, set.mission.E1th, set.mission.E5th, set.reference.G1th, set.reference.G5th, set.reference.E1th, set.reference.E5th);
                else
                    [msg100, msg101, msg102] = PlotAvailability(app.folderName, app.obsFileName, app.navFileName, app.maxLimit, app.epochDate, set);
                    msg103 = "Warning: Expected observables not uploaded";
                    msg104 = sprintf("SETTINGS:\nC/N0 thresholds [dB-Hz]\nTLM - L1: %.1f, L5: %.1f, E1: %.1f, E5: %.1f\n", set.mission.G1th, set.mission.G5th, set.mission.E1th, set.mission.E5th);
                end

                app.ExperimentSuccessLamp_Q1b.Color = app.colorGreen;
                app.SaveResultsButton_Q1b.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1b.Value = sprintf("%s\n%s\n%s\n%s\n%s\n%s",expDate, msg100, msg101, msg102, msg103, msg104);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1b ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1b.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1b.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1b failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: GenerateReportButton_Q1a
        function GenerateReportButton_Q1aPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1a.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,1))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath, filesep, app.currentOpsWindow, filesep, "exp_Q1a", filesep, runId);

                %--- OP metadata fields
                opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                    app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                    string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                    app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
                %--- Document metadata fields
                reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
                docMetaFields = [string(datetime("now")), reportID];
                %--- Report metadata fields
                reportMetaFields = ["Q1.a", string(app.expCounters(app.opsWinNum,1)), app.folderName, app.currentOperator];

                %--- Report text content
                repTestFields = [docMetaFields, opMetaFields, reportMetaFields];
                repLogField = app.TextArea_Q1a.Value;
                repOpComment = app.CommentArea_Q1a.Value;

                [msg00] = genReport(resultsFolderName, strcat('Q1a_',reportID), 'rptTmpl\RSPC\Q1a_tmpl_v2.dotx', repTestFields, figures, repLogField, repOpComment);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentOpsWindow, 'Q1a', resultsFolderName, strcat('Q1a_',reportID), docMetaFields, app);
                OSR_data = app.OSR;
                save(strcat("settings",filesep,"OSR.mat"), "OSR_data");

                %--- Update lamps and buttons
                app.ReportLamp_Q1a.Color = app.colorGreen;
                app.GenerateReportButton_Q1a.Enable = "off";
                %--- Experiment has been completed
                app.expCompleted(app.opsWinNum,1) = true;
                app.Q1a_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1a.Value = [app.TextArea_Q1a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment Q1a saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,1)));
            catch
                app.ReportLamp_Q1a.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1a.Value = [app.TextArea_Q1a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment Q1a (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,1)));
            end

            scroll(app.TextArea_Q1a, 'bottom');
        end

        % Button pushed function: RunExperimentButton_Q1c
        function RunExperimentButton_Q1cPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all;

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q1c.Value = "Running experiment...";
            app.CommentArea_Q1c.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1c.Color = app.colorYellow;

            app.ResultsLamp_Q1c.Color = app.colorGray;
            app.ReportLamp_Q1c.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Run Experiment
            try
                msg100 = PlotRawPseudoranges(app.folderName, app.obsFileName, app.PRN, app.maxLimit, app.epochDate, 120);
                if ~isempty(app.refObsFileName)
                    msg101 = PlotRawPseudoranges(app.folderName, app.refObsFileName, app.PRN, app.maxLimit, app.epochDate, 121);
                    msg102 = PlotRawPseudorangesDiff(app.folderName, app.obsFileName, app.refObsFileName, app.PRN, app.maxLimit, app.epochDate, 122);
                else
                    placeholderPlot('plotTitle', 121);
                    placeholderPlot('plotTitle', 122);
                    msg101 = "Warning: Reference observables not uploaded";
                    msg102 = "Cannot plot reference Pseudoranges and Pseudorange differences";
                end

                app.ExperimentSuccessLamp_Q1c.Color = app.colorGreen;
                app.SaveResultsButton_Q1c.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");       
                app.TextArea_Q1c.Value = sprintf("%s\n%s\n%s\n%s",expDate, msg100, msg101, msg102);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1c ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1c.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1c.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1c failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: RunExperimentButton_Q1e
        function RunExperimentButton_Q1ePushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q1e.Value = "Running experiment...";
            app.CommentArea_Q1e.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1e.Color = app.colorYellow;

            app.ReportLamp_Q1e.Color = app.colorGray;
            app.ResultsLamp_Q1e.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Run experiment
            try
                msg100 = PlotDopplerShift(app.folderName, app.obsFileName, app.PRN, app.maxLimit, app.epochDate, 140);
                msg101 = PlotDopplerRate(app.folderName, app.obsFileName, app.PRN, app.maxLimit, app.epochDate, 142);

                if ~isempty(app.refObsFileName)
                    msg102 = PlotDopplerShift(app.folderName, app.refObsFileName, app.PRN, app.maxLimit, app.epochDate, 141);
                    msg103 = PlotDopplerRate(app.folderName, app.refObsFileName, app.PRN, app.maxLimit, app.epochDate, 143);
                    msg104 = PlotDopplerShiftDiff(app.folderName, app.obsFileName, app.refObsFileName, app.PRN, app.maxLimit, app.epochDate, 144);
                    msg105 = PlotDopplerRateDiff(app.folderName, app.obsFileName, app.refObsFileName, app.PRN, app.maxLimit, app.epochDate, 145);
                else
                    placeholderPlot('plotTitle',141);
                    placeholderPlot('plotTitle',143);
                    placeholderPlot('plotTitle',144);
                    placeholderPlot('plotTitle',145);

                    msg102 = "Warning: Reference observables not available";
                    msg103 = "Cannot plot Doppler shift difference";
                    msg104 = "Cannot plot reference Doppler shift and Doppler rate";
                    msg105 = "Cannot plot Doppler rate difference";
                end

                app.ExperimentSuccessLamp_Q1e.Color = app.colorGreen;
                app.SaveResultsButton_Q1e.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1e.Value = sprintf("%s\n%s\n%s\n%s\n%s\n%s\n%s",expDate, msg100, msg101, msg102, msg103, msg104, msg105);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1a ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1e.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1e.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1a failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q1a
        function SaveResultsButton_Q1aPushed(app, event)
            app.ResultsLamp_Q1a.Color = app.colorYellow;
            drawnow;

            try
                % update run counters
                app.expCounters(app.opsWinNum,1) = app.expCounters(app.opsWinNum,1) + 1;

                % update run counters field
                app.RunEditField_Q1a.Value = app.expCounters(app.opsWinNum,1);

                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,1))));
                % datetime dropped-off because does not match with input data folder
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q1a",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                app.ResultsLamp_Q1a.Color = app.colorGreen;
                app.GenerateReportButton_Q1a.Enable = "on";
                app.SaveResultsButton_Q1a.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1a.Value = [app.TextArea_Q1a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment Q1a saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,1)));
            catch
                app.ResultsLamp_Q1a.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1a.Value = [app.TextArea_Q1a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment Q1a (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,1)));
            end

            scroll(app.TextArea_Q1a, 'bottom');
        end

        % Value changed function: GALE1Spinner, GALE5Spinner, 
        % ...and 2 other components
        function GPSL1SpinnerValueChanged(app, event)
            if app.LinkvaluesCheckBox.Value == true
                value = event.Source.Value;

                app.GPSL1Spinner.Value = value;
                app.GPSL5Spinner.Value = value;
                app.GALE1Spinner.Value = value;
                app.GALE5Spinner.Value = value;
            end
        end

        % Value changed function: GALE1Spinner_2, GALE5Spinner_2, 
        % ...and 2 other components
        function GPSL1Spinner_2ValueChanged(app, event)
            if app.LinkvaluesCheckBox_2.Value == true
                value = event.Source.Value;

                app.GPSL1Spinner_2.Value = value;
                app.GPSL5Spinner_2.Value = value;
                app.GALE1Spinner_2.Value = value;
                app.GALE5Spinner_2.Value = value;
            end
        end

        % Button pushed function: SaveResultsButton_Q1b
        function SaveResultsButton_Q1bPushed(app, event)
            app.ResultsLamp_Q1b.Color = app.colorYellow;
            drawnow;

            try
                % update run counters
                app.expCounters(app.opsWinNum,2) = app.expCounters(app.opsWinNum,2) + 1;

                % update run counters field on GUI
                app.RunEditField_Q1b.Value = app.expCounters(app.opsWinNum,2);

                runId=strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,2))));

                % Save figures
                resultsFolderName = strcat(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q1b",filesep,runId);   % Your destination folder

                app.figurePaths = saveFigs(resultsFolderName);

                % save the configuration
                CNoThresholds = app.tmpConfigData;  % Extract the field
                save(strcat(resultsFolderName, filesep,"expSettings"), 'CNoThresholds');

                % % flush the temporary container for configuration data
                % app.tmpConfigData=[];
                app.ResultsLamp_Q1b.Color = app.colorGreen;
                app.GenerateReportButton_Q1b.Enable = "on";
                app.SaveResultsButton_Q1b.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1b.Value = [app.TextArea_Q1b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment Q1b saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,2)));
            catch
                app.ResultsLamp_Q1b.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1b.Value = [app.TextArea_Q1b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment Q1b (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,2)));
            end

            scroll(app.TextArea_Q1b, 'bottom');
        end

        % Button pushed function: SaveResultsButton_Q1c
        function SaveResultsButton_Q1cPushed(app, event)
            app.ResultsLamp_Q1c.Color = app.colorYellow;
            drawnow;

            try
                % update run counters
                app.expCounters(app.opsWinNum,3) = app.expCounters(app.opsWinNum,3) + 1;

                % update run counters field
                app.RunEditField_Q1c.Value = app.expCounters(app.opsWinNum,3);

                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,3))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q1c",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                app.ResultsLamp_Q1c.Color = app.colorGreen;
                app.GenerateReportButton_Q1c.Enable = "on";
                app.SaveResultsButton_Q1c.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1c.Value = [app.TextArea_Q1c.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment Q1c saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,3)));
            catch
                app.ResultsLamp_Q1c.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1c.Value = [app.TextArea_Q1c.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment Q1c (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,3)));
            end

            scroll(app.TextArea_Q1c, 'bottom');
        end

        % Button pushed function: SaveResultsButton_Q1e
        function SaveResultsButton_Q1ePushed(app, event)
            app.ResultsLamp_Q1e.Color = app.colorYellow;
            drawnow;

            try
                % update run counters
                app.expCounters(app.opsWinNum,5) = app.expCounters(app.opsWinNum,5) + 1;

                % update run counters field
                app.RunEditField_Q1e.Value = app.expCounters(app.opsWinNum,5);

                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,5))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q1e",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                app.ResultsLamp_Q1e.Color = app.colorGreen;
                app.GenerateReportButton_Q1e.Enable = "on";
                app.SaveResultsButton_Q1e.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1e.Value = [app.TextArea_Q1e.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment Q1e saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,5)));
            catch
                app.ResultsLamp_Q1e.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1e.Value = [app.TextArea_Q1e.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment Q1e (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,5)));
            end

            scroll(app.TextArea_Q1e, 'bottom');
        end

        % Button pushed function: ResetSessionButton
        function ResetSessionButtonPushed(app, event)
            %--- Ask user confirmation
            YN = uiconfirm(app.AnalysisToolUIFigure,'Do you want to reset the session?', 'Operation reset request');

            if strcmpi(YN,'OK')
                close all;
                clc;

                %% save progress
                expCounters = app.expCounters; %#ok<ADPROPLC>
                save(strcat("settings",filesep,"expCounters.mat"), 'expCounters');

                expCompleted = app.expCompleted; %#ok<ADPROPLC>
                save(strcat("settings",filesep,"expCompleted.mat"), 'expCompleted');

                %% Clear all paths and corresponding UI elements
                app.obsFileName    = [];
                app.navFileName    = [];
                app.acqFileName    = [];

                app.refObsFileName = [];
                app.refNavFileName = [];

                %% Reset all color lamps
                app.SessionsettingsLamp.Color      = app.colorGray;
                app.TelemetrydataloadingLamp.Color = app.colorGray;
                app.IQSdataloadingLamp.Color       = app.colorGray;
                app.ReadyforexperimentsLamp.Color  = app.colorGray;

                %% Lock and reset experiemnts
                disableExperimentsRunButtons(app);
                resetExpButtons(app);
                resetExpLamps(app);
                resetExpText(app);

                %--- Reset ID counters and lamps of completion
                resetCountersCompleted(app);

                %--- Other UI elements in experiments
                app.UITable_Q1d.Data = {};
                app.UITable_Q1f_L1.Data = {};
                app.UITable_Q1f_L5.Data = {};
                app.UITable_Q3b_GPS.Data = {};
                app.UITable_Q3b_Galileo.Data = {};

                %% Reset log window
                updateConsoleBox(app,"The operator has cleaned the LuNART-Q environment.");
                app.consoleWindow.Value = "";

                %% Settings panels reset
                %app.OperatorDropDown.Enable  = true;
                app.OperationWindowDropDown.Enable = true;

                app.LoadDataButton.Value = false;
                app.LoadDataButton.Enable = true;

                resetOpsWinInputDataPanel(app);
   
                %% Clear variables and UI elements of the plots tab
                app.PRN         = [];
                app.maxLimit    = [];
                app.epochDate   = [];
                app.ticksVector = [];

                %--- Reset active window label
                app.expOpsWinField.Value = strings;
                app.expOpsWinField_2.Value = strings;

                %--- Other variables
                app.figurePaths = [];

                %--- Reset OSR panel
                resetOSRPanel(app);

                %--- Close the log file
                fclose all;

                %% Reload the app
                startupFcn(app);
            end
        end

        % Button pushed function: RunExperimentButton_Q2a
        function RunExperimentButton_Q2aPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q2a.Value = "Running experiment...";
            app.CommentArea_Q2a.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q2a.Color = app.colorYellow;

            app.ResultsLamp_Q2a.Color = app.colorGray;
            app.ReportLamp_Q2a.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;

            %% Reference Frame Settings
            settings = app.ReferenceFrame_Q2a.Value;

            %% Run experiment
            try
                %--- If NAV is not present, make it catch
                assert(~isempty(app.navFileName));

                if ~isempty(app.refNavFileName)
                    [msg100] = PlotPosition(app.folderName, app.navFileName, app.maxLimit, app.epochDate, 200, settings, app.refNavFileName);
                    [msg101] = PlotVelocity(app.folderName, app.navFileName, app.maxLimit, app.epochDate, 201, settings, app.refNavFileName);
                    msg104 = "";
                else
                    [msg100] = PlotPosition(app.folderName, app.navFileName, app.maxLimit, app.epochDate, 200, settings);
                    [msg101] = PlotVelocity(app.folderName, app.navFileName, app.maxLimit, app.epochDate, 201, settings);
                    msg104 = "Warning: No reference trajectory";
                end
                [msg102] = PlotClocks(app.folderName, app.navFileName, app.maxLimit, app.epochDate, 202);
                [msg103] = PlotStd(app.folderName, app.navFileName, app.maxLimit, app.epochDate, 203);

                app.ExperimentSuccessLamp_Q2a.Color = app.colorGreen;
                app.SaveResultsButton_Q2a.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q2a.Value = sprintf("%s\n%s\n%s\n%s\n%s\n%s",expDate, msg100, msg101, msg102, msg103, msg104);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q2a ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q2a.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q2a.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q2a failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q2a
        function SaveResultsButton_Q2aPushed(app, event)
            app.ResultsLamp_Q2a.Color = app.colorYellow;
            drawnow;

            try
                % update run counters
                app.expCounters(app.opsWinNum,8) = app.expCounters(app.opsWinNum,8) + 1;

                % update run counters field
                app.RunEditField_Q2a.Value = app.expCounters(app.opsWinNum,8);

                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,8))));
                % datetime dropped-off because does not match with input data folder
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q2a",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                app.ResultsLamp_Q2a.Color = app.colorGreen;
                app.GenerateReportButton_Q2a.Enable = "on";
                app.SaveResultsButton_Q2a.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q2a.Value = [app.TextArea_Q2a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment Q2a saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,8)));
            catch
                app.ResultsLamp_Q2a.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q2a.Value = [app.TextArea_Q2a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment Q2a (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,8)));
            end

            scroll(app.TextArea_Q2a, 'bottom');
        end

        % Button pushed function: RunExperimentButton_Q2d
        function RunExperimentButton_Q2dPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q2d.Value = "Running experiment...";
            app.CommentArea_Q2d.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q2d.Color = app.colorYellow;

            app.ResultsLamp_Q2d.Color = app.colorGray;
            app.ReportLamp_Q2d.Color = app.colorGray;

            resetExpButtons(app);
            drawnow;          

            %% Run experiment
            try
                %--- If NAV is not present, make it catch
                assert(~isempty(app.navFileName));

                %--- Settings
                DOPlims = [app.StartingEpochEditField.Value app.maxLimit(2)];

                if ~isempty(app.refNavFileName)
                    msg100 = PlotDOP(app.folderName, app.navFileName, DOPlims, app.epochDate, 230, app.refNavFileName);
                else
                    msg100 = PlotDOP(app.folderName, app.navFileName, DOPlims, app.epochDate, 230);
                end

                msg101 = sprintf("SETTINGS:\nFirst %d epoch skipped", DOPlims(1)-1);

                app.ExperimentSuccessLamp_Q2d.Color = app.colorGreen;
                app.SaveResultsButton_Q2d.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q2d.Value = sprintf("%s\n%s\n%s", expDate, msg100, msg101);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q2d ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q2d.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q2d.Value = sprintf("%s\nExperiment failed!", expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q2d failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q2d
        function SaveResultsButton_Q2dPushed(app, event)
            app.ResultsLamp_Q2d.Color = app.colorYellow;
            drawnow;

            try
                % update run counters
                app.expCounters(app.opsWinNum,11) = app.expCounters(app.opsWinNum,11) + 1;

                % update run counters field
                app.RunEditField_Q2d.Value = app.expCounters(app.opsWinNum,11);

                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,11))));
                % datetime dropped-off because does not match with input data folder
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q2d",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                app.ResultsLamp_Q2d.Color = app.colorGreen;
                app.GenerateReportButton_Q2d.Enable = "on";
                app.SaveResultsButton_Q2d.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q2d.Value = [app.TextArea_Q2d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment Q2d saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,11)));
            catch
                app.ResultsLamp_Q2d.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q2d.Value = [app.TextArea_Q2d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment Q2d (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,11)));
            end

            scroll(app.TextArea_Q2d, 'bottom');
        end

        % Button pushed function: RunExperimentButton_Q1d
        function RunExperimentButton_Q1dPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q1d.Value = "Running experiment...";
            app.CommentArea_Q1d.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1d.Color = app.colorYellow;

            app.ReportLamp_Q1d.Color = app.colorGray;
            app.ResultsLamp_Q1d.Color = app.colorGray;

            resetExpButtons(app);

            %% Reset other UI elements
            app.UITable_Q1d.Data = {};
            drawnow;

            %% Run experiment
            try
                if ~isempty(app.refObsFileName)
                    [msg103] = PlotRawPseudorangesDiff(app.folderName, app.obsFileName, app.refObsFileName, app.PRN, app.maxLimit, app.epochDate, 133, app.navFileName);
                else
                    placeholderPlot('plotTitle', 133);
                    msg103 = "Warning: Missing data";
                end

                [msg100, msg101, msg102, statTable] = PlotDiffDiff(app.folderName, app.obsFileName, app.PRN, app.maxLimit, app.epochDate, 130, 131);

                app.UITable_Q1d.Data = statTable;

                h = figure(132);
                uitable('Data',statTable{:,:},'ColumnName',statTable.Properties.VariableNames,'RowName',statTable.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1]);
                Pix_SS = get(0,'screensize');
                h.OuterPosition = [3*Pix_SS(3)/4 0 Pix_SS(3)/4 Pix_SS(4)/2];
                h.WindowState = 'minimized';

                app.ExperimentSuccessLamp_Q1d.Color = app.colorGreen;
                app.SaveResultsButton_Q1d.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1d.Value = sprintf("%s\n%s\n%s\n%s\n%s",expDate, msg100, msg101, msg102, msg103);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1d ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1d.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1d.Value = sprintf("%s\n Experiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1d failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q1d
        function SaveResultsButton_Q1dPushed(app, event)
            app.ResultsLamp_Q1d.Color = app.colorYellow;
            drawnow;

            try
                % update run counters
                app.expCounters(app.opsWinNum,4) = app.expCounters(app.opsWinNum,4) + 1;

                % update run counters field
                app.RunEditField_Q1d.Value = app.expCounters(app.opsWinNum,4);

                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,4))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q1d",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                % Save table
                statTable=app.UITable_Q1d.Data;
                if istable(statTable)
                    writetable(statTable,fullfile(resultsFolderName, "statTable.xlsx"));
                else
                    writecell(statTable,fullfile(resultsFolderName, "statTable.xlsx"));
                end

                app.ResultsLamp_Q1d.Color = app.colorGreen;
                app.GenerateReportButton_Q1d.Enable = "on";
                app.SaveResultsButton_Q1d.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1d.Value = [app.TextArea_Q1d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment Q1d saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,4)));
            catch
                app.ResultsLamp_Q1d.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1d.Value = [app.TextArea_Q1d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment Q1d (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,4)));
            end

            scroll(app.TextArea_Q1d, 'bottom');
        end

        % Value changed function: LoadDataButton
        function LoadDataButtonValueChanged(app, event)
            %% Check if OP already exists and ask user if they want to parse again
            app.currentOpsWindow = app.OperationWindowDropDown.Value;
            app.folderName = strcat("data", filesep, app.currentOpsWindow);

            if exist(app.folderName, 'dir')               
                YN = uiconfirm(app.AnalysisToolUIFigure,'This OP has already been processed. Do you want to parse it again?', 'Exising OP', ...
                    "Options",["Yes","No","Cancel"], "DefaultOption",2, "CancelOption",3);
                
                if strcmpi(YN,'No')
                    skipFlag = 1;
                elseif strcmpi(YN,'Cancel')
                    return;
                else
                    %--- Empty folder for overwrite
                    delete(strcat(app.folderName, filesep, "*"));
                    skipFlag = 0;
                end
            else
                mkdir(app.folderName);
                skipFlag = 0;
            end

            [opWinScan] = sscanf(app.currentOpsWindow,"OP%02d_%01d");
            app.opsWinNum = opWinScan(1) + opWinScan(2) * 100;

            %% Reset elements
            %--- Lock UI elements
            lockConfiguration(app);

            %--- Disable itself
            app.LoadDataButton.Enable = false;

            %--- Update lamps
            app.SessionsettingsLamp.Color = app.colorYellow;
            app.TelemetrydataloadingLamp.Color = app.colorYellow;

            drawnow;

            %% Update variables
            %--- Save current operator, op window and location
            app.currentOperator = app.OperatorDropDown.Value;
            %--- Update operator field in experimet and OSR tabs
            app.expOperatorField.Value = app.currentOperator;
            app.expOperatorField_2.Value = app.currentOperator;
            %--- Update operation window field in experimet and OSR tabs
            app.expOpsWinField.Value = app.currentOpsWindow;
            app.expOpsWinField_2.Value = app.currentOpsWindow;

            %--- Update OSR list
            app.OSRLamp.Color = app.colorGray;
            app.OSR_reportList = cell(1, 12);

            %--- Update report list
            loadReportList(app);
            %--- Load counters and lamps of completed experiment
            loadCountersCompleted(app);

            %--- Notify of successful change of settings
            app.SessionsettingsLamp.Color = app.colorGreen;
            updateConsoleBox(app,"Session settings configured successfully.");
            updateConsoleBox(app,strcat(sprintf("Current Operator: %s",app.currentOperator),sprintf(", Current OPS windows: %s", app.currentOpsWindow)));

            drawnow;

            %% Load already parsed data
            if skipFlag == 1
                fileList = dir(app.folderName);
                check = 0;

                %--- Try to find telemetry
                if any(contains({fileList.name}, 'acqSolutions'))
                    app.acqFileName = 'acqSolutions.mat';
                    check = 1;
                end

                if any(contains({fileList.name}, 'obsSolutions'))
                    app.obsFileName = 'obsSolutions.mat';
                    check = 1;
                end

                if any(contains({fileList.name}, 'navSolutions'))
                    app.navFileName = 'navSolutions.mat';
                    check = 1;
                end

                %--- If at least one telemetry file is present
                if check == 1
                    app.TelemetrydataloadingLamp.Color = app.colorGreen;
                else
                    app.TelemetrydataloadingLamp.Color = app.colorRed;
                end
            else

                %% Parse and load
                %--- Wait bar to show progress
                d = uiprogressdlg(app.AnalysisToolUIFigure,'Title','Please Wait',...
                    'Message','Loading mission data','Cancelable','on');

                %--- Parsing of input telemetry file [NEW data structure, ongoing migration]
                [ACQfilePath, RAWfilePath, NAVfilePath] = FindTelemetryTxtFiles(app.sessionSettings.inputFilePath, app.currentOpsWindow);

                %--- Load Telemetry, Config & Spirent files
                updateConsoleBox(app,"Mission data loading has started. It may take some seconds.");
                drawnow;

                updateProgressBar(app,event,d,1/10,'Parsing ACQ data...');
                try
                    [ACQ] = TxtParserAcq(ACQfilePath);
                    
                    acqFlag = true;
                    updateConsoleBox(app,"ACQ data loaded succesfully");
                catch
                    ACQ = [];
                    acqFlag = false;
                    updateConsoleBox(app,"[CONGIF.ERR_02] ACQ data parsing failed.");
                end


                updateProgressBar(app,event,d,3/10,'Parsing RAW data...');
                try
                    [RAW] = TxtParserRaw(RAWfilePath);

                    rawFlag = true;
                    updateConsoleBox(app,"RAW data loaded succesfully");
                catch
                    RAW = [];
                    rawFlag = false;
                    updateConsoleBox(app,"[CONGIF.ERR_04] RAW data parsing failed.");
                end


                updateProgressBar(app,event,d,2/10,'Parsing NAV data...');
                try
                    [NAV] = TxtParserNav(NAVfilePath);

                    navFlag = true;
                    updateConsoleBox(app,"NAV data loaded succesfully");
                catch
                    NAV = [];
                    navFlag = false;
                    updateConsoleBox(app,"[CONGIF.ERR_03] NAV data parsing failed.");
                end


                %--- Adaptation Layer to ASI data format
                updateProgressBar(app,event,d,4/10,'Parsing into MATLAB structures...');
                try
                    [acqSolutions, obsSolutions, navSolutions] = telemetryParser_ASI(ACQ, RAW, NAV);
                    updateConsoleBox(app,"MATLAB data parsing completed succesfully.");
                catch
                    updateConsoleBox(app,"[CONGIF.ERR_05] MATLAB data parsing failed.");
                end

                %--- Save output data to ASI/PoliTo data-structure
                updateProgressBar(app,event,d,8/10,'Saving parsed data and ephemeris...');

                try                  
                    app.acqFileName = 'acqSolutions.mat';
                    app.obsFileName = 'obsSolutions.mat';
                    app.navFileName = 'navSolutions.mat';
                    
                    if acqFlag
                    save(strcat(app.folderName,filesep,app.acqFileName),'acqSolutions');
                    end
                    if rawFlag
                        save(strcat(app.folderName,filesep,app.obsFileName),'obsSolutions');
                    end
                    if navFlag
                        save(strcat(app.folderName,filesep,app.navFileName),'navSolutions');
                    end

                    app.TelemetrydataloadingLamp.Color = app.colorGreen;

                    updateConsoleBox(app,"Parsed data correctly saved locally (internal data structures).");
                catch
                    app.TelemetrydataloadingLamp.Color = app.colorRed;

                    updateConsoleBox(app,"[CONGIF.ERR_06] Could not save parsed data.");
                end
            end

            %% Crate time series and other useful structures
            try
                %--- The epoch dates are obtained and the properties of the slider are updated
                app.epochDate = GetEpochDate(app.folderName, app.obsFileName);

                dataLen = height(app.epochDate);

                %--- ticks for plots
                app.ticksVector(1) = 1;
                app.ticksVector(2) = floor(dataLen/5);
                app.ticksVector(3) = floor(2*dataLen/5);
                app.ticksVector(4) = floor(3*dataLen/5);
                app.ticksVector(5) = floor(4*dataLen/5);
                app.ticksVector(6) = dataLen;

                %--- Generate random colors for plots, seed based on OP
                app.PRN = PRNColors(app.opsWinNum);

                %--- Index limites
                [~, app.maxLimit] = GetLimit(app.folderName, app.obsFileName);

                %--- Update UI
                app.ReadyforexperimentsLamp.Color = app.colorGreen;
                updateConsoleBox(app,"Experimental setup preparation completed successfully.")

                enableExperimentsRunButtons(app);
            catch
                app.ReadyforexperimentsLamp.Color = app.colorYellow;
                updateConsoleBox(app,"[CONGIF.ERR_07] Experimental setup preparation failed.")
            end

            %% IQS
            %--- Check for presence of IQS data
            [app.iqsFileList{1}, app.iqsFileList{2}] = FindIQSFiles(app.sessionSettings.inputFilePath, app.currentOpsWindow); 
            
            if ~isempty(app.iqsFileList{1})
                %--- Enable IQS experiment even if TLM data loading failed
                app.RunExperimentButton_Q3b.Enable = true;

                app.IQSdataloadingLamp.Color = app.colorGreen;
                updateConsoleBox(app,"IQS data preparation completed successfully.")
            else
                app.IQSdataloadingLamp.Color = app.colorRed;

                if ~isempty(app.iqsFileList{2})
                    updateConsoleBox(app,"[WARNING] Only L5 IQS data available, which LuNART-q currently does not process.")
                else
                    updateConsoleBox(app,"[CONGIF.ERR_08] No IQS data found for this operation.")
                end
            end

            %% Final taks
            try
                %--- Close wait bar
                d.Value = 10/10;
                d.Message = 'Loading completed.';
                close(d);
            catch
            end
        end

        % Close request function: AnalysisToolUIFigure
        function AnalysisToolUIFigureCloseRequest(app, event)
            YN = uiconfirm(app.AnalysisToolUIFigure,'Do you want to close the tool?', 'Close request',"Icon","warning");

            if strcmpi(YN,'OK')

                temp = app.expCounters;
                save(strcat("settings",filesep,"expCounters.mat"), 'temp');

                temp = app.expCompleted;
                save(strcat("settings",filesep,"expCompleted.mat"), 'temp');

                OSR_data = app.OSR;
                save(strcat("settings",filesep,"OSR.mat"), 'OSR_data');

                %fclose(app.logFileId);

                fclose all;
                close all force;
                delete(app);
            end
        end

        % Button pushed function: GenerateReportButton_Q1b
        function GenerateReportButton_Q1bPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1b.Color = app.colorYellow;
            drawnow % force lamp color change

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,2))));
                resultsFolderName = strcat(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q1b",filesep,runId);

                %--- OP metadata fields
                opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                    app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                    string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                    app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
                %--- Document metadata fields
                reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
                docMetaFields = [string(datetime("now")), reportID];
                %--- Report metadata fields
                reportMetaFields = ["Q1.b", string(app.expCounters(app.opsWinNum,2)), app.folderName, app.currentOperator];

                %--- Report text content
                repTestFields = [docMetaFields, opMetaFields, reportMetaFields];
                repLogField  = app.TextArea_Q1b.Value;
                repOpComment = app.CommentArea_Q1b.Value;

                [msg1] = genReport(resultsFolderName,strcat('Q1b_',reportID),'rptTmpl\RSPC\Q1b_tmpl_v2.dotx',repTestFields,figures,repLogField,repOpComment,app.tmpConfigData);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentOpsWindow,'Q1b',resultsFolderName,strcat('Q1b_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save(strcat("settings",filesep,"OSR.mat"), "OSR_data");

                %---
                app.ReportLamp_Q1b.Color = app.colorGreen;
                app.GenerateReportButton_Q1b.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(app.opsWinNum,2) = true;
                app.Q1b_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1b.Value = [app.TextArea_Q1b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment Q1b saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,2)));
            catch
                app.ReportLamp_Q1b.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1b.Value = [app.TextArea_Q1b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment Q1b (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,2)));
            end

            scroll(app.TextArea_Q1b, 'bottom');
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            myicon = imread("./images/icons/lunart_ico.png");
            %license=sprintf("LuNART licensing is still under discussion. Any permission to use, modify, or distribute the software must be obtained directly from the Italian Space Agency and Politecnico di Torino, and may be subject to specific terms and conditions as determined by the rightful owners of the software.\n\nFor inquiries or permissions related to LuNART, please contact the Italian Space Agency and Politecnico di Torino.");
            %license=sprintf("LuGRE Navigation Analysis and Reporting Tool (LuNART-Q)\n© Copyright ASI-Politecnico di Torino\n\nLuNART is a software owned by the Italian Space Agency (ASI) and was developed by Politecnico di Torino in the framework of the NASA-ASI Lunar GNSS Receiver Experiment (LuGRE).\n\nAll rights, including but not limited to intellectual property rights, copyrights, and any other applicable rights, are the exclusive property of the Italian Space Agency and Politecnico di Torino. \n\nUnauthorized reproduction, distribution, or use of the LuNART software is strictly prohibited, and any such actions may be subject to legal action. \n\nPermission to use, modify, or distribute the software must be obtained directly from the Italian Space Agency and Politecnico di Torino, and may be subject to specific terms and conditions as determined by the rightful owners of the software.\n\nFor inquiries or permissions related to LuNART, please contact the Italian Space Agency and Politecnico di Torino.");
            license = sprintf("LuGRE Navigation Analysis and Reporting Tool (LuNART-q)\n© Copyright ASI-Politecnico di Torino\n\nLuNART-q is owned by the Italian Space Agency (ASI) and was developed by Politecnico di Torino under the Lunar GNSS Receiver Experiment (LuGRE).\n\nAll rights, including intellectual property and copyright, remain with ASI and Politecnico di Torino.\n\nUnauthorized use, modification, or distribution is strictly prohibited and may lead to legal action. Permissions must be obtained directly from ASI and Politecnico di Torino.\n\nFor inquiries or permissions, contact: navsas@polito.it.");
            h = msgbox(license,"About LuNART","help");
        end

        % Size changed function: Q1eTab
        function Q1eTabSizeChanged(app, event)
            position = app.Q1eTab.Position;

        end

        % Button pushed function: GenerateReportButton_Q1c
        function GenerateReportButton_Q1cPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1c.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,3))));
                resultsFolderName = strcat(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q1c",filesep,runId);

                %--- OP metadata fields
                opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                    app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                    string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                    app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
                %--- Document metadata fields
                reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
                docMetaFields = [string(datetime("now")), reportID];
                %--- Report metadata fields
                reportMetaFields = ["Q1.c", string(app.expCounters(app.opsWinNum,3)), app.folderName, app.currentOperator];

                %--- Report text content
                repTestFields = [docMetaFields, opMetaFields, reportMetaFields];
                repLogField  = app.TextArea_Q1c.Value;
                repOpComment = app.CommentArea_Q1c.Value;

                [msg1] = genReport(resultsFolderName,strcat('Q1c_',reportID),'rptTmpl\RSPC\Q1c_tmpl_v2.dotx',repTestFields,figures,repLogField,repOpComment,app.tmpConfigData);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentOpsWindow,'Q1c',resultsFolderName,strcat('Q1c_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save(strcat("settings",filesep,"OSR.mat"), "OSR_data");

                % ----
                app.ReportLamp_Q1c.Color = app.colorGreen;
                app.GenerateReportButton_Q1c.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(app.opsWinNum,3) = true;
                app.Q1c_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1c.Value = [app.TextArea_Q1c.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment Q1c saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,3)));
            catch
                app.ReportLamp_Q1c.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1c.Value = [app.TextArea_Q1c.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment Q1c (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,3)));
            end

            scroll(app.TextArea_Q1c, 'bottom');
        end

        % Button pushed function: RunExperimentButton_Q1f
        function RunExperimentButton_Q1fPushed(app, event)
            %% Close previous plots, reset lamps and buttons
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q1f.Value = "Running experiment...";
            app.CommentArea_Q1f.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q1f.Color = app.colorYellow;

            app.ResultsLamp_Q1f.Color = app.colorGray;
            app.ReportLamp_Q1f.Color = app.colorGray;

            resetExpButtons(app);
            app.UITable_Q1f_L1.Data = {};
            app.UITable_Q1f_L5.Data = {};
            drawnow;

            %% Save settings
            if app.DetrendedButton.Value == true
                settings.mode = 1;
            else
                settings.mode = 2;
            end

            settings.window = app.WindowSpinner.Value;
            settings.threshold = app.ThresholdSpinner.Value;

            %% Run experiment
            try
                [msg100] = PlotSingleCMC(app.folderName, app.obsFileName, app.PRN, app.maxLimit, app.epochDate, 160, settings);

                %[~, ~, msg100] = PlotDoubleCMC(app.folderName, app.obsFileName, app.PRN, app.maxLimit, app.epochDate, 160, settings);
                [L1outliers, L5outliers, msg101] = PlotDoubleCMC(app.folderName, app.obsFileName, app.PRN, app.maxLimit, app.epochDate, 161, settings);

                %--- Show outliers in UI
                app.UITable_Q1f_L1.Data = L1outliers(:, 2:end);
                app.UITable_Q1f_L5.Data = L5outliers(:, 2:end);

                msg102 = sprintf("SETTINGS:\nWindow size: %d, Outlier threshold: %.1f %c", settings.window, settings.threshold, 963);

                app.ExperimentSuccessLamp_Q1f.Color = app.colorGreen;
                app.SaveResultsButton_Q1f.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1f.Value = sprintf("%s\n%s\n%s\n%s",expDate, msg100, msg101, msg102);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1f ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q1f.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q1f.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q1f failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: SaveResultsButton_Q1f
        function SaveResultsButton_Q1fPushed(app, event)
            app.ResultsLamp_Q1f.Color = app.colorYellow;
            drawnow;

            try
                % update run counters
                app.expCounters(app.opsWinNum,6) = app.expCounters(app.opsWinNum,6) + 1;

                % update run counters field
                app.RunEditField_Q1f.Value = app.expCounters(app.opsWinNum,6);

                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,6))));
                % datetime dropped-off because does not match with input data folder
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q1f",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                % Save table
                statTable_L1=app.UITable_Q1f_L1.Data;
                statTable_L5=app.UITable_Q1f_L5.Data;
                         
                try
                    if ~isempty(statTable_L1)
                        if istable(statTable_L1)
                            writetable(statTable_L1,fullfile(resultsFolderName, "statTable_L1.xlsx"));
                        else
                            writecell(statTable_L1,fullfile(resultsFolderName, "statTable_L1.xlsx"));
                        end
                    end
                    if ~isempty(statTable_L5)
                        if istable(statTable_L5)
                            writetable(statTable_L5,fullfile(resultsFolderName, "statTable_L5.xlsx"));
                        else
                            writecell(statTable_L5,fullfile(resultsFolderName, "statTable_L5.xlsx"));
                        end
                    end
                catch
                    newMessage = sprintf("Writetable() on excel file has failed.");
                    app.TextArea_Q1f.Value = [app.TextArea_Q1f.Value; newMessage];
                end

                app.ResultsLamp_Q1f.Color = app.colorGreen;
                app.GenerateReportButton_Q1f.Enable = "on";
                app.SaveResultsButton_Q1f.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q1f.Value = [app.TextArea_Q1f.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment Q1f saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,6)));
            catch
                app.ResultsLamp_Q1f.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q1f.Value = [app.TextArea_Q1f.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment Q1f (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,6)));
            end

            scroll(app.TextArea_Q1f, 'bottom');
        end

        % Value changed function: OperationWindowDropDown
        function OperationwindowDropDownValueChanged(app, event)
            try
                %--- Import from file
                opTable = readtable(fullfile(strcat(app.sessionSettings.inputFilePath, filesep, "Ancillary"), "OPTABLE.csv"));
                idx = find(strcmp(opTable.OPID, sscanf(app.OperationWindowDropDown.Value, "OP%s")));

                %--- Update UI
                if strcmp(opTable.Phase(idx),'C')
                    app.PhaseDropDown.Value    = "Commissioning";
                elseif strcmp(opTable.Phase(idx),'T')
                    app.PhaseDropDown.Value    = "Transit";
                elseif strcmp(opTable.Phase(idx),'L')
                    app.PhaseDropDown.Value    = "LLO";
                elseif strcmp(opTable.Phase(idx),'S')
                    app.PhaseDropDown.Value    = "Surface";
                end

                app.ModeDropDown.Value                  = opTable.Mode(idx);
                app.OPStartEditField.Value              = char(opTable.ReferenceOPStartTime_yyyy_mm_ddHh_mm_ssUTC_(idx));
                app.InitialAltitudeREEditField.Value    = string(opTable.InitialAltitude_RE_(idx));

                if isempty(char(opTable.RTP_start_yyyy_mm_ddHh_mm_ssUTC_(idx)))
                    app.RTPStartEditField.Value         = "n.a.";
                else
                    app.RTPStartEditField.Value         = char(opTable.RTP_start_yyyy_mm_ddHh_mm_ssUTC_(idx));
                end
                
                if isempty(char(opTable.RTP_end_yyyy_mm_ddHh_mm_ssUTC_(idx)))
                    app.RTPEndEditField.Value         = "n.a.";
                else
                    app.RTPEndEditField.Value               = char(opTable.RTP_end_yyyy_mm_ddHh_mm_ssUTC_(idx));
                end

                if isempty(char(opTable.SC_Start_yyyy_mm_ddHh_mm_ss_fffUTC_(idx)))
                    app.SCStartEditField.Value          = "n.a.";
                else
                    app.SCStartEditField.Value          = char(opTable.SC_Start_yyyy_mm_ddHh_mm_ss_fffUTC_(idx));
                end

                if isempty(char(opTable.SC_bands(idx)))
                    app.SCBandsDropDown.Value           = "None";
                else
                    app.SCBandsDropDown.Value           = opTable.SC_bands(idx);
                end

                app.L1BitDepthEditField.Value           = string(opTable.L1_E1SCBitDepth_bit_sample_(idx));
                app.L1SamplingFreqEditField.Value       = string(opTable.L1_E1SCSamplingFrequency_Msps_(idx));
                app.L1DurationEditField.Value           = string(opTable.L1_E1SCDuration_ms_(idx));

                app.L5BitDepthEditField.Value           = string(opTable.L5_E5aBitDepth_bit_sample_(idx));
                app.L5SamplingFreqEditField.Value       = string(opTable.L5_E5aSCSamplingFrequency_Msps_(idx));
                app.L5DurationEditField.Value           = string(opTable.L5_E5aSCDuration_ms_(idx));

                %--- Checkboxes
                if contains(app.ModeDropDown.Value, "RTP")
                    app.RTPCheckBox.Value = true;
                else
                    app.RTPCheckBox.Value = false;
                end
                if contains(app.SCBandsDropDown.Value, "L1")
                    app.L1CheckBox.Value = true;
                else
                    app.L1CheckBox.Value = false;
                end
                if contains(app.SCBandsDropDown.Value, "L5")
                    app.L5CheckBox.Value = true;
                else
                    app.L5CheckBox.Value = false;
                end
            catch
                updateConsoleBox(app,"Could not read operations table, please check input folder.");
            end
        end

        % Button pushed function: GenerateReportButton_Q1f
        function GenerateReportButton_Q1fPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1f.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,6))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q1f",filesep,runId);

                %--- OP metadata fields
                opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                    app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                    string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                    app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
                %--- Document metadata fields
                reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
                docMetaFields = [string(datetime("now")), reportID];
                %--- Report metadata fields
                reportMetaFields = ["Q1.f", string(app.expCounters(app.opsWinNum,6)), app.folderName, app.currentOperator];

                %--- Report text content
                repTestFields = [docMetaFields, opMetaFields, reportMetaFields];
                repLogField = app.TextArea_Q1f.Value;
                repOpComment = app.CommentArea_Q1f.Value;

                [msg00] = genReport(resultsFolderName,strcat('Q1f_',reportID),'rptTmpl\RSPC\Q1f_tmpl_v2.dotx',repTestFields,figures,repLogField,repOpComment);

                %--- If flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentOpsWindow,'Q1f',resultsFolderName,strcat('Q1f_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save(strcat("settings",filesep,"OSR.mat"), "OSR_data");

                %---
                app.ReportLamp_Q1f.Color = app.colorGreen;
                app.GenerateReportButton_Q1f.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(app.opsWinNum,6) = true;
                app.Q1f_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s\nStatistics table included in the report is also saved separately in the folder and can be sent to POC", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1f.Value = [app.TextArea_Q1f.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment Q1f saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,6)));
            catch
                app.ReportLamp_Q1f.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1f.Value = [app.TextArea_Q1f.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment Q1f (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,6)));
            end

            scroll(app.TextArea_Q1f, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q1e
        function GenerateReportButton_Q1ePushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1c.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,5))));
                resultsFolderName = strcat(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q1e",filesep,runId);

                %--- OP metadata fields
                opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                    app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                    string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                    app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
                %--- Document metadata fields
                reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
                docMetaFields = [string(datetime("now")), reportID];
                %--- Report metadata fields
                reportMetaFields = ["Q1.e", string(app.expCounters(app.opsWinNum,5)), app.folderName, app.currentOperator];

                %--- Report text content
                repTestFields = [docMetaFields, opMetaFields, reportMetaFields];
                repLogField  = app.TextArea_Q1e.Value;
                repOpComment = app.CommentArea_Q1e.Value;

                [msg1] = genReport(resultsFolderName,strcat('Q1e_',reportID),'rptTmpl\RSPC\Q1e_tmpl_v2.dotx',repTestFields,figures,repLogField,repOpComment,app.tmpConfigData);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentOpsWindow,'Q1e',resultsFolderName,strcat('Q1e_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save(strcat("settings",filesep,"OSR.mat"), "OSR_data");

                % ----
                app.ReportLamp_Q1e.Color = app.colorGreen;
                app.GenerateReportButton_Q1e.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(app.opsWinNum,5) = true;
                app.Q1e_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1e.Value = [app.TextArea_Q1e.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment Q1e saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,5)));
            catch
                app.ReportLamp_Q1e.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1e.Value = [app.TextArea_Q1e.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment Q1e (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,5)));
            end

            scroll(app.TextArea_Q1e, 'bottom');
        end

        % Button pushed function: RunExperimentButton_Q3b
        function RunExperimentButton_Q3bPushed(app, event)
            %% Close previous plots, reset lamps and messages
            close all

            %--- First disable all experiments to avoid interference
            %(activate again when finished running)
            disableExperimentsRunButtons(app);

            app.TextArea_Q3b.Value = "Running experiment...";
            app.CommentArea_Q3b.Value = "No relevant comments.";
            app.ExperimentSuccessLamp_Q3b.Color = app.colorYellow;

            app.ResultsLamp_Q3b.Color = app.colorGray;
            app.ReportLamp_Q3b.Color = app.colorGray;

            %--- Empty tables from previous run
            app.UITable_Q3b_GPS.Data = {};
            app.UITable_Q3b_Galileo.Data = {};

            resetExpButtons(app);
            drawnow;

            %% Remove ppPvt folder to avoid possible conflicts with names
            warning('off','MATLAB:rmpath:DirNotFound');
            addpath(genpath('extSftw\swRx\'));

            try
                % Collect parameters from SwRx panel and RUN
                %--- GPS Signals
                %--- G1C
                if app.G1CCheckBox_2.Value == true
                    acqSettingsG1C.signal            = app.G1CCheckBox_2.Text;
                    acqSettingsG1C.dopplerStep       = app.DopplerstepHzEditField.Value;
                    acqSettingsG1C.forceSSshift      = app.DopplercenteroffsetHzEditField.Value;
                    acqSettingsG1C.searchSpace       = app.DopplerrangeHzEditField.Value;
                    acqSettingsG1C.K                 = app.NonCoherentsumsEditField_8.Value;
                    acqSettingsG1C.acqTcoh           = app.CoherentIntegrationtimesEditField.Value;
                    acqSettingsG1C.acqPfaSys         = app.FalseAlarmProbabilityEditField_8.Value;
                    acqSettingsG1C.codeDopplerSearch = app.CodeDopplerCheckBoxG1C.Value;

                    acqSettingsG1C.acqSatelliteList = 1:32;

                    %fullFilePath = fullfile(app.IQSdir, app.iqsFileList{1});
                    acqSettingsG1C.rawFileL1 = app.iqsFileList{1};
                    acqSettingsG1C.doProbeData = 1;

                    [G1Csettings, acqG1CRes] = NavSASrx_fcn(acqSettingsG1C,app);

                    [msg100] = PlotAcqResults(G1Csettings, acqG1CRes, 311);

                    [msg101, CNoTab] = CompareCNo(app.folderName, app.acqFileName, acqSettingsG1C.signal, acqG1CRes, 312);

                    if ~isempty(app.acqFileName)
                        app.UITable_Q3b_GPS.Data = CNoTab;
                    else
                        % Convert the table to a cell array
                        CNoTabCell = table2cell(CNoTab);

                        if ~isempty(CNoTabCell)
                        % Assign specific columns (1, 3, and 5) of the UITable to the converted cell array
                        app.UITable_Q3b_GPS.Data(:, [1, 3, 5]) = CNoTabCell;
                        end
                    end
                else
                    msg100 = "GPS L1 not selected";
                    msg101 = "";
                end
            catch
                app.ExperimentSuccessLamp_Q3b.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q3b.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q3b failed.\n'));
            end

            try
                if app.E1BCheckBox_2.Value == true
                    acqSettingsE1B.signal               = app.E1BCheckBox_2.Text;
                    acqSettingsE1B.dopplerStep          = app.DopplerStepEditField_12.Value;
                    acqSettingsE1B.forceSSshift         = app.DopplerStepEditField_22.Value;
                    acqSettingsE1B.searchSpace          = app.DopplerStepEditField_23.Value;
                    acqSettingsE1B.K                    = app.NonCoherentsumsEditField_12.Value;
                    acqSettingsE1B.acqTcoh              = app.CoherentIntegrationtimeEditField_12.Value;
                    acqSettingsE1B.acqPfaSys            = app.FalseAlarmProbabilityEditField_12.Value;
                    acqSettingsE1B.codeDopplerSearch    = app.CodeDopplerCheckBoxE1B.Value;

                    acqSettingsE1B.acqSatelliteList = 1:50;
                   
                    %fullFilePath = fullfile(app.IQSdir, app.iqsFileList{1});
                    acqSettingsE1B.rawFileL1 = app.iqsFileList{1};
                    acqSettingsE1B.doProbeData = 0;

                    [E1Bsettings, acqE1BRes] = NavSASrx_fcn(acqSettingsE1B,app);

                    [msg102] = PlotAcqResults(E1Bsettings, acqE1BRes, 313);

                    [msg103, CNoTab_E1B] = CompareCNo(app.folderName, app.acqFileName, acqSettingsE1B.signal, acqE1BRes, 314);

                    app.UITable_Q3b_Galileo.Data = CNoTab_E1B;

                    if ~isempty(app.acqFileName)
                        app.UITable_Q3b_Galileo.Data = CNoTab_E1B;
                    else
                        % Convert the table to a cell array
                        CNoTabCell = table2cell(CNoTab_E1B);

                        % Assign specific columns (1, 3, and 5) of the UITable to the converted cell array
                        if ~isempty(CNoTabCell)
                        app.UITable_Q3b_Galileo.Data(:, [1, 3, 5]) = CNoTabCell;
                        end
                    end
                else
                    msg102 = "Galileo E1 not selected";
                    msg103 = "";
                end

                app.ExperimentSuccessLamp_Q3b.Color = app.colorGreen;
                app.SaveResultsButton_Q3b.Enable = "on";

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q3b.Value = sprintf("%s\n%s\n%s\n%s\n%s", expDate, msg100, msg101, msg102, msg103);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q3b ran succesfully.\n'));
            catch
                app.ExperimentSuccessLamp_Q3b.Color = app.colorRed;

                expDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                app.TextArea_Q3b.Value = sprintf("%s\nExperiment failed!",expDate);
                fprintf(app.logFileId,strcat(expDate,' Experiment Q3b failed.\n'));
            end

            %--- Enable all experiments again
            enableExperimentsRunButtons(app);
        end

        % Button pushed function: GenerateReportButton_Q1d
        function GenerateReportButton_Q1dPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q1d.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,4))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q1d",filesep,runId);

                %--- OP metadata fields
                opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                    app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                    string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                    app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
                %--- Document metadata fields
                reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
                docMetaFields = [string(datetime("now")), reportID];
                %--- Report metadata fields
                reportMetaFields = ["Q1.d", string(app.expCounters(app.opsWinNum,4)), app.folderName, app.currentOperator];

                %--- Report text content
                repTestFields = [docMetaFields, opMetaFields, reportMetaFields];
                repLogField = app.TextArea_Q1d.Value;
                repOpComment = app.CommentArea_Q1d.Value;

                [msg1] = genReport(resultsFolderName,strcat('Q1d_',reportID),'rptTmpl\RSPC\Q1d_tmpl_v2.dotx',repTestFields,figures,repLogField,repOpComment);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentOpsWindow,'Q1d',resultsFolderName,strcat('Q1d_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save(strcat("settings",filesep,"OSR.mat"), "OSR_data");

                % ----
                app.ReportLamp_Q1d.Color = app.colorGreen;
                app.GenerateReportButton_Q1d.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(app.opsWinNum,4) = true;
                app.Q1d_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s\nStatistics table included in the report is also saved separately in the folder", saveDate, resultsFolderName, msg01);
                app.TextArea_Q1d.Value = [app.TextArea_Q1d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment Q1d saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,4)));
            catch
                app.ReportLamp_Q1a.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q1d.Value = [app.TextArea_Q1d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment Q1d (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,4)));
            end

            scroll(app.TextArea_Q1d, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q2a
        function GenerateReportButton_Q2aPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q2a.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,8))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q2a",filesep,runId);

                %--- OP metadata fields
                opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                    app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                    string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                    app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
                %--- Document metadata fields
                reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
                docMetaFields = [string(datetime("now")), reportID];
                %--- Report metadata fields
                reportMetaFields = ["Q2.a", string(app.expCounters(app.opsWinNum,8)), app.folderName, app.currentOperator];

                %--- Report text content
                repTestFields = [docMetaFields, opMetaFields, reportMetaFields];
                repLogField = app.TextArea_Q2a.Value;
                repOpComment = app.CommentArea_Q2a.Value;

                [msg1] = genReport(resultsFolderName,strcat('Q2a_',reportID),'rptTmpl\RSPC\Q2a_tmpl_v2.dotx',repTestFields,figures,repLogField,repOpComment);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentOpsWindow,'Q2a',resultsFolderName,strcat('Q2a_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save(strcat("settings",filesep,"OSR.mat"), "OSR_data");

                % ----
                app.ReportLamp_Q2a.Color = app.colorGreen;
                app.GenerateReportButton_Q2a.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(app.opsWinNum,8) = true;
                app.Q2a_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q2a.Value = [app.TextArea_Q2a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment Q2a saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,8)));
            catch
                app.ReportLamp_Q2a.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q2a.Value = [app.TextArea_Q2a.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment Q2a (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,8)));
            end

            scroll(app.TextArea_Q2a, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q2d
        function GenerateReportButton_Q2dPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q2d.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,11))));
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q2d",filesep,runId);

                %--- OP metadata fields
                opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                    app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                    string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                    app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
                %--- Document metadata fields
                reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
                docMetaFields = [string(datetime("now")), reportID];
                %--- Report metadata fields
                reportMetaFields = ["Q2.d", string(app.expCounters(app.opsWinNum,11)), app.folderName, app.currentOperator];

                %--- Report text content
                repTestFields = [docMetaFields, opMetaFields, reportMetaFields];
                repLogField = app.TextArea_Q2d.Value;
                repOpComment = app.CommentArea_Q2d.Value;

                [msg1] = genReport(resultsFolderName,strcat('Q2d_',reportID),'rptTmpl\RSPC\Q2d_tmpl_v2.dotx',repTestFields,figures,repLogField,repOpComment);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentOpsWindow,'Q2d',resultsFolderName,strcat('Q2d_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save(strcat("settings",filesep,"OSR.mat"), "OSR_data");

                % ----
                app.ReportLamp_Q2d.Color = app.colorGreen;
                app.GenerateReportButton_Q2d.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(app.opsWinNum,11) = true;
                app.Q2d_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q2d.Value = [app.TextArea_Q2d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment Q2d saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,11)));
            catch
                app.ReportLamp_Q2d.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q2d.Value = [app.TextArea_Q2d.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment Q2d (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,11)));
            end

            scroll(app.TextArea_Q2d, 'bottom');
        end

        % Button pushed function: GenerateReportButton_Q3b
        function GenerateReportButton_Q3bPushed(app, event)
            import mlreportgen.dom.*

            app.ReportLamp_Q3b.Color = app.colorYellow;
            drawnow;

            try
                for iFig = 1:length(app.figurePaths)
                    figures(iFig) = Image(app.figurePaths(iFig));
                end

                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,15))));
                resultsFolderName = strcat(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q3b",filesep,runId);

                %--- OP metadata fields
                opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                    app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                    string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                    app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
                %--- Document metadata fields
                reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
                docMetaFields = [string(datetime("now")), reportID];
                %--- Report metadata fields
                reportMetaFields = ["Q3.b", string(app.expCounters(app.opsWinNum,15)), app.folderName, app.currentOperator];

                %--- Report text content
                repTestFields = [docMetaFields, opMetaFields, reportMetaFields];
                repLogField  = app.TextArea_Q3b.Value;
                repOpComment = app.CommentArea_Q3b.Value;

                [msg1] = genReport(resultsFolderName,strcat('Q3b_',reportID),'rptTmpl\RSPC\Q3b_tmpl_v2.dotx',repTestFields,figures,repLogField,repOpComment,app.tmpConfigData);

                % ---- if flagged -> Operation Science Report (OSR)
                msg01 = makeAvailable2OSR(app.currentOpsWindow,'Q3b',resultsFolderName,strcat('Q3b_',reportID),docMetaFields,app);
                OSR_data = app.OSR;
                save(strcat("settings",filesep,"OSR.mat"), "OSR_data");

                % ----
                app.ReportLamp_Q3b.Color = app.colorGreen;
                app.GenerateReportButton_Q3b.Enable = "off";

                %--- Experiment has been completed
                app.expCompleted(app.opsWinNum,15) = true;
                app.Q3b_completed.Color = app.colorGreen;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nReport saved in: %s\n%s", saveDate, resultsFolderName, msg01);
                app.TextArea_Q3b.Value = [app.TextArea_Q3b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Report of experiment Q3b saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,15)));
            catch
                app.ReportLamp_Q3b.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving report", saveDate);
                app.TextArea_Q3b.Value = [app.TextArea_Q3b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving report of experiment Q3b (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,15)));
            end

            scroll(app.TextArea_Q3b, 'bottom');
        end

        % Cell edit callback: UITable_Q1d
        function UITable_Q1dCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: SaveResultsButton_Q3b
        function SaveResultsButton_Q3bPushed(app, event)
            app.ResultsLamp_Q3b.Color = app.colorYellow;
            drawnow;

            try
                % update run counters
                app.expCounters(app.opsWinNum,15) = app.expCounters(app.opsWinNum,15) + 1;

                % update run counters field
                app.RunEditField_Q3b.Value = app.expCounters(app.opsWinNum,15);

                % Save figures
                runId = strcat("r",sprintf("%02d",(app.expCounters(app.opsWinNum,15))));
                % datetime dropped-off because does not match with input data folder
                resultsFolderName = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow,filesep,"exp_Q3b",filesep,runId);   % Your destination folder
                app.figurePaths = saveFigs(resultsFolderName);

                % Save table
                statTable_GPS=app.UITable_Q3b_GPS.Data;
                statTable_Galileo=app.UITable_Q3b_Galileo.Data;

                if ~isempty(statTable_GPS)
                    if istable(statTable_GPS)
                        writetable(statTable_GPS,fullfile(resultsFolderName, "statTable_GPS.xlsx"));
                    else
                        writecell(statTable_GPS,fullfile(resultsFolderName, "statTable_GPS.xlsx"));
                    end
                end

                if ~isempty(statTable_Galileo)
                    if istable(statTable_Galileo)
                        writetable(statTable_Galileo,fullfile(resultsFolderName, "statTable_Galileo.xlsx"));
                    else
                        writecell(statTable_Galileo,fullfile(resultsFolderName, "statTable_Galileo.xlsx"));
                    end
                end

                app.ResultsLamp_Q3b.Color = app.colorGreen;
                app.GenerateReportButton_Q3b.Enable = "on";
                app.SaveResultsButton_Q3b.Enable = "off";

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nResults saved in: %s", saveDate, resultsFolderName);
                app.TextArea_Q3b.Value = [app.TextArea_Q3b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Results of experiment Q3b saved (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,15)));
            catch
                app.ResultsLamp_Q3b.Color = app.colorRed;

                saveDate = strcat("[",string(datetime("now",'Format','MMM-dd HH:mm:ss')),"]");
                newMessage = sprintf("%s\nError saving results", saveDate);
                app.TextArea_Q3b.Value = [app.TextArea_Q3b.Value; newMessage];
                fprintf(app.logFileId,sprintf("%s Error saving results of experiment Q3b (Run ID: %d)\n", saveDate, app.expCounters(app.opsWinNum,15)));
            end

            scroll(app.TextArea_Q3b, 'bottom');
        end

        % Button pushed function: OpenReport_Q1a
        function OpenReport_Q1aPushed(app, event)
            % split string in list
            data = split(app.ListBox_Q1a.Value,' ');

            % read from list the filename to be open
            toFind = string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1a(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1a(reportIdx,2)),'.pdf'), 'pdf');

        end

        % Button pushed function: DeleteReport_Q1a
        function DeleteReport_Q1aPushed(app, event)
            %--- Get current items in the list box
            items = app.ListBox_Q1a.Items;

            %--- Get index of the selected item
            selectedIndex = app.ListBox_Q1a.Value;

            %--- Check if list is empty or only has one item
            if isempty(items) || isscalar(items)
                app.ListBox_Q1a.Items = {};
            else
                %--- Remove selected item from the list
                items(strcmp(items, selectedIndex)) = [];
                %--- Update list box
                app.ListBox_Q1a.Items = items;

                %--- Adjust value if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1a.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1a.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1a
        function AddReport_Q1aButtonPushed(app, event)
            %--- Add to the textbox and check experiment checkbox
            app.CheckBox_Q1a.Value=true;
            app.EditField_Q1a_OSR.Value=app.ListBox_Q1a.Value;

            %-- Add to a temporary list for the generation of the OSR
            % split string in list
            data = split(app.ListBox_Q1a.Value,' ');

            %--- Read from list the filename to be open
            toFind = string(data(1));

            %--- Look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1a(:, 3)));
            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1a(reportIdx,2)),'.pdf')};

            insertPosition = 1;
            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q1b
        function OpenReport_Q1bPushed(app, event)
            %--- Split string in list
            data = split(app.ListBox_Q1b.Value,' ');

            %--- Read from list the filename to be open
            toFind =string(data(1));

            %--- Look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1b(:, 3)));

            %--- Open for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1b(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q1b
        function DeleteReport_Q1bPushed(app, event)

            % Get the current items in the list box
            items = app.ListBox_Q1b.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q1b.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q1b.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q1b.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1b.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1b.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1b
        function AddReport_Q1bPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q1b.Value=true;
            app.EditField_Q1b_OSR.Value=app.ListBox_Q1b.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q1b.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1b(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1b(reportIdx,2)),'.pdf')};

            insertPosition = 2;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: GenerateReportButton_OSR
        function GenerateReportButton_OSRPushed(app, event)
            %--- Specify the output file name
            % Define the location of the output file
            resultsFolderNameOSR = fullfile(app.sessionSettings.outputFilePath,filesep,app.currentOpsWindow);

            %--- OP metadata fields
            opMetaFields = [app.OperationWindowDropDown.Value, app.PhaseDropDown.Value, app.ModeDropDown.Value, ...
                app.OPStartEditField.Value, app.InitialAltitudeREEditField.Value, ...
                string(app.RTPCheckBox.Value), app.RTPStartEditField.Value, app.RTPEndEditField.Value, ...
                app.SCStartEditField.Value, app.SCBandsDropDown.Value, string(app.L1CheckBox.Value), string(app.L5CheckBox.Value)];
            %--- Document metadata fields
            reportID = string(generateReportSerial([opMetaFields, string(datetime("now"))]));
            docMetaFields = [string(datetime("now")), reportID];
            repTestFields = [docMetaFields, opMetaFields];
            oprtComment = app.OSR_comments.Value;

            %--- Convert the cell array to a comma-separated list of arguments
            pdfList = app.OSR_reportList;

            %--- Initialize the result string array
            resultArray = cell(1, length(pdfList));

            %--- Iterate over each element in pdfList
            for i = 1:length(pdfList)
                if isempty(pdfList{i})
                    resultArray{i} = '☐';
                else
                    resultArray{i} = '☑';
                end
            end

            %--- Name files
            coverFile = strcat(resultsFolderNameOSR,'\',app.currentOpsWindow,'_OSR_cover');
            outputFile = strcat(resultsFolderNameOSR,'\',app.currentOpsWindow, sprintf('_OSR_%s', reportID));

            %--- Generate OSR cover page (specific for the current OPS)
            [msg] = genOSRCover(coverFile,'rptTmpl\OSR\General_OSR.dotx', [repTestFields string(resultArray)], oprtComment);

            %--- Remove empty items
            nonEmptyPdfList = [{strcat(coverFile, ".pdf")} pdfList(~cellfun('isempty', pdfList))];

            %--- Concatenate the PDFs
            try
                mergePdfs(string(nonEmptyPdfList), strcat(outputFile,".pdf"));
                rptview(outputFile, 'pdf')
                app.currentWindowOSR = outputFile;
                app.OSRLamp.Color    = app.colorGreen;
            catch
                app.OSRLamp.Color    = app.colorRed;
            end
        end

        % Button pushed function: OpenReport_Q1c
        function OpenReport_Q1cPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q1c.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1c(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1c(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q1c
        function DeleteReport_Q1cPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q1c.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q1c.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q1c.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q1c.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1c.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1c.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1c
        function AddReport_Q1cPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q1c.Value=true;
            app.EditField_Q1c_OSR.Value=app.ListBox_Q1c.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q1c.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1c(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1c(reportIdx,2)),'.pdf')};

            insertPosition = 3;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q1d
        function OpenReport_Q1dPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q1d.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1d(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1d(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReportQ1d
        function DeleteReportQ1dPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q1d.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q1d.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q1d.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q1d.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1d.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1d.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1d
        function AddReport_Q1dPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q1d.Value=true;
            app.EditField_Q1d_OSR.Value=app.ListBox_Q1d.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q1d.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1d(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1d(reportIdx,2)),'.pdf')};

            insertPosition = 4;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q1e
        function OpenReport_Q1ePushed(app, event)
            % split string in list
            data=split(app.ListBox_Q1e.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1e(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1e(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q1e
        function DeleteReport_Q1ePushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q1e.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q1e.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q1e.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q1e.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1e.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1e.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1e
        function AddReport_Q1ePushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q1e.Value=true;
            app.EditField_Q1e_OSR.Value=app.ListBox_Q1e.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q1e.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1e(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1e(reportIdx,2)),'.pdf')};

            insertPosition = 5;

            app.OSR_reportList{insertPosition} = string(newreport);


        end

        % Button pushed function: OpenReport_Q1f
        function OpenReport_Q1fPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q1f.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1f(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q1f(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q1f
        function DeleteReport_Q1fPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q1f.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q1f.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q1f.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q1f.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q1f.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q1f.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q1f
        function AddReport_Q1fPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q1f.Value=true;
            app.EditField_Q1f_OSR.Value=app.ListBox_Q1f.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q1f.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q1f(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q1f(reportIdx,2)),'.pdf')};

            insertPosition = 6;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q2a
        function OpenReport_Q2aPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q2a.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2a(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q2a(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q2a
        function DeleteReport_Q2aPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q2a.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q2a.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q2a.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q2a.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q2a.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q2a.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q2a
        function AddReport_Q2aPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q2a.Value=true;
            app.EditField_Q2a_OSR.Value=app.ListBox_Q2a.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q2a.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2a(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q2a(reportIdx,2)),'.pdf')};

            insertPosition = 7;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q2c
        function OpenReport_Q2cPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q2c.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2c(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q2c(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q2c
        function DeleteReport_Q2cPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q2c.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q2c.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q2c.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q2c.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q2c.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q2c.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q2c
        function AddReport_Q2cPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q2c.Value=true;
            app.EditField_Q2c_OSR.Value=app.ListBox_Q2c.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q2c.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2c(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q2c(reportIdx,2)),'.pdf')};

            insertPosition = 9;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q2d
        function OpenReport_Q2dPushed(app, event)
            % split string in list
            data=split(app.ListBox_Q2d.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2d(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q2d(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q2d
        function DeleteReport_Q2dPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q2d.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q2d.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q2d.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q2d.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q2d.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q2d.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q2d
        function AddReport_Q2dPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q2d.Value=true;
            app.EditField_Q2d_OSR.Value=app.ListBox_Q2d.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q2d.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q2d(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q2d(reportIdx,2)),'.pdf')};

            insertPosition = 10;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q3a
        function OpenReport_Q3aPushed(app, event)
            % read from list the filename to be open
            % split string in list
            data=split(app.ListBox_Q3a.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q3a(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q3a(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q3a
        function DeleteReport_Q3aPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q3a.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q3a.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q3a.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q3a.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q3a.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q3a.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q3a
        function AddReport_Q3aPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q3a.Value=true;
            app.EditField_Q3a_OSR.Value=app.ListBox_Q3a.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q3a.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q3a(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q3a(reportIdx,2)),'.pdf')};

            insertPosition = 11;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: OpenReport_Q3b
        function OpenReport_Q3bPushed(app, event)
            % read from list the filename to be open
            % split string in list
            data=split(app.ListBox_Q3b.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q3b(:, 3)));

            % Open it for visualization
            rptview(strcat(string(app.OSR.(app.currentOpsWindow).Q3b(reportIdx,2)),'.pdf'), 'pdf');
        end

        % Button pushed function: DeleteReport_Q3b
        function DeleteReport_Q3bPushed(app, event)
            % Get the current items in the list box
            items = app.ListBox_Q3b.Items;

            % Get the index of the selected item
            selectedIndex = app.ListBox_Q3b.Value;

            % Check if the list is empty or only has one item
            if isempty(items) || isscalar(items)
                % If the list is empty or has only one item, clear the list box
                app.ListBox_Q3b.Items = {};
            else
                % Remove the selected item from the list
                items(strcmp(items, selectedIndex)) = [];

                % Update the list box with the new list
                app.ListBox_Q3b.Items = items;

                % Adjust the Value property if the selected index was the last item
                if any(strcmp(selectedIndex, items))
                    app.ListBox_Q3b.Value = items{end};  % Select the last item in the updated list
                else
                    app.ListBox_Q3b.Value = items{1};  % Select the first item in the updated list
                end
            end
        end

        % Button pushed function: AddReport_Q3b
        function AddReport_Q3bPushed(app, event)
            % Add to the textbox and check experiment checkbox

            app.CheckBox_Q3b.Value=true;
            app.EditField_Q3b_OSR.Value=app.ListBox_Q3b.Value;

            % Add to a temporary list for the generation of the OSR

            % split string in list
            data=split(app.ListBox_Q3b.Value,' ');

            % read from list the filename to be open
            toFind=string(data(1));

            % look for filepath
            reportIdx = find(cellfun(@(x) isequal(x{1}, toFind), app.OSR.(app.currentOpsWindow).Q3b(:, 3)));

            newreport = {strcat(string(app.OSR.(app.currentOpsWindow).Q3b(reportIdx,2)),'.pdf')};

            insertPosition = 12;

            app.OSR_reportList{insertPosition} = string(newreport);
        end

        % Button pushed function: RestorelistsButton
        function RestorelistsButtonPushed(app, event)
            %--- Repopulate lists
            try
                app.ListBox_Q1a.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1a(:,4)));
            catch
            end
            try
                app.ListBox_Q1b.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1b(:,4)));
            catch
            end
            try
                app.ListBox_Q1c.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1c(:,4)));
            catch
            end
            try
                app.ListBox_Q1d.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1d(:,4)));
            catch
            end
            try
                app.ListBox_Q1e.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1e(:,4)));
            catch
            end
            try
                app.ListBox_Q1f.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q1f(:,4)));
            catch
            end
            try
                app.ListBox_Q2a.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q2a(:,4)));
            catch
            end
            try
                app.ListBox_Q2c.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q2c(:,4)));
            catch
            end
            try
                app.ListBox_Q2d.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q2d(:,4)));
            catch
            end
            try
                app.ListBox_Q3a.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q3a(:,4)));
            catch
            end
            try
                app.ListBox_Q3b.Items=flipud(string(app.OSR.(app.currentOpsWindow).Q3b(:,4)));
            catch
            end
        end

        % Value changed function: E1BCheckBox_2, G1CCheckBox_2
        function G1CCheckBox_2ValueChanged(app, event)
            valueG1C = app.G1CCheckBox_2.Value;
            valueE1B = app.E1BCheckBox_2.Value;

            if ~valueG1C
                app.FalseAlarmProbabilityEditField_8.Enable="off";
                app.CoherentIntegrationtimesEditField.Enable="off";
                app.NonCoherentsumsEditField_8.Enable="off";
                app.DopplerrangeHzEditField.Enable="off";
                app.DopplercenteroffsetHzEditField.Enable="off";
                app.DopplerstepHzEditField.Enable="off";
                app.CodeDopplerCheckBoxG1C.Enable="off";
            else
                app.FalseAlarmProbabilityEditField_8.Enable="on";
                app.CoherentIntegrationtimesEditField.Enable="on";
                app.NonCoherentsumsEditField_8.Enable="on";
                app.DopplerrangeHzEditField.Enable="on";
                app.DopplercenteroffsetHzEditField.Enable="on";
                app.DopplerstepHzEditField.Enable="on";
                app.CodeDopplerCheckBoxG1C.Enable="on";
            end

            if ~valueE1B
                app.DopplerStepEditField_12.Enable="off";
                app.DopplerStepEditField_22.Enable="off";
                app.DopplerStepEditField_23.Enable="off";
                app.DopplerStepEditField_22.Enable="off";
                app.FalseAlarmProbabilityEditField_12.Enable="off";
                app.CoherentIntegrationtimeEditField_12.Enable="off";
                app.NonCoherentsumsEditField_12.Enable="off";
                app.CodeDopplerCheckBoxE1B.Enable="off";
            else
                app.DopplerStepEditField_12.Enable="on";
                app.DopplerStepEditField_22.Enable="on";
                app.DopplerStepEditField_23.Enable="on";
                app.DopplerStepEditField_22.Enable="on";
                app.FalseAlarmProbabilityEditField_12.Enable="on";
                app.CoherentIntegrationtimeEditField_12.Enable="on";
                app.NonCoherentsumsEditField_12.Enable="on";
                app.CodeDopplerCheckBoxE1B.Enable="on";
            end
        end

        % Button pushed function: inspectOverrideOutputFolder_2
        function inspectOverrideOutputFolderButtonPushed(app, event)
            selpath = uigetdir(path);

            drawnow;
            figure(app.AnalysisToolUIFigure);

            %--- Ovveride into local variable and GUI field
            app.sessionSettings.outputFilePath = selpath;
            app.outputDataEditField.Value = app.sessionSettings.outputFilePath;

            %--- Ovveride settings for the next session
            settings = app.sessionSettings;

            %--- Update both matlab and txt
            updateConfigFile('settings\config.txt', settings);

            %--- Check folder attributes
            [~,outPathValues] = fileattrib(app.sessionSettings.outputFilePath);

            if exist(app.sessionSettings.outputFilePath, 'dir') && outPathValues.UserWrite
                app.OutputDataPathLamp.Color = app.colorGreen;
            else
                app.OutputDataPathLamp.Color = app.colorRed;
                updateConsoleBox(app,"[CONGIF.ERR_01] Output data path not found or no writing permission.")
            end

            %app.outputDataEditField.Enable = "on";
        end

        % Button pushed function: inspectOverrideInputFolder
        function inspectOverrideInputFolderButtonPushed2(app, event)
            selpath = uigetdir(path);

            drawnow;
            figure(app.AnalysisToolUIFigure);

            %--- Ovveride into local variable and GUI field
            app.sessionSettings.inputFilePath = selpath;
            app.inputDataEditField.Value = app.sessionSettings.inputFilePath;

            %--- Ovveride settings for the next session
            settings = app.sessionSettings;

            %--- Update both matlab and txt
            updateConfigFile('settings\config.txt', settings);

            %--- Check folder attributes
            [~,inPathValues] = fileattrib(app.sessionSettings.inputFilePath);

            if exist(app.sessionSettings.inputFilePath, 'dir') && inPathValues.UserRead
                app.InputDataPathLamp.Color = app.colorGreen;
            else
                app.InputDataPathLamp.Color = app.colorRed;
                updateConsoleBox(app,"[CONGIF.ERR_00] Input data path not found or no writing permission.")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AnalysisToolUIFigure = uifigure('Visible', 'off');
            app.AnalysisToolUIFigure.Name = 'Analysis Tool';
            app.AnalysisToolUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'logos', 'LuGRE Insignia.png');
            app.AnalysisToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnalysisToolUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.AnalysisToolUIFigure);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.Label_11 = uilabel(app.ConfigurationTab);
            app.Label_11.Tooltip = {'Matlab version 2022a or later is suggested. Some of the functionalities used by the tool might be uncompatible with older versions.'};
            app.Label_11.Text = '';
            app.Image = uiimage(app.ConfigurationTab);
            app.Image.Tooltip = {''};
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'logos', 'NavSAS_logo_intero.png');
            app.LuGRENavigationAnalysisandReportingToolQuickLookToolLabel = uilabel(app.ConfigurationTab);
            app.LuGRENavigationAnalysisandReportingToolQuickLookToolLabel.Text = 'LuGRE Navigation Analysis and Reporting Tool (v1.0)';
            app.Panel_18 = uipanel(app.ConfigurationTab);
            app.SessionsettingsPanel = uipanel(app.Panel_18);
            app.SessionsettingsPanel.Title = 'Session settings';
            app.Panel_17 = uipanel(app.SessionsettingsPanel);
            app.inspectOverrideInputFolder = uibutton(app.Panel_17, 'push');
            app.inspectOverrideInputFolder.ButtonPushedFcn = createCallbackFcn(app, @inspectOverrideInputFolderButtonPushed2, true);
            app.inspectOverrideInputFolder.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-folder-50.png');
            app.inspectOverrideInputFolder.Tooltip = {''};
            app.inspectOverrideInputFolder.Text = '';
            app.inputDataEditField = uitextarea(app.Panel_17);
            app.inputDataEditField.Editable = 'off';
            app.inputDataEditField.Tooltip = {'Select root folder of LuGRE dataset'};
            app.OperatornameIDDropDownLabel_3 = uilabel(app.Panel_17);
            app.OperatornameIDDropDownLabel_3.Text = 'Input folder';
            app.OperatorDropDownLabel = uilabel(app.Panel_17);
            app.OperatorDropDownLabel.Text = 'Operator ';
            app.OperatorDropDown = uidropdown(app.Panel_17);
            app.OperatorDropDown.Items = {'Operator 1', 'Operator 2', 'Operator 3'};
            app.OperatorDropDown.Tooltip = {'Operator name is included in the reports, you can edit the list from "settings/config.txt"'};
            app.OperatorDropDown.Value = 'Operator 1';
            app.InputDataPathLamp = uilamp(app.Panel_17);
            app.InputDataPathLamp.Tooltip = {'If the lamp is red, the selected folder does not exist or you do not have read permission'};
            app.outputDataEditField = uitextarea(app.Panel_17);
            app.outputDataEditField.Editable = 'off';
            app.outputDataEditField.Tooltip = {'Select the folder in which you want to save results'};
            app.OperatornameIDDropDownLabel_4 = uilabel(app.Panel_17);
            app.OperatornameIDDropDownLabel_4.Text = 'Output folder';
            app.inspectOverrideOutputFolder_2 = uibutton(app.Panel_17, 'push');
            app.inspectOverrideOutputFolder_2.ButtonPushedFcn = createCallbackFcn(app, @inspectOverrideOutputFolderButtonPushed, true);
            app.inspectOverrideOutputFolder_2.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-folder-50.png');
            app.inspectOverrideOutputFolder_2.Tooltip = {''};
            app.inspectOverrideOutputFolder_2.Text = '';
            app.OutputDataPathLamp = uilamp(app.Panel_17);
            app.OutputDataPathLamp.Tooltip = {'If the lamp is red, the selected folder does not exist or you do not have write permission'};
            app.LoadMissionDataLabel = uilabel(app.SessionsettingsPanel);
            app.LoadMissionDataLabel.Text = 'Load Mission Data';
            app.OPInfoLabel = uilabel(app.SessionsettingsPanel);
            app.OPInfoLabel.Text = 'OP Info';
            app.LoadDataButton = uibutton(app.SessionsettingsPanel, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Tooltip = {'Parse telemetry data'};
            app.LoadDataButton.Text = 'Load Data';
            app.RealTimeProcessingLabel = uilabel(app.SessionsettingsPanel);
            app.RealTimeProcessingLabel.Text = 'Real-Time Processing';
            app.SampleCaptureLabel = uilabel(app.SessionsettingsPanel);
            app.SampleCaptureLabel.Text = 'Sample Capture';
            app.L1E1Label = uilabel(app.SessionsettingsPanel);
            app.L1E1Label.Text = 'L1/E1';
            app.L5E5Label = uilabel(app.SessionsettingsPanel);
            app.L5E5Label.Text = 'L5/E5';
            app.RTPCheckBox = uicheckbox(app.SessionsettingsPanel);
            app.RTPCheckBox.Enable = 'off';
            app.RTPCheckBox.Text = '';
            app.L1CheckBox = uicheckbox(app.SessionsettingsPanel);
            app.L1CheckBox.Enable = 'off';
            app.L1CheckBox.Text = '';
            app.L5CheckBox = uicheckbox(app.SessionsettingsPanel);
            app.L5CheckBox.Enable = 'off';
            app.L5CheckBox.Text = '';
            app.OperationWindowLabel = uilabel(app.SessionsettingsPanel);
            app.OperationWindowLabel.Text = 'Select Operation Window';
            app.OperationWindowDropDown = uidropdown(app.SessionsettingsPanel);
            app.OperationWindowDropDown.Items = {'OP1_0', 'OP2_0', 'OP3_0', 'OP5_0', 'OP9_0', 'OP12_0', 'OP14_0', 'OP17_0', 'OP18_0', 'OP21_0', 'OP22_0', 'OP23_0', 'OP27_0', 'OP31_0', 'OP32_0', 'OP37_0', 'OP38_0', 'OP40_0', 'OP73_0', 'OP74_0', 'OP76_0', 'OP77_0', 'OP77_1', 'OP78_0', 'OP78_1'};
            app.OperationWindowDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationwindowDropDownValueChanged, true);
            app.OperationWindowDropDown.Value = 'OP1_0';
            app.InitialAltitudeREEditFieldLabel = uilabel(app.SessionsettingsPanel);
            app.InitialAltitudeREEditFieldLabel.Text = 'Initial Altitude [RE]';
            app.InitialAltitudeREEditField = uieditfield(app.SessionsettingsPanel, 'text');
            app.InitialAltitudeREEditField.Editable = 'off';
            app.InitialAltitudeREEditField.Value = 'n.a.';
            app.RTPStartEditFieldLabel = uilabel(app.SessionsettingsPanel);
            app.RTPStartEditFieldLabel.Text = 'RTP Start';
            app.RTPStartEditField = uieditfield(app.SessionsettingsPanel, 'text');
            app.RTPStartEditField.Tooltip = {''};
            app.RTPStartEditField.Value = 'n.a.';
            app.OPStartEditFieldLabel = uilabel(app.SessionsettingsPanel);
            app.OPStartEditFieldLabel.Text = 'OP Start';
            app.OPStartEditField = uieditfield(app.SessionsettingsPanel, 'text');
            app.OPStartEditField.Editable = 'off';
            app.OPStartEditField.Tooltip = {'Operation start time in UTC (Formtat: HH:mm:ss)'};
            app.OPStartEditField.Value = 'n.a.';
            app.PhaseDropDownLabel = uilabel(app.SessionsettingsPanel);
            app.PhaseDropDownLabel.Enable = 'off';
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.SessionsettingsPanel);
            app.PhaseDropDown.Items = {'Commissioning', 'Transit', 'LLO', 'Surface'};
            app.PhaseDropDown.Enable = 'off';
            app.PhaseDropDown.Value = 'Commissioning';
            app.ModeDropDownLabel = uilabel(app.SessionsettingsPanel);
            app.ModeDropDownLabel.Enable = 'off';
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.SessionsettingsPanel);
            app.ModeDropDown.Items = {'SC-RTP', 'RTP-SC', 'RTP', 'SC'};
            app.ModeDropDown.Enable = 'off';
            app.ModeDropDown.Value = 'SC-RTP';
            app.RTPEndEditFieldLabel = uilabel(app.SessionsettingsPanel);
            app.RTPEndEditFieldLabel.Text = 'RTP End';
            app.RTPEndEditField = uieditfield(app.SessionsettingsPanel, 'text');
            app.RTPEndEditField.Tooltip = {''};
            app.RTPEndEditField.Value = 'n.a.';
            app.SCStartEditFieldLabel = uilabel(app.SessionsettingsPanel);
            app.SCStartEditFieldLabel.Text = 'SC Start';
            app.SCStartEditField = uieditfield(app.SessionsettingsPanel, 'text');
            app.SCStartEditField.Editable = 'off';
            app.SCStartEditField.Tooltip = {''};
            app.SCStartEditField.Value = 'n.a.';
            app.SCBandsDropDownLabel = uilabel(app.SessionsettingsPanel);
            app.SCBandsDropDownLabel.Enable = 'off';
            app.SCBandsDropDownLabel.Text = 'SC Bands';
            app.SCBandsDropDown = uidropdown(app.SessionsettingsPanel);
            app.SCBandsDropDown.Items = {'None', 'L1/E1', 'L5/E5a', 'L1/E1 + L5/E5a'};
            app.SCBandsDropDown.Enable = 'off';
            app.SCBandsDropDown.Value = 'None';
            app.BitDepthEditFieldLabel = uilabel(app.SessionsettingsPanel);
            app.BitDepthEditFieldLabel.Text = 'Bit Depth';
            app.L1BitDepthEditField = uieditfield(app.SessionsettingsPanel, 'text');
            app.L1BitDepthEditField.Editable = 'off';
            app.L1BitDepthEditField.Tooltip = {''};
            app.L1BitDepthEditField.Value = 'n.a.';
            app.SamplingFrequencyMspsEditFieldLabel = uilabel(app.SessionsettingsPanel);
            app.SamplingFrequencyMspsEditFieldLabel.Text = {'Sampling '; 'Frequency [Msps]'};
            app.L1SamplingFreqEditField = uieditfield(app.SessionsettingsPanel, 'text');
            app.L1SamplingFreqEditField.Editable = 'off';
            app.L1SamplingFreqEditField.Tooltip = {''};
            app.L1SamplingFreqEditField.Value = 'n.a.';
            app.DurationmsEditFieldLabel = uilabel(app.SessionsettingsPanel);
            app.DurationmsEditFieldLabel.Text = 'Duration [ms]';
            app.L1DurationEditField = uieditfield(app.SessionsettingsPanel, 'text');
            app.L1DurationEditField.Editable = 'off';
            app.L1DurationEditField.Tooltip = {''};
            app.L1DurationEditField.Value = 'n.a.';
            app.DurationmsEditField_2Label = uilabel(app.SessionsettingsPanel);
            app.DurationmsEditField_2Label.Text = 'Duration [ms]';
            app.L5DurationEditField = uieditfield(app.SessionsettingsPanel, 'text');
            app.L5DurationEditField.Editable = 'off';
            app.L5DurationEditField.Tooltip = {''};
            app.L5DurationEditField.Value = 'n.a.';
            app.SamplingFrequencyMspsEditField_2Label = uilabel(app.SessionsettingsPanel);
            app.SamplingFrequencyMspsEditField_2Label.Text = {'Sampling '; 'Frequency [Msps]'};
            app.L5SamplingFreqEditField = uieditfield(app.SessionsettingsPanel, 'text');
            app.L5SamplingFreqEditField.Editable = 'off';
            app.L5SamplingFreqEditField.Tooltip = {''};
            app.L5SamplingFreqEditField.Value = 'n.a.';
            app.BitDepthEditField_2Label = uilabel(app.SessionsettingsPanel);
            app.BitDepthEditField_2Label.Text = 'Bit Depth';
            app.L5BitDepthEditField = uieditfield(app.SessionsettingsPanel, 'text');
            app.L5BitDepthEditField.Editable = 'off';
            app.L5BitDepthEditField.Tooltip = {''};
            app.L5BitDepthEditField.Value = 'n.a.';
            app.ToolstatusPanel = uipanel(app.Panel_18);
            app.ToolstatusPanel.Title = 'Tool status';
            app.ReferenceDataLoadingLabel = uilabel(app.ToolstatusPanel);
            app.ReferenceDataLoadingLabel.Text = '3. IQS data loading ....................................................................................................';
            app.IQSdataloadingLamp = uilamp(app.ToolstatusPanel);
            app.InputDataLoadingLabel = uilabel(app.ToolstatusPanel);
            app.InputDataLoadingLabel.Text = '2. Telemetry data loading ...........................................................................................';
            app.TelemetrydataloadingLamp = uilamp(app.ToolstatusPanel);
            app.ReadyforexperimentsLabel = uilabel(app.ToolstatusPanel);
            app.ReadyforexperimentsLabel.Text = 'Ready for experiments ...............................................................................................';
            app.ReadyforexperimentsLamp = uilamp(app.ToolstatusPanel);
            app.SessionsettingsdatetimeoperatorLabel = uilabel(app.ToolstatusPanel);
            app.SessionsettingsdatetimeoperatorLabel.Text = '1. Session settings .....................................................................................................';
            app.SessionsettingsLamp = uilamp(app.ToolstatusPanel);
            app.ResetSessionButton = uibutton(app.ToolstatusPanel, 'push');
            app.ResetSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSessionButtonPushed, true);
            app.ResetSessionButton.Tooltip = {'Close plots, clear all variables and corresponding UI elements'};
            app.ResetSessionButton.Text = 'Reset Session';
            app.ConsolemessagesLogPanel = uipanel(app.Panel_18);
            app.ConsolemessagesLogPanel.Title = 'Console messages (Log)';
            app.consoleWindow = uitextarea(app.ConsolemessagesLogPanel);
            app.consoleWindow.Editable = 'off';
            app.Image_2 = uiimage(app.ConfigurationTab);
            app.Image_2.Tooltip = {''};
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'images', 'logos', 'LuGRE Insignia.png');
            app.Image_3 = uiimage(app.ConfigurationTab);
            app.Image_3.Tooltip = {''};
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'images', 'logos', 'NASA_logo.png');
            app.Image_4 = uiimage(app.ConfigurationTab);
            app.Image_4.Tooltip = {''};
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'images', 'logos', 'Logo-ASI-2019.png');
            app.LuGRENavigationAnalysisandReportingToolLabel = uilabel(app.ConfigurationTab);
            app.LuGRENavigationAnalysisandReportingToolLabel.Text = 'LuGRE Navigation Analysis and Reporting Tool';
            app.VisualizationandAnalysisToolfortheLuGREMissionLabel_5 = uilabel(app.ConfigurationTab);
            app.VisualizationandAnalysisToolfortheLuGREMissionLabel_5.Text = 'NavSAS - Politecnico di Torino | Department of Electronics and Telecommunications';
            app.LuNARTLabel = uilabel(app.ConfigurationTab);
            app.LuNARTLabel.Text = 'LuNART';
            app.Image2 = uiimage(app.ConfigurationTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-info-50.png');
            app.Image3 = uiimage(app.ConfigurationTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'icons', 'lunart_ico.png');
            app.ScientificExperimentsTab = uitab(app.TabGroup);
            app.ScientificExperimentsTab.Title = 'Scientific Experiments';
            app.TabGroup3 = uitabgroup(app.ScientificExperimentsTab);
            app.Q1aTab = uitab(app.TabGroup3);
            app.Q1aTab.Title = 'Q1.a';
            app.Label_12 = uilabel(app.Q1aTab);
            app.Label_12.Text = '';
            app.RunExperimentButton_Q1a = uibutton(app.Q1aTab, 'push');
            app.RunExperimentButton_Q1a.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1aPushed, true);
            app.RunExperimentButton_Q1a.Enable = 'off';
            app.RunExperimentButton_Q1a.Text = 'Run Experiment';
            app.OutputPanel_15 = uipanel(app.Q1aTab);
            app.OutputPanel_15.Title = 'Output';
            app.TextArea_Q1a = uitextarea(app.OutputPanel_15);
            app.TextArea_Q1a.Editable = 'off';
            app.TextArea_Q1a.Enable = 'off';
            app.GenerateReportButton_Q1a = uibutton(app.Q1aTab, 'push');
            app.GenerateReportButton_Q1a.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1aPushed, true);
            app.GenerateReportButton_Q1a.Enable = 'off';
            app.GenerateReportButton_Q1a.Tooltip = {'Generate word and pdf report associated to the current run.'};
            app.GenerateReportButton_Q1a.Text = 'Generate Report';
            app.ExperimentSuccessLamp_Q1a = uilamp(app.Q1aTab);
            app.ReportLamp_Q1a = uilamp(app.Q1aTab);
            app.SaveResultsButton_Q1a = uibutton(app.Q1aTab, 'push');
            app.SaveResultsButton_Q1a.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1aPushed, true);
            app.SaveResultsButton_Q1a.Enable = 'off';
            app.SaveResultsButton_Q1a.Tooltip = {'Save figures and relevant results from the experiment.'};
            app.SaveResultsButton_Q1a.Text = 'Save Results';
            app.ResultsLamp_Q1a = uilamp(app.Q1aTab);
            app.OperatorCommentincludedinthereportPanel = uipanel(app.Q1aTab);
            app.OperatorCommentincludedinthereportPanel.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1a = uitextarea(app.OperatorCommentincludedinthereportPanel);
            app.CommentArea_Q1a.Value = {'No relevant comments.'};
            app.Label_119 = uilabel(app.Q1aTab);
            app.Label_119.Text = {'This investigation uses the LuGRE receiver to measure signal strength, i.e., carrier-to-noise density Ratio (C/No) from radiometrically-visible GNSS satellites.'; ''; 'This investigation will focus on receiver-reported carrier-to-noise density ratio (C/N0) as a proxy for received signal power. '; 'C/No mission Telemetry RAW are compared to predicted C/No data (if any available).'};
            app.Experiment1aLabel_18 = uilabel(app.Q1aTab);
            app.Experiment1aLabel_18.Text = 'Experiment Q1.a | Measure the signal strength (C/No) throughout the current operations window';
            app.Experiment1aLabel_19 = uilabel(app.Q1aTab);
            app.Experiment1aLabel_19.Text = 'Objective 1 | Receive GNSS signal at the Moon and characterize the signal environment';
            app.Label_121 = uilabel(app.Q1aTab);
            app.Label_121.Text = {'• [Plot - 100] RAW - Measured C/No time series'; '• [Optional Plot - 101] REF- Predicted C/No time series'; '• [Optional Plot - 102] Difference of C/No time series (common visibility between RAW and REF)'};
            app.Experiment1bLabel_8 = uilabel(app.Q1aTab);
            app.Experiment1bLabel_8.Text = 'Expected Science Products';
            app.MethodologyLabel_9 = uilabel(app.Q1aTab);
            app.MethodologyLabel_9.Text = 'Methodology ';
            app.Label_124 = uilabel(app.Q1aTab);
            app.Label_124.Text = {'• C/No time series is plotted both from the RAW and REF'; '• RAW and REF time series are aligned in time to provide consistent comparison and then subtracted from each other'};
            app.runPanel = uipanel(app.Q1aTab);
            app.RunEditFieldLabel = uilabel(app.runPanel);
            app.RunEditFieldLabel.Text = 'Run';
            app.RunEditField_Q1a = uieditfield(app.runPanel, 'numeric');
            app.RunEditField_Q1a.Limits = [0 5000];
            app.RunEditField_Q1a.Editable = 'off';
            app.RunEditField_Q1a.Enable = 'off';
            app.Panel_5 = uipanel(app.Q1aTab);
            app.Label_122 = uilabel(app.Panel_5);
            app.Label_122.Text = '• RAW - C/No measurements';
            app.Label_123 = uilabel(app.Panel_5);
            app.Label_123.Text = {'• REF - Predicted C/No'; ''};
            app.TelemetryInputLampLabel_2 = uilabel(app.Panel_5);
            app.TelemetryInputLampLabel_2.Text = 'Telemetry Input';
            app.ExternalInputLamp_2Label_2 = uilabel(app.Panel_5);
            app.ExternalInputLamp_2Label_2.Text = 'External Input';
            app.Q1bTab = uitab(app.TabGroup3);
            app.Q1bTab.Title = 'Q1.b';
            app.Experiment1aLabel_3 = uilabel(app.Q1bTab);
            app.Experiment1aLabel_3.Text = 'Objective 1 | Receive GNSS signal at the Moon and characterize the signal environment';
            app.Experiment1bLabel = uilabel(app.Q1bTab);
            app.Experiment1bLabel.Text = 'Experiment Q1.b | Determine signal availability throughout the current operations window';
            app.MethodologyLabel_2 = uilabel(app.Q1bTab);
            app.MethodologyLabel_2.Text = 'Methodology ';
            app.Label_18 = uilabel(app.Q1bTab);
            app.Label_18.Text = {'This investigation will analyze the availability of GNSS signals during the selected operations window. '; 'The following parameters are logged during real-time operation of the on-board receiver:'; ''; '•	Tracked signals (i.e., PRNs included in the Telemetry RAW message)'; '•	C/N0 measurements from RAW'; ''; 'Based on the data collected during the current observation window, the availability of a sufficient number of satellites for different purposes (e.g., to obtain a least-squares single point positioning solution) is determined by this experiment throughout each operation window timespan:'; ''; '•       Availability for different minimum acquisition and tracking thresholds can be evaluated for both mission telemetry and predicted data'; '•       The analysis will be performed per frequency (L1, L5, both) and per constellation (GPS, Galileo, both)'; '•       The benefits in terms of availability of a hybrid GPS+Galileo least squares solution using the Galileo-GPS Time Offset (GGTO)'};
            app.Label_20 = uilabel(app.Q1bTab);
            app.Label_20.Text = {'• RAW and REF time series are aligned in time to provide consistent comparison'; '• For each epoch, C/N0 from RAW and REF are used to determine channel visibility '; '• Only C/N0 above the operator-defined thresholds is considered to compute visibility'; '• For constellation visibility, the number of unique visible satellites is counted (i.e., two channels of the same PRN are counted as one)'; '• Availability is computed as percantege of epoch where a PVT soluton can be obtained according to different conditions'};
            app.OutputPanel_2 = uipanel(app.Q1bTab);
            app.OutputPanel_2.Title = 'Output';
            app.TextArea_Q1b = uitextarea(app.OutputPanel_2);
            app.TextArea_Q1b.Editable = 'off';
            app.TextArea_Q1b.Enable = 'off';
            app.ExperimentSuccessLamp_Q1b = uilamp(app.Q1bTab);
            app.RunExperimentButton_Q1b = uibutton(app.Q1bTab, 'push');
            app.RunExperimentButton_Q1b.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1bPushed, true);
            app.RunExperimentButton_Q1b.Enable = 'off';
            app.RunExperimentButton_Q1b.Text = 'Run Experiment';
            app.GenerateReportButton_Q1b = uibutton(app.Q1bTab, 'push');
            app.GenerateReportButton_Q1b.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1bPushed, true);
            app.GenerateReportButton_Q1b.Enable = 'off';
            app.GenerateReportButton_Q1b.Text = 'Generate Report';
            app.SettingsCNoThresholdPanel = uipanel(app.Q1bTab);
            app.SettingsCNoThresholdPanel.Title = 'Settings (C/No Threshold)';
            app.GPSL1SpinnerLabel = uilabel(app.SettingsCNoThresholdPanel);
            app.GPSL1SpinnerLabel.Text = 'GPS L1';
            app.GPSL1Spinner = uispinner(app.SettingsCNoThresholdPanel);
            app.GPSL1Spinner.Step = 0.1;
            app.GPSL1Spinner.Limits = [10 50];
            app.GPSL1Spinner.ValueDisplayFormat = '%11.1f';
            app.GPSL1Spinner.ValueChangedFcn = createCallbackFcn(app, @GPSL1SpinnerValueChanged, true);
            app.GPSL1Spinner.Value = 20;
            app.GPSL5SpinnerLabel = uilabel(app.SettingsCNoThresholdPanel);
            app.GPSL5SpinnerLabel.Text = 'GPS L5';
            app.GPSL5Spinner = uispinner(app.SettingsCNoThresholdPanel);
            app.GPSL5Spinner.Step = 0.1;
            app.GPSL5Spinner.Limits = [10 50];
            app.GPSL5Spinner.ValueDisplayFormat = '%11.1f';
            app.GPSL5Spinner.ValueChangedFcn = createCallbackFcn(app, @GPSL1SpinnerValueChanged, true);
            app.GPSL5Spinner.Value = 20;
            app.GALE1Label = uilabel(app.SettingsCNoThresholdPanel);
            app.GALE1Label.Text = 'GAL E1';
            app.GALE1Spinner = uispinner(app.SettingsCNoThresholdPanel);
            app.GALE1Spinner.Step = 0.1;
            app.GALE1Spinner.Limits = [10 50];
            app.GALE1Spinner.ValueDisplayFormat = '%11.1f';
            app.GALE1Spinner.ValueChangedFcn = createCallbackFcn(app, @GPSL1SpinnerValueChanged, true);
            app.GALE1Spinner.Value = 20;
            app.GALE5Label = uilabel(app.SettingsCNoThresholdPanel);
            app.GALE5Label.Text = 'GAL E5';
            app.GALE5Spinner = uispinner(app.SettingsCNoThresholdPanel);
            app.GALE5Spinner.Step = 0.1;
            app.GALE5Spinner.Limits = [10 50];
            app.GALE5Spinner.ValueDisplayFormat = '%11.1f';
            app.GALE5Spinner.ValueChangedFcn = createCallbackFcn(app, @GPSL1SpinnerValueChanged, true);
            app.GALE5Spinner.Value = 20;
            app.Label_85 = uilabel(app.SettingsCNoThresholdPanel);
            app.Label_85.Text = 'dB-Hz';
            app.Label_86 = uilabel(app.SettingsCNoThresholdPanel);
            app.Label_86.Text = 'dB-Hz';
            app.Label_87 = uilabel(app.SettingsCNoThresholdPanel);
            app.Label_87.Text = 'dB-Hz';
            app.Label_88 = uilabel(app.SettingsCNoThresholdPanel);
            app.Label_88.Text = 'dB-Hz';
            app.Label_89 = uilabel(app.SettingsCNoThresholdPanel);
            app.Label_89.Text = 'Telemetry';
            app.Label_90 = uilabel(app.SettingsCNoThresholdPanel);
            app.Label_90.Text = 'dB-Hz';
            app.Label_91 = uilabel(app.SettingsCNoThresholdPanel);
            app.Label_91.Text = 'dB-Hz';
            app.Label_92 = uilabel(app.SettingsCNoThresholdPanel);
            app.Label_92.Text = 'dB-Hz';
            app.Label_93 = uilabel(app.SettingsCNoThresholdPanel);
            app.Label_93.Text = 'dB-Hz';
            app.GPSL1Spinner_2Label = uilabel(app.SettingsCNoThresholdPanel);
            app.GPSL1Spinner_2Label.Text = 'GPS L1';
            app.GPSL1Spinner_2 = uispinner(app.SettingsCNoThresholdPanel);
            app.GPSL1Spinner_2.Step = 0.1;
            app.GPSL1Spinner_2.Limits = [10 50];
            app.GPSL1Spinner_2.ValueDisplayFormat = '%11.1f';
            app.GPSL1Spinner_2.ValueChangedFcn = createCallbackFcn(app, @GPSL1Spinner_2ValueChanged, true);
            app.GPSL1Spinner_2.Value = 20;
            app.GPSL5Spinner_2Label = uilabel(app.SettingsCNoThresholdPanel);
            app.GPSL5Spinner_2Label.Text = 'GPS L5';
            app.GPSL5Spinner_2 = uispinner(app.SettingsCNoThresholdPanel);
            app.GPSL5Spinner_2.Step = 0.1;
            app.GPSL5Spinner_2.Limits = [10 50];
            app.GPSL5Spinner_2.ValueDisplayFormat = '%11.1f';
            app.GPSL5Spinner_2.ValueChangedFcn = createCallbackFcn(app, @GPSL1Spinner_2ValueChanged, true);
            app.GPSL5Spinner_2.Value = 20;
            app.GALE1Label_2 = uilabel(app.SettingsCNoThresholdPanel);
            app.GALE1Label_2.Text = 'GAL E1';
            app.GALE1Spinner_2 = uispinner(app.SettingsCNoThresholdPanel);
            app.GALE1Spinner_2.Step = 0.1;
            app.GALE1Spinner_2.Limits = [10 50];
            app.GALE1Spinner_2.ValueDisplayFormat = '%11.1f';
            app.GALE1Spinner_2.ValueChangedFcn = createCallbackFcn(app, @GPSL1Spinner_2ValueChanged, true);
            app.GALE1Spinner_2.Value = 20;
            app.GALL5Spinner_2Label_2 = uilabel(app.SettingsCNoThresholdPanel);
            app.GALL5Spinner_2Label_2.Text = 'GAL E5';
            app.GALE5Spinner_2 = uispinner(app.SettingsCNoThresholdPanel);
            app.GALE5Spinner_2.Step = 0.1;
            app.GALE5Spinner_2.Limits = [10 50];
            app.GALE5Spinner_2.ValueDisplayFormat = '%11.1f';
            app.GALE5Spinner_2.ValueChangedFcn = createCallbackFcn(app, @GPSL1Spinner_2ValueChanged, true);
            app.GALE5Spinner_2.Value = 20;
            app.Label_94 = uilabel(app.SettingsCNoThresholdPanel);
            app.Label_94.Text = 'Reference';
            app.LinkvaluesCheckBox = uicheckbox(app.SettingsCNoThresholdPanel);
            app.LinkvaluesCheckBox.Tooltip = {'Tick box to link values of all Telemetry thresholds '};
            app.LinkvaluesCheckBox.Text = 'Link values';
            app.LinkvaluesCheckBox.Value = true;
            app.LinkvaluesCheckBox_2 = uicheckbox(app.SettingsCNoThresholdPanel);
            app.LinkvaluesCheckBox_2.Tooltip = {'Tick box to link values of all Reference thresholds '};
            app.LinkvaluesCheckBox_2.Text = 'Link values';
            app.LinkvaluesCheckBox_2.Value = true;
            app.OperatorCommentincludedinthereportPanel_6 = uipanel(app.Q1bTab);
            app.OperatorCommentincludedinthereportPanel_6.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1b = uitextarea(app.OperatorCommentincludedinthereportPanel_6);
            app.CommentArea_Q1b.Value = {'No relevant comments.'};
            app.ResultsLamp_Q1b = uilamp(app.Q1bTab);
            app.ReportLamp_Q1b = uilamp(app.Q1bTab);
            app.SaveResultsButton_Q1b = uibutton(app.Q1bTab, 'push');
            app.SaveResultsButton_Q1b.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1bPushed, true);
            app.SaveResultsButton_Q1b.Enable = 'off';
            app.SaveResultsButton_Q1b.Text = 'Save Results';
            app.Experiment1bLabel_4 = uilabel(app.Q1bTab);
            app.Experiment1bLabel_4.Text = 'Expected Science Products';
            app.runPanel_Q1b = uipanel(app.Q1bTab);
            app.RunEditField_2Label = uilabel(app.runPanel_Q1b);
            app.RunEditField_2Label.Text = 'Run';
            app.RunEditField_Q1b = uieditfield(app.runPanel_Q1b, 'numeric');
            app.RunEditField_Q1b.Limits = [0 5000];
            app.RunEditField_Q1b.Editable = 'off';
            app.RunEditField_Q1b.Enable = 'off';
            app.Panel_6 = uipanel(app.Q1bTab);
            app.TelemetryInputLabel_3 = uilabel(app.Panel_6);
            app.TelemetryInputLabel_3.Text = {'• RAW - C/N0 measurements'; '• NAV - PVT solution availability'};
            app.ExternalInputLabel_3 = uilabel(app.Panel_6);
            app.ExternalInputLabel_3.Text = '• REF - Predicted C/N0';
            app.TelemetryInputLampLabel = uilabel(app.Panel_6);
            app.TelemetryInputLampLabel.Text = 'Telemetry Input';
            app.ExternalInputLamp_2Label = uilabel(app.Panel_6);
            app.ExternalInputLamp_2Label.Text = 'External Input';
            app.ScienceProductLabel_20 = uilabel(app.Q1bTab);
            app.ScienceProductLabel_20.Text = {'• [Plot - 110] Number of tracked signals per channel, e.g. L1 C/A, E1a, (filtered by user-defined threshold) from Telemetry RAW and Reference'; '• [Plot - 111] Number of visible unique SVs per constellation, i.e., GPS, Galileo, and GPS+Galileo from Telemetry RAW and Reference'; '• [Plot - 112] Single and multi-constellation Single Point Positioning Solution availability and outages (with time percentages)'};
            app.Q1cTab = uitab(app.TabGroup3);
            app.Q1cTab.Title = 'Q1.c';
            app.Experiment1aLabel_4 = uilabel(app.Q1cTab);
            app.Experiment1aLabel_4.Text = 'Objective 1 | Receive GNSS signal at the Moon and characterize the signal environment';
            app.Experiment1cLabel = uilabel(app.Q1cTab);
            app.Experiment1cLabel.Text = 'Experiment Q1.c | Measure pseudorange from visible satellites throughout the current operations window';
            app.OutputPanel_3 = uipanel(app.Q1cTab);
            app.OutputPanel_3.Title = 'Output';
            app.TextArea_Q1c = uitextarea(app.OutputPanel_3);
            app.TextArea_Q1c.Editable = 'off';
            app.TextArea_Q1c.Enable = 'off';
            app.ExperimentSuccessLamp_Q1c = uilamp(app.Q1cTab);
            app.MethodologyLabel_3 = uilabel(app.Q1cTab);
            app.MethodologyLabel_3.Text = 'Methodology ';
            app.RunExperimentButton_Q1c = uibutton(app.Q1cTab, 'push');
            app.RunExperimentButton_Q1c.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1cPushed, true);
            app.RunExperimentButton_Q1c.Enable = 'off';
            app.RunExperimentButton_Q1c.Text = 'Run Experiment';
            app.GenerateReportButton_Q1c = uibutton(app.Q1cTab, 'push');
            app.GenerateReportButton_Q1c.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1cPushed, true);
            app.GenerateReportButton_Q1c.Enable = 'off';
            app.GenerateReportButton_Q1c.Text = 'Generate Report';
            app.Label_96 = uilabel(app.Q1cTab);
            app.Label_96.Text = {'• Pseudorange and range time series is plotted from the RAW and REF respectively'; '• RAW and REF time series are aligned in time to provide consistent comparison and then subtracted from each other'};
            app.OperatorCommentincludedinthereportPanel_7 = uipanel(app.Q1cTab);
            app.OperatorCommentincludedinthereportPanel_7.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1c = uitextarea(app.OperatorCommentincludedinthereportPanel_7);
            app.CommentArea_Q1c.Value = {'No relevant comments.'};
            app.ResultsLamp_Q1c = uilamp(app.Q1cTab);
            app.ReportLamp_Q1c = uilamp(app.Q1cTab);
            app.SaveResultsButton_Q1c = uibutton(app.Q1cTab, 'push');
            app.SaveResultsButton_Q1c.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1cPushed, true);
            app.SaveResultsButton_Q1c.Enable = 'off';
            app.SaveResultsButton_Q1c.Text = 'Save Results';
            app.Label_106 = uilabel(app.Q1cTab);
            app.Label_106.Text = {'This investigation utilizes the LuGRE receiver to collect pseudorange measurements from tracked GNSS satellites signals throughout the selected operations window.  '; ''; 'In-flight pseudorange measurement collection is a precursor to generating onboard and ground-based navigation solutions via least squares estimator and to initialize Bayesian navigation filters; such use cases are addressed in investigations Q2.a, Q2.b, Q2.c and Q3.a.'};
            app.ScienceProductLabel_4 = uilabel(app.Q1cTab);
            app.ScienceProductLabel_4.Text = {'• [Plot - 120] RAW - Raw code pseudorange times series'; '• [Optional Plot - 121] REF - Predicted code pseudorange times series'; '• [Optional Plot - 122] Difference of code pseudorange time series (common visibility)'};
            app.Experiment1bLabel_5 = uilabel(app.Q1cTab);
            app.Experiment1bLabel_5.Text = 'Expected Science Products';
            app.runPanel_Q1c = uipanel(app.Q1cTab);
            app.RunEditField_3Label = uilabel(app.runPanel_Q1c);
            app.RunEditField_3Label.Text = 'Run';
            app.RunEditField_Q1c = uieditfield(app.runPanel_Q1c, 'numeric');
            app.RunEditField_Q1c.Limits = [0 5000];
            app.RunEditField_Q1c.Editable = 'off';
            app.RunEditField_Q1c.Enable = 'off';
            app.Panel_7 = uipanel(app.Q1cTab);
            app.TelemetryInputLabel_4 = uilabel(app.Panel_7);
            app.TelemetryInputLabel_4.Text = '• RAW - Code pseudorange measurements';
            app.ExternalInputLabel_16 = uilabel(app.Panel_7);
            app.ExternalInputLabel_16.Text = {'• REF - Predicted ranges'; ''};
            app.TelemetryInputLamp_2Label = uilabel(app.Panel_7);
            app.TelemetryInputLamp_2Label.Text = 'Telemetry Input';
            app.ExternalInputLampLabel = uilabel(app.Panel_7);
            app.ExternalInputLampLabel.Text = 'External Input';
            app.Q1dTab = uitab(app.TabGroup3);
            app.Q1dTab.Title = 'Q1.d';
            app.Experiment1aLabel_5 = uilabel(app.Q1dTab);
            app.Experiment1aLabel_5.Text = 'Objective 1 | Receive GNSS signal at the Moon and characterize the signal environment';
            app.Experiment1dLabel = uilabel(app.Q1dTab);
            app.Experiment1dLabel.Text = 'Experiment Q1.d | Evaluate pseudorange error from visible satellites throughout current operations window';
            app.Label_24 = uilabel(app.Q1dTab);
            app.Label_24.Text = {'This investigation analyzes pseudorange deviations from the expected value for tracked GNSS satellites signals by comparing the measured values (corrected with the receiver clock bias) with the predicted ranges. Differently from experiment Q1.c (which only reports on-board raw pseudorange measurements), this experiment aims at providing a statistical characterization of their deviations throughout the operations window. The deviation is characterized in terms of mean (error bias) and standard deviation of the measurements noise. '; 'The experiment is performed on both code and phase pseudoranges.'};
            app.OutputPanel_4 = uipanel(app.Q1dTab);
            app.OutputPanel_4.Title = 'Output';
            app.TextArea_Q1d = uitextarea(app.OutputPanel_4);
            app.TextArea_Q1d.Editable = 'off';
            app.TextArea_Q1d.Enable = 'off';
            app.ExperimentSuccessLamp_Q1d = uilamp(app.Q1dTab);
            app.ScienceProductLabel = uilabel(app.Q1dTab);
            app.ScienceProductLabel.Text = {'• [Plot - 130] Finite-difference third derivative time series of code pseudoranges'; '• [Plot - 131] Finite-difference third derivative time series of phase pseudoranges  '; '• [Table - 132] Estimated measurement noise bias and standard deviation from Plot 130 and 131'; '• [Optional Plot - 133] Range error time series plot (difference between GEONS predicted range and RAW raw pseudorange corrected by NAV clock bias)'};
            app.GenerateReportButton_Q1d = uibutton(app.Q1dTab, 'push');
            app.GenerateReportButton_Q1d.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1dPushed, true);
            app.GenerateReportButton_Q1d.Enable = 'off';
            app.GenerateReportButton_Q1d.Text = 'Generate Report';
            app.ReportLamp_Q1d = uilamp(app.Q1dTab);
            app.SaveResultsButton_Q1d = uibutton(app.Q1dTab, 'push');
            app.SaveResultsButton_Q1d.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1dPushed, true);
            app.SaveResultsButton_Q1d.Enable = 'off';
            app.SaveResultsButton_Q1d.Text = 'Save Results';
            app.ResultsLamp_Q1d = uilamp(app.Q1dTab);
            app.OperatorComment_4 = uipanel(app.Q1dTab);
            app.OperatorComment_4.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1d = uitextarea(app.OperatorComment_4);
            app.CommentArea_Q1d.Value = {'No relevant comments.'};
            app.PseudorangeerroranalysisLabel = uilabel(app.Q1dTab);
            app.PseudorangeerroranalysisLabel.Text = 'Pseudorange error analysis';
            app.UITable_Q1d = uitable(app.Q1dTab);
            app.UITable_Q1d.ColumnName = {'Channel'; 'PRN'; 'Code Mean [m]'; 'Code Std [m]'; 'Phase Mean [m]'; 'Phase Std [m]'};
            app.UITable_Q1d.RowName = {};
            app.UITable_Q1d.CellEditCallback = createCallbackFcn(app, @UITable_Q1dCellEdit, true);
            app.Experiment1bLabel_6 = uilabel(app.Q1dTab);
            app.Experiment1bLabel_6.Text = 'Expected Science Products';
            app.Experiment1bLabel_7 = uilabel(app.Q1dTab);
            app.Experiment1bLabel_7.Text = 'Methodology';
            app.runPanel_Q1d = uipanel(app.Q1dTab);
            app.RunEditField_4Label = uilabel(app.runPanel_Q1d);
            app.RunEditField_4Label.Text = 'Run';
            app.RunEditField_Q1d = uieditfield(app.runPanel_Q1d, 'numeric');
            app.RunEditField_Q1d.Limits = [0 5000];
            app.RunEditField_Q1d.Editable = 'off';
            app.RunEditField_Q1d.Enable = 'off';
            app.Label_126 = uilabel(app.Q1dTab);
            app.Label_126.Text = {'• RAW and REF time series are aligned in time to provide consistent comparison and then subtracted from each other'; '•  The method of finite differences is used to compute the third derivative (with 4th order of accuracy) to remove trends from the measurements due to movement of the lender'; '• Mean and standard deviation are computed from the timeseries and shown in the table'; '• The last plot shows the subtraction between RAW pseudoranges corrected with estimated receiver clock bias from NAV and then the REF predicted ranges'};
            app.Panel_8 = uipanel(app.Q1dTab);
            app.TelemetryInputLabel = uilabel(app.Panel_8);
            app.TelemetryInputLabel.Text = {'• RAW - Code pseudorange measurements'; '• RAW - Phase pseudorange measurements'; '• NAV - Bias of the receiver clock'};
            app.ExternalInputLabel = uilabel(app.Panel_8);
            app.ExternalInputLabel.Text = '• REF - Predicted ranges';
            app.TelemetryInputLamp_3Label_2 = uilabel(app.Panel_8);
            app.TelemetryInputLamp_3Label_2.Text = 'External Input';
            app.TelemetryInputLamp_2Label_2 = uilabel(app.Panel_8);
            app.TelemetryInputLamp_2Label_2.Text = 'Telemetry Input';
            app.RunExperimentButton_Q1d = uibutton(app.Q1dTab, 'push');
            app.RunExperimentButton_Q1d.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1dPushed, true);
            app.RunExperimentButton_Q1d.Enable = 'off';
            app.RunExperimentButton_Q1d.Text = 'Run Experiment';
            app.Q1eTab = uitab(app.TabGroup3);
            app.Q1eTab.SizeChangedFcn = createCallbackFcn(app, @Q1eTabSizeChanged, true);
            app.Q1eTab.Title = 'Q1.e';
            app.Experiment1aLabel_6 = uilabel(app.Q1eTab);
            app.Experiment1aLabel_6.Text = 'Objective 1 | Receive GNSS signal at the Moon and characterize the signal environment';
            app.Experiment1eLabel = uilabel(app.Q1eTab);
            app.Experiment1eLabel.Text = 'Experiment Q1.e | Measure Doppler-shift and Doppler-rate profiles throughout the current operations window';
            app.OutputPanel_5 = uipanel(app.Q1eTab);
            app.OutputPanel_5.Title = 'Output';
            app.TextArea_Q1e = uitextarea(app.OutputPanel_5);
            app.TextArea_Q1e.Editable = 'off';
            app.TextArea_Q1e.Enable = 'off';
            app.ExperimentSuccessLamp_Q1e = uilamp(app.Q1eTab);
            app.MethodologyLabel_5 = uilabel(app.Q1eTab);
            app.MethodologyLabel_5.Text = 'Methodology ';
            app.Label_42 = uilabel(app.Q1eTab);
            app.Label_42.Text = {'• Doppler shift and Doppler rate time series are plotted both from the RAW and REF'; '• RAW and REF time series are aligned in time to provide consistent comparison and then subtracted from each other for both Doppler shift and Doppler rate'};
            app.RunExperimentButton_Q1e = uibutton(app.Q1eTab, 'push');
            app.RunExperimentButton_Q1e.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1ePushed, true);
            app.RunExperimentButton_Q1e.Enable = 'off';
            app.RunExperimentButton_Q1e.Text = 'Run Experiment';
            app.GenerateReportButton_Q1e = uibutton(app.Q1eTab, 'push');
            app.GenerateReportButton_Q1e.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1ePushed, true);
            app.GenerateReportButton_Q1e.Enable = 'off';
            app.GenerateReportButton_Q1e.Text = 'Generate Report';
            app.OperatorCommentincludedinthereportPanel_10 = uipanel(app.Q1eTab);
            app.OperatorCommentincludedinthereportPanel_10.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1e = uitextarea(app.OperatorCommentincludedinthereportPanel_10);
            app.CommentArea_Q1e.Value = {'No relevant comments.'};
            app.ReportLamp_Q1e = uilamp(app.Q1eTab);
            app.ResultsLamp_Q1e = uilamp(app.Q1eTab);
            app.SaveResultsButton_Q1e = uibutton(app.Q1eTab, 'push');
            app.SaveResultsButton_Q1e.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1ePushed, true);
            app.SaveResultsButton_Q1e.Enable = 'off';
            app.SaveResultsButton_Q1e.Text = 'Save Results';
            app.Label_107 = uilabel(app.Q1eTab);
            app.Label_107.Text = 'This investigation will utilize the data collected by the LuGRE receiver to depict Doppler shift and Doppler rate profiles of visible GNSS satellites during the transit operational periods or on the lunar surface.';
            app.runPanel_Q1e = uipanel(app.Q1eTab);
            app.RunEditField_5Label = uilabel(app.runPanel_Q1e);
            app.RunEditField_5Label.Text = 'Run';
            app.RunEditField_Q1e = uieditfield(app.runPanel_Q1e, 'numeric');
            app.RunEditField_Q1e.Limits = [0 5000];
            app.RunEditField_Q1e.Editable = 'off';
            app.RunEditField_Q1e.Enable = 'off';
            app.ScienceProductLabel_16 = uilabel(app.Q1eTab);
            app.ScienceProductLabel_16.Text = {'• [Plot - 140] RAW - Doppler shift time series'; '• [Optional Plot - 141] REF - Predicted Doppler shift time series'; '• [Plot - 142] RAW - Doppler rate time series'; '• [Optional Plot - 143] REF - Predicted Doppler rate time series'; '• [Optional Plot - 144] Difference of Doppler shift time series (common visibility)'; '• [Optional Plot - 145] Difference of Doppler rate time series (common visibility)'};
            app.Experiment1bLabel_9 = uilabel(app.Q1eTab);
            app.Experiment1bLabel_9.Text = 'Expected Science Products';
            app.Panel_9 = uipanel(app.Q1eTab);
            app.TelemetryInputLabel_18 = uilabel(app.Panel_9);
            app.TelemetryInputLabel_18.Text = {'• RAW - Doppler shift measurements'; '• RAW - Doppler rate measurements'};
            app.ExternalInputLabel_17 = uilabel(app.Panel_9);
            app.ExternalInputLabel_17.Text = {'• REF - Predicted Doppler shifts'; '• REF - Predicted Doppler rates'};
            app.TelemetryInputLamp_3Label_3 = uilabel(app.Panel_9);
            app.TelemetryInputLamp_3Label_3.Text = 'External Input';
            app.TelemetryInputLamp_2Label_3 = uilabel(app.Panel_9);
            app.TelemetryInputLamp_2Label_3.Text = 'Telemetry Input';
            app.Q1fTab = uitab(app.TabGroup3);
            app.Q1fTab.Title = 'Q1.f';
            app.Experiment1aLabel_7 = uilabel(app.Q1fTab);
            app.Experiment1aLabel_7.Text = 'Objective 1 | Receive GNSS signal at the Moon and characterize the signal environment';
            app.Experiment1fLabel = uilabel(app.Q1fTab);
            app.Experiment1fLabel.Text = 'Experiment Q1.f | Evaluate the presence and characteristics of multipath due to the Blue Ghost lander and/or the lunar surface';
            app.ExperimentSuccessLamp_Q1f = uilamp(app.Q1fTab);
            app.RunExperimentButton_Q1f = uibutton(app.Q1fTab, 'push');
            app.RunExperimentButton_Q1f.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q1fPushed, true);
            app.RunExperimentButton_Q1f.Enable = 'off';
            app.RunExperimentButton_Q1f.Text = 'Run Experiment';
            app.Label_109 = uilabel(app.Q1fTab);
            app.Label_109.Text = {'This investigation aims at identifying potential anomalies affecting GNSS observables due to multipath phenomena. Multipath typically induce biases in both DLL and PLL thus leading to biased pseudorange measurements. The experiment is based on the post-processing of single-frequency (1F) and double-frequency (2F) code and carrier-phase pseudorange measurements through Code-minus-Carrier (CMC) approach and its variants. '; 'Different ways to compute and plot the combination of these measurements allow to isolate certain error contributions, such as:'; '• Composite multipath'; '• Carrier-phase ambiguity'; '• Composite receiver noise'; 'When unsmoothed code measurements are employed, phase multipath and noise terms are assumed negligible w.r.t. to the code counterparts.'; 'The resulting CMC time series can be eventually detrended by subtracting its mean, thus removing piece-wise constant terms.'};
            app.ScienceProductLabel_8 = uilabel(app.Q1fTab);
            app.ScienceProductLabel_8.Text = {'• [Plot - 160] Single frequency (1F) CMC time series (shifted around 0)'; '• [Plot - 161] Double-frequency (2F) CMC or detrended CMC with outliers detection'; '• [Table - 162] List of outliers with associated PRN, epoch and value'};
            app.Panel_14 = uipanel(app.Q1fTab);
            app.TelemetryInputLamp_3Label_4 = uilabel(app.Panel_14);
            app.TelemetryInputLamp_3Label_4.Text = 'External Input';
            app.TelemetryInputLamp_2Label_6 = uilabel(app.Panel_14);
            app.TelemetryInputLamp_2Label_6.Text = 'Telemetry Input';
            app.TelemetryInputLabel_8 = uilabel(app.Panel_14);
            app.TelemetryInputLabel_8.Text = {'• RAW - Raw pseudoranges'; '• RAW - Carrier phase measurements'};
            app.ExternalInputLabel_8 = uilabel(app.Panel_14);
            app.ExternalInputLabel_8.Text = '• [None]';
            app.MethodologyLabel_14 = uilabel(app.Q1fTab);
            app.MethodologyLabel_14.Text = 'Methodology ';
            app.Experiment1bLabel_14 = uilabel(app.Q1fTab);
            app.Experiment1bLabel_14.Text = 'Expected Science Products';
            app.runPanel_Q1f = uipanel(app.Q1fTab);
            app.RunEditField_5Label_2 = uilabel(app.runPanel_Q1f);
            app.RunEditField_5Label_2.Text = 'Run';
            app.RunEditField_Q1f = uieditfield(app.runPanel_Q1f, 'numeric');
            app.RunEditField_Q1f.Limits = [0 5000];
            app.RunEditField_Q1f.Editable = 'off';
            app.RunEditField_Q1f.Enable = 'off';
            app.OutputPanel_19 = uipanel(app.Q1fTab);
            app.OutputPanel_19.Title = 'Output';
            app.TextArea_Q1f = uitextarea(app.OutputPanel_19);
            app.TextArea_Q1f.Editable = 'off';
            app.TextArea_Q1f.Enable = 'off';
            app.OperatorCommentincludedinthereportPanel_11 = uipanel(app.Q1fTab);
            app.OperatorCommentincludedinthereportPanel_11.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q1f = uitextarea(app.OperatorCommentincludedinthereportPanel_11);
            app.CommentArea_Q1f.Value = {'No relevant comments.'};
            app.GenerateReportButton_Q1f = uibutton(app.Q1fTab, 'push');
            app.GenerateReportButton_Q1f.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q1fPushed, true);
            app.GenerateReportButton_Q1f.Enable = 'off';
            app.GenerateReportButton_Q1f.Text = 'Generate Report';
            app.ReportLamp_Q1f = uilamp(app.Q1fTab);
            app.ResultsLamp_Q1f = uilamp(app.Q1fTab);
            app.SaveResultsButton_Q1f = uibutton(app.Q1fTab, 'push');
            app.SaveResultsButton_Q1f.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q1fPushed, true);
            app.SaveResultsButton_Q1f.Enable = 'off';
            app.SaveResultsButton_Q1f.Text = 'Save Results';
            app.Label_141 = uilabel(app.Q1fTab);
            app.Label_141.Text = {'• The first plot computes single-frequency CMC. The shifts of each curve due to integer ambiguity of the carrier is removed, so each time series starts from value 0. Therefore, the behaviour in time is of interest more than the actual value. Single-frequency CMC can highlight the contribution of the Ionosphere delay.'; '• For epochs in which multi-frequency measurements are available, a dual frequency Code-Minus-Carrier (CMC) is computed in the second plot. The operator can choose to plot either the original or detrended (mean-subtracted by means of first derivative) time series.'; '• Standard deviation is calculated using a rolling window of size selected by the operator, who can also select a threshold for outliers, defined as multiples of the estimated standard deviation. Samples that overcome the threshold are highlighted in plots and collected as potential anomalies in the tables. Outliers in the detrended double-frequency CMC can highlight sudden jumps in the measurements due to multipath'};
            app.SettingsPanel = uipanel(app.Q1fTab);
            app.SettingsPanel.Title = 'Settings';
            app.FCMCButtonGroup = uibuttongroup(app.SettingsPanel);
            app.FCMCButtonGroup.Title = '2F CMC';
            app.RawButton = uiradiobutton(app.FCMCButtonGroup);
            app.RawButton.Tooltip = {'Suggested only to check for jumps due to loss of lock'};
            app.RawButton.Text = 'Raw';
            app.DetrendedButton = uiradiobutton(app.FCMCButtonGroup);
            app.DetrendedButton.Text = 'Detrended';
            app.DetrendedButton.Value = true;
            app.OutliersdetectionButtonGroup = uibuttongroup(app.SettingsPanel);
            app.OutliersdetectionButtonGroup.Title = 'Outliers detection';
            app.WindowSpinnerLabel = uilabel(app.OutliersdetectionButtonGroup);
            app.WindowSpinnerLabel.Text = 'Window';
            app.WindowSpinner = uispinner(app.OutliersdetectionButtonGroup);
            app.WindowSpinner.Step = 10;
            app.WindowSpinner.Limits = [50 200];
            app.WindowSpinner.RoundFractionalValues = 'on';
            app.WindowSpinner.ValueDisplayFormat = '%d';
            app.WindowSpinner.Tooltip = {'Define size of window used for variance estimation'};
            app.WindowSpinner.Value = 100;
            app.ThresholdSpinnerLabel = uilabel(app.OutliersdetectionButtonGroup);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.OutliersdetectionButtonGroup);
            app.ThresholdSpinner.Step = 0.1;
            app.ThresholdSpinner.Limits = [2 5];
            app.ThresholdSpinner.ValueDisplayFormat = '%11.1f';
            app.ThresholdSpinner.Tooltip = {'Define threshold as a multiple of the estimated standard deviation'};
            app.ThresholdSpinner.Value = 4;
            app.UITable_Q1f_L1 = uitable(app.Q1fTab);
            app.UITable_Q1f_L1.ColumnName = {'PRN'; 'Time'; 'Value'};
            app.UITable_Q1f_L1.RowName = {};
            app.UITable_Q1f_L5 = uitable(app.Q1fTab);
            app.UITable_Q1f_L5.ColumnName = {'PRN'; 'Time'; 'Value'};
            app.UITable_Q1f_L5.RowName = {};
            app.L1E1OutliersLabel = uilabel(app.Q1fTab);
            app.L1E1OutliersLabel.Text = 'L1 / E1 Outliers';
            app.L5E5OutliersLabel = uilabel(app.Q1fTab);
            app.L5E5OutliersLabel.Text = 'L5 / E5 Outliers';
            app.Q2aTab = uitab(app.TabGroup3);
            app.Q2aTab.Title = 'Q2.a';
            app.Experiment1aLabel_9 = uilabel(app.Q2aTab);
            app.Experiment1aLabel_9.Text = 'Objective 2 | Demonstrate navigation and time estimation using GNSS data collected at the Moon';
            app.Experiment2aLabel = uilabel(app.Q2aTab);
            app.Experiment2aLabel.Text = 'Experiment Q2.a | Calculate and Characterize Least Squares Multi-GNSS point solutions throughout the mission';
            app.OutputPanel_8 = uipanel(app.Q2aTab);
            app.OutputPanel_8.Title = 'Output';
            app.TextArea_Q2a = uitextarea(app.OutputPanel_8);
            app.TextArea_Q2a.Editable = 'off';
            app.TextArea_Q2a.Enable = 'off';
            app.ExperimentSuccessLamp_Q2a = uilamp(app.Q2aTab);
            app.RunExperimentButton_Q2a = uibutton(app.Q2aTab, 'push');
            app.RunExperimentButton_Q2a.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q2aPushed, true);
            app.RunExperimentButton_Q2a.Enable = 'off';
            app.RunExperimentButton_Q2a.Text = 'Run Experiment';
            app.GenerateReportButton_Q2a = uibutton(app.Q2aTab, 'push');
            app.GenerateReportButton_Q2a.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q2aPushed, true);
            app.GenerateReportButton_Q2a.Enable = 'off';
            app.GenerateReportButton_Q2a.Text = 'Generate Report';
            app.OperatorCommentincludedinthereportPanel_12 = uipanel(app.Q2aTab);
            app.OperatorCommentincludedinthereportPanel_12.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q2a = uitextarea(app.OperatorCommentincludedinthereportPanel_12);
            app.CommentArea_Q2a.Value = {'No relevant comments.'};
            app.ResultsLamp_Q2a = uilamp(app.Q2aTab);
            app.ReportLamp_Q2a = uilamp(app.Q2aTab);
            app.SaveResultsButton_Q2a = uibutton(app.Q2aTab, 'push');
            app.SaveResultsButton_Q2a.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q2aPushed, true);
            app.SaveResultsButton_Q2a.Enable = 'off';
            app.SaveResultsButton_Q2a.Text = 'Save Results';
            app.Label_108 = uilabel(app.Q2aTab);
            app.Label_108.Text = {'This investigation will estimate the BGM1 lander state vector (position, velocity, clock bias), and its associated uncertainties from real-time GNSS observables using the LuGRE receiver’s onboard implementation of the least squares multi-GNSS point solution. This investigation has the following outcomes:'; ''; '•	Demonstrate real time onboard least squares positioning in transit, near the Moon and on the lunar surface.'; '•	Demonstrate multi-GNSS least-squares solution beyond the Space Service Volume (SSV).'; '•	Characterize achieved precision and accuracy onboard and in post-processing (see also investigation Q3a)'; ''; ''; ''};
            app.Panel_10 = uipanel(app.Q2aTab);
            app.TelemetryInputLabel_21 = uilabel(app.Panel_10);
            app.TelemetryInputLabel_21.Text = {'• NAV - Position, Velocity and Clock bias estimates'; '• [Optional] NAV - Number of satellites used '; ''};
            app.ExternalInputLabel_18 = uilabel(app.Panel_10);
            app.ExternalInputLabel_18.Text = '• [None]';
            app.ExternalInputLamp_5Label = uilabel(app.Panel_10);
            app.ExternalInputLamp_5Label.Text = 'External Input';
            app.TelemetryInputLamp_2Label_4 = uilabel(app.Panel_10);
            app.TelemetryInputLamp_2Label_4.Text = 'Telemetry Input';
            app.MethodologyLabel_10 = uilabel(app.Q2aTab);
            app.MethodologyLabel_10.Text = 'Methodology ';
            app.Label_127 = uilabel(app.Q2aTab);
            app.Label_127.Text = {'• The experiment takes mission Telemetry NAV data and provides a visualization of the solutions obtained onboard and associated uncertainties'; '• The operator can choose which reference frame to use for the plots (ECI of ECEF)'};
            app.ScienceProductLabel_17 = uilabel(app.Q2aTab);
            app.ScienceProductLabel_17.Text = {'• [Plot - 200] NAV - On-boad estimated position time series (per-axis subplots) compared to reference data (if available)'; '• [Plot - 201] NAV - On-boad estimated velocity time series (per-axis subplots) compared to reference data (if available)'; '• [Plot - 202] NAV - On-boad estimated clock bias and clock drift time series'; '• [Plot - 203] NAV - On-boad estimated standard deviations (subplots for position, velocity and time estimates)'};
            app.Experiment1bLabel_10 = uilabel(app.Q2aTab);
            app.Experiment1bLabel_10.Text = 'Expected Science Products';
            app.runPanel_6 = uipanel(app.Q2aTab);
            app.RunEditField_6Label = uilabel(app.runPanel_6);
            app.RunEditField_6Label.Text = 'Run';
            app.RunEditField_Q2a = uieditfield(app.runPanel_6, 'numeric');
            app.RunEditField_Q2a.Limits = [0 5000];
            app.RunEditField_Q2a.Editable = 'off';
            app.RunEditField_Q2a.Enable = 'off';
            app.ReferenceFrameSwitchLabel = uilabel(app.Q2aTab);
            app.ReferenceFrameSwitchLabel.Text = 'Reference Frame';
            app.ReferenceFrame_Q2a = uiswitch(app.Q2aTab, 'slider');
            app.ReferenceFrame_Q2a.Items = {'ECI', 'ECEF'};
            app.ReferenceFrame_Q2a.ItemsData = [1 2];
            app.ReferenceFrame_Q2a.Value = 1;
            app.Q2dTab = uitab(app.TabGroup3);
            app.Q2dTab.Title = 'Q2.d';
            app.Experiment1aLabel_16 = uilabel(app.Q2dTab);
            app.Experiment1aLabel_16.Text = 'Objective 2 | Demonstrate navigation and time estimation using GNSS data collected at the Moon';
            app.Experiment2dLabel = uilabel(app.Q2dTab);
            app.Experiment2dLabel.Text = 'Experiment Q2.d | Compute the Dilution of precision from received signals when available';
            app.Label_98 = uilabel(app.Q2dTab);
            app.Label_98.Text = {'The dilution of precision (DOP) identifies a coefficient that regulates the measurements error propagation as a mathematical effect of the relative geometry between observed GNSS satellites and receiver location. It is computed through Jacobian of the set of equations describing the linearized multi-lateration problem. As such, it is calculated based on the position of the satellites used for the fix and the true position of the user (or of a linearisation point sufficiently close to it) and does not depend on the accuracy of the measurements, nor on the selected reference frame. '; ''; 'Under the assumption of zero-mean, independent and identically distributed (i.i.d.) Gaussian measurement errors, the root mean square (RMS) error of the state estimates can be written as the product of the GDOP and the standard deviation of the measurements noise. This experiment aims at collecting the Geometrical DOP, Position DOP, and time DOP values computed by the on-board receiver and compare them to predicted values.'};
            app.OutputPanel_16 = uipanel(app.Q2dTab);
            app.OutputPanel_16.Title = 'Output';
            app.TextArea_Q2d = uitextarea(app.OutputPanel_16);
            app.TextArea_Q2d.Editable = 'off';
            app.TextArea_Q2d.Enable = 'off';
            app.OperatorCommentincludedinthereportPanel_3 = uipanel(app.Q2dTab);
            app.OperatorCommentincludedinthereportPanel_3.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q2d = uitextarea(app.OperatorCommentincludedinthereportPanel_3);
            app.CommentArea_Q2d.Value = {'No relevant comments.'};
            app.ExperimentSuccessLamp_Q2d = uilamp(app.Q2dTab);
            app.RunExperimentButton_Q2d = uibutton(app.Q2dTab, 'push');
            app.RunExperimentButton_Q2d.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q2dPushed, true);
            app.RunExperimentButton_Q2d.Enable = 'off';
            app.RunExperimentButton_Q2d.Text = 'Run Experiment';
            app.GenerateReportButton_Q2d = uibutton(app.Q2dTab, 'push');
            app.GenerateReportButton_Q2d.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q2dPushed, true);
            app.GenerateReportButton_Q2d.Enable = 'off';
            app.GenerateReportButton_Q2d.Text = 'Generate Report';
            app.ResultsLamp_Q2d = uilamp(app.Q2dTab);
            app.ReportLamp_Q2d = uilamp(app.Q2dTab);
            app.SaveResultsButton_Q2d = uibutton(app.Q2dTab, 'push');
            app.SaveResultsButton_Q2d.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q2dPushed, true);
            app.SaveResultsButton_Q2d.Enable = 'off';
            app.SaveResultsButton_Q2d.Text = 'Save Results';
            app.runPanel_Q2d = uipanel(app.Q2dTab);
            app.RunEditField_8Label = uilabel(app.runPanel_Q2d);
            app.RunEditField_8Label.Text = 'Run';
            app.RunEditField_Q2d = uieditfield(app.runPanel_Q2d, 'numeric');
            app.RunEditField_Q2d.Limits = [0 5000];
            app.RunEditField_Q2d.Editable = 'off';
            app.RunEditField_Q2d.Enable = 'off';
            app.Panel_11 = uipanel(app.Q2dTab);
            app.Label_133 = uilabel(app.Panel_11);
            app.Label_133.Text = '• NAV - GDOP, PDOP, TDOP values';
            app.Label_134 = uilabel(app.Panel_11);
            app.Label_134.Text = '• REF - Predicted GDOP, TDOP, PDOP';
            app.TelemetryInputLamp_8Label = uilabel(app.Panel_11);
            app.TelemetryInputLamp_8Label.Text = 'Telemetry Input';
            app.ExternalInputLamp_7Label = uilabel(app.Panel_11);
            app.ExternalInputLamp_7Label.Text = 'External Input';
            app.Label_135 = uilabel(app.Q2dTab);
            app.Label_135.Text = {'• [Plot - 230a] DOP metrics time series from Telemetry NAV, i.e., GDOP, PDOP and TDOP '; '• [Plot - 230b] Overall number of tracked signals (channels) from Telemetry NAV'};
            app.Experiment1bLabel_11 = uilabel(app.Q2dTab);
            app.Experiment1bLabel_11.Text = 'Expected Science Products';
            app.runPanel_8 = uipanel(app.Q2dTab);
            app.StartingEpochEditFieldLabel = uilabel(app.runPanel_8);
            app.StartingEpochEditFieldLabel.Text = 'Starting Epoch';
            app.StartingEpochEditField = uieditfield(app.runPanel_8, 'numeric');
            app.StartingEpochEditField.Limits = [1 500];
            app.StartingEpochEditField.RoundFractionalValues = 'on';
            app.StartingEpochEditField.Tooltip = {'DOP may be very large at the beginning of the dataset. In that case, the operator may choose to skip the first few epochs for better visibility.'; ''; 'PVT rate is 1Hz (1 epoch = 1 second)'};
            app.StartingEpochEditField.Value = 1;
            app.SettingsLabel = uilabel(app.runPanel_8);
            app.SettingsLabel.Text = 'Settings';
            app.MethodologyLabel_12 = uilabel(app.Q2dTab);
            app.MethodologyLabel_12.Text = 'Methodology ';
            app.TimeseriesofDOPmetricsfromtheTelemetryisplottedLabel = uilabel(app.Q2dTab);
            app.TimeseriesofDOPmetricsfromtheTelemetryisplottedLabel.Text = {'• Time-series of DOP metrics from the Telemetry is plotted '; '• Time-series of number of tracked channels across all constellations from th Telemetry is plotted'; '• Since the receiver does not acquire all satellites at once, the number of tracked signals at the beginning may be very low and thus the GDOP very large. The operator can decide to skip epochs at the beginning for visualization purposes'};
            app.Q3bTab = uitab(app.TabGroup3);
            app.Q3bTab.Title = 'Q3.b';
            app.Experiment1aLabel_14 = uilabel(app.Q3bTab);
            app.Experiment1aLabel_14.Text = 'Objective 3 | Utilize collected Data to support development of GNSS receivers specific to lunar use';
            app.Experiment3bLabel = uilabel(app.Q3bTab);
            app.Experiment3bLabel.Text = 'Experiment Q3.b | Process GNSS IQ samples with independent software/hardware receivers';
            app.ExperimentSuccessLamp_Q3b = uilamp(app.Q3bTab);
            app.RunExperimentButton_Q3b = uibutton(app.Q3bTab, 'push');
            app.RunExperimentButton_Q3b.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButton_Q3bPushed, true);
            app.RunExperimentButton_Q3b.Enable = 'off';
            app.RunExperimentButton_Q3b.Text = 'Run Experiment';
            app.Label_115 = uilabel(app.Q3bTab);
            app.Label_115.Text = {'This investigation assesses the replicability of onboard receiver''s GNSS signal processing (at radio-frequency level) by leveraging raw batches of In-Phase and Quadrature signal samples collected by the LuGRE payload during Sample Capture (SC) operations. A time-frequency analyzer is in charge of providing an overview of signal characteristics while an acquisition routine is in charge of post-processing the batches both for L1/E1 and L5/E5. Different configurations parameters are available to optimize acquisition performance. This quick investigation supports: '; ''; '•	An analysis of possible signal distortions due to unexpected dynamics of the signals, by means of estimated probability densities of samples.'; '•	An analysis of the spectral behavior and presence of potential outliers due to propagation environment, RFIs or front-end failures. '; '•	An assessment of the telemetry data obtained on-board during subsequent RTP operations (i.e., initial ACQ C/No and Doppler shift estimate)'};
            app.OutputPanel_21 = uipanel(app.Q3bTab);
            app.OutputPanel_21.Title = 'Output';
            app.TextArea_Q3b = uitextarea(app.OutputPanel_21);
            app.TextArea_Q3b.Editable = 'off';
            app.TextArea_Q3b.Enable = 'off';
            app.OperatorCommentincludedinthereportPanel_9 = uipanel(app.Q3bTab);
            app.OperatorCommentincludedinthereportPanel_9.Title = 'Operator Comment (included in the report)';
            app.CommentArea_Q3b = uitextarea(app.OperatorCommentincludedinthereportPanel_9);
            app.CommentArea_Q3b.Value = {'No relevant comments.'};
            app.GenerateReportButton_Q3b = uibutton(app.Q3bTab, 'push');
            app.GenerateReportButton_Q3b.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_Q3bPushed, true);
            app.GenerateReportButton_Q3b.Enable = 'off';
            app.GenerateReportButton_Q3b.Tooltip = {'Generating report will quickly open and close MS Word '};
            app.GenerateReportButton_Q3b.Text = 'Generate Report';
            app.ResultsLamp_Q3b = uilamp(app.Q3bTab);
            app.ReportLamp_Q3b = uilamp(app.Q3bTab);
            app.SaveResultsButton_Q3b = uibutton(app.Q3bTab, 'push');
            app.SaveResultsButton_Q3b.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButton_Q3bPushed, true);
            app.SaveResultsButton_Q3b.Enable = 'off';
            app.SaveResultsButton_Q3b.Text = 'Save Results';
            app.runPanel_Q3b = uipanel(app.Q3bTab);
            app.RunEditField_8Label_3 = uilabel(app.runPanel_Q3b);
            app.RunEditField_8Label_3.Text = 'Run';
            app.RunEditField_Q3b = uieditfield(app.runPanel_Q3b, 'numeric');
            app.RunEditField_Q3b.Limits = [0 5000];
            app.RunEditField_Q3b.Editable = 'off';
            app.RunEditField_Q3b.Enable = 'off';
            app.Panel_16 = uipanel(app.Q3bTab);
            app.Label_143 = uilabel(app.Panel_16);
            app.Label_143.Text = {'• ACQ - Acquired satellites PRNs'; '• ACQ - C/No measurements'; '• ACQ - Doppler shift estimates'; '• IQS - L1/E1 IQ samples batch'};
            app.Label_144 = uilabel(app.Panel_16);
            app.Label_144.Text = '• Not required';
            app.TelemetryInputLampLabel_3 = uilabel(app.Panel_16);
            app.TelemetryInputLampLabel_3.Text = 'Telemetry Input';
            app.ExternalInputLamp_2Label_3 = uilabel(app.Panel_16);
            app.ExternalInputLamp_2Label_3.Text = 'External Input';
            app.MethodologyLabel_16 = uilabel(app.Q3bTab);
            app.MethodologyLabel_16.Text = 'Methodology ';
            app.Label_145 = uilabel(app.Q3bTab);
            app.Label_145.Text = {'• The acquisition stage processes the initial 50 ms of the IQS batch to provide time-domain and frequency-domain analysis'; '• IQ samples are used to estimate C/No and Doppler shifts, which are compared to the ACQ counterparts obtained by the payload operating in mixed mode'};
            app.ScienceProductLabel_23 = uilabel(app.Q3bTab);
            app.ScienceProductLabel_23.Text = {'[Plot - 310] - Probe data including 50 ms time-series, histogram of quantized values, samples distribution with Gaussian fit check '; '[Plot - 311 & 313] - Acquisition results with estimated C/No for L1 and E1 respectively'; '[Table - 312 & 314] - Comparison of on-board estimated C/No and Doppler shift (from ACQ data) and post-processed outcomes for L1 and E1 respectively'; ''};
            app.Experiment1bLabel_16 = uilabel(app.Q3bTab);
            app.Experiment1bLabel_16.Text = 'Expected Science Products';
            app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2 = uipanel(app.Q3bTab);
            app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2.Title = 'IQS processing - Software Receiver Settings (Acquisition)';
            app.DopplerstepHzEditFieldLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.DopplerstepHzEditFieldLabel.Text = 'Doppler step [Hz]';
            app.DopplerstepHzEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.DopplerstepHzEditField.Limits = [1 500];
            app.DopplerstepHzEditField.Value = 50;
            app.NonCoherentsumsEditField_8Label = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.NonCoherentsumsEditField_8Label.Text = 'Non-Coherent sums';
            app.NonCoherentsumsEditField_8 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.NonCoherentsumsEditField_8.Limits = [1 20];
            app.NonCoherentsumsEditField_8.Value = 1;
            app.CoherentIntegrationtimesEditFieldLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.CoherentIntegrationtimesEditFieldLabel.Text = 'Coherent Integration time [s]';
            app.CoherentIntegrationtimesEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.CoherentIntegrationtimesEditField.Limits = [0.001 0.02];
            app.CoherentIntegrationtimesEditField.Value = 0.001;
            app.FalseAlarmProbabilityEditField_8Label = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.FalseAlarmProbabilityEditField_8Label.Text = 'False Alarm Probability';
            app.FalseAlarmProbabilityEditField_8 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.FalseAlarmProbabilityEditField_8.Limits = [0.001 0.005];
            app.FalseAlarmProbabilityEditField_8.Value = 0.001;
            app.G1CCheckBox_2 = uicheckbox(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.G1CCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @G1CCheckBox_2ValueChanged, true);
            app.G1CCheckBox_2.Text = 'G1C';
            app.G1CCheckBox_2.Value = true;
            app.E1BCheckBox_2 = uicheckbox(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.E1BCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @G1CCheckBox_2ValueChanged, true);
            app.E1BCheckBox_2.Text = 'E1B';
            app.E1BCheckBox_2.Value = true;
            app.DopplerStepEditField_12 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.DopplerStepEditField_12.Limits = [1 500];
            app.DopplerStepEditField_12.Value = 50;
            app.NonCoherentsumsEditField_12 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.NonCoherentsumsEditField_12.Limits = [1 20];
            app.NonCoherentsumsEditField_12.Value = 1;
            app.CoherentIntegrationtimeEditField_12 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.CoherentIntegrationtimeEditField_12.Limits = [0.004 0.02];
            app.CoherentIntegrationtimeEditField_12.Value = 0.004;
            app.FalseAlarmProbabilityEditField_12 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.FalseAlarmProbabilityEditField_12.Limits = [0.001 0.005];
            app.FalseAlarmProbabilityEditField_12.Value = 0.001;
            app.SelectGNSSsignalsandacquisitionparametersLabel_2 = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.SelectGNSSsignalsandacquisitionparametersLabel_2.Text = 'Select GNSS signals and acquisition parameters';
            app.TelemetryInputLamp_4Label_6 = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.TelemetryInputLamp_4Label_6.Text = 'GPS L1';
            app.TelemetryInputLamp_4Label_7 = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.TelemetryInputLamp_4Label_7.Text = 'Gal E1';
            app.DopplerStepEditField_22 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.DopplerStepEditField_22.Limits = [-10000 10000];
            app.DopplercenteroffsetHzEditFieldLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.DopplercenteroffsetHzEditFieldLabel.Text = 'Doppler center offset [Hz]';
            app.DopplercenteroffsetHzEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.DopplercenteroffsetHzEditField.Limits = [-10000 10000];
            app.DopplerStepEditField_23 = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.DopplerStepEditField_23.Limits = [0 50000];
            app.DopplerStepEditField_23.ValueDisplayFormat = '%d';
            app.DopplerStepEditField_23.Value = 40000;
            app.DopplerrangeHzEditFieldLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.DopplerrangeHzEditFieldLabel.Text = 'Doppler range [Hz]';
            app.DopplerrangeHzEditField = uieditfield(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2, 'numeric');
            app.DopplerrangeHzEditField.Limits = [0 50000];
            app.DopplerrangeHzEditField.ValueDisplayFormat = '%d';
            app.DopplerrangeHzEditField.Value = 40000;
            app.CodeDopplerSearchLabel = uilabel(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.CodeDopplerSearchLabel.Text = 'Code Doppler Search*';
            app.CodeDopplerCheckBoxG1C = uicheckbox(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.CodeDopplerCheckBoxG1C.Text = '';
            app.CodeDopplerCheckBoxE1B = uicheckbox(app.GNSSSoftwareReceiverSettingsAcquisitionStagePanel_2);
            app.CodeDopplerCheckBoxE1B.Text = '';
            app.UITable_Q3b_GPS = uitable(app.Q3bTab);
            app.UITable_Q3b_GPS.ColumnName = {'PRN'; 'TLM C/No [dB-Hz]'; 'IQS C/No [dB-Hz]'; 'TLM Dopp. [Hz]'; 'IQS Dopp. [Hz]'};
            app.UITable_Q3b_GPS.RowName = {};
            app.UITable_Q3b_Galileo = uitable(app.Q3bTab);
            app.UITable_Q3b_Galileo.ColumnName = {'PRN'; 'TLM C/No [dB-Hz]'; 'IQS C/No [dB-Hz]'; 'TLM Dopp. [Hz]'; 'IQS Dopp. [Hz]'};
            app.UITable_Q3b_Galileo.RowName = {};
            app.GPSL1CALabel = uilabel(app.Q3bTab);
            app.GPSL1CALabel.Text = 'GPS L1 C/A';
            app.GalE1bLabel = uilabel(app.Q3bTab);
            app.GalE1bLabel.Text = 'Gal E1b';
            app.WARNINGLabel_q1d_3 = uilabel(app.Q3bTab);
            app.WARNINGLabel_q1d_3.Text = '*WARNING: The experiment may last for tens of minutes based on the configuration';
            app.LuGRENavigationAnalysisandReportingToolLabel_2 = uilabel(app.ScientificExperimentsTab);
            app.LuGRENavigationAnalysisandReportingToolLabel_2.Text = ' LuGRE Navigation Analysis and Reporting Tool';
            app.VisualizationandAnalysisToolfortheLuGREMissionLabel_9 = uilabel(app.ScientificExperimentsTab);
            app.VisualizationandAnalysisToolfortheLuGREMissionLabel_9.Text = 'NavSAS - Politecnico di Torino | Department of Electronics and Telecommunications';
            app.OpsWindowLabel = uilabel(app.ScientificExperimentsTab);
            app.OpsWindowLabel.Text = 'Ops Window';
            app.expOpsWinField = uieditfield(app.ScientificExperimentsTab, 'text');
            app.expOpsWinField.Editable = 'off';
            app.expOpsWinField.Value = 'Default';
            app.OperatorEditFieldLabel_2 = uilabel(app.ScientificExperimentsTab);
            app.OperatorEditFieldLabel_2.Text = 'Operator';
            app.expOperatorField = uieditfield(app.ScientificExperimentsTab, 'text');
            app.expOperatorField.Editable = 'off';
            app.expOperatorField.Value = 'Default';
            app.Q1a_completed = uilamp(app.ScientificExperimentsTab);
            app.Q1d_completed = uilamp(app.ScientificExperimentsTab);
            app.Q1c_completed = uilamp(app.ScientificExperimentsTab);
            app.Q1b_completed = uilamp(app.ScientificExperimentsTab);
            app.Q1e_completed = uilamp(app.ScientificExperimentsTab);
            app.Q2a_completed = uilamp(app.ScientificExperimentsTab);
            app.Q1f_completed = uilamp(app.ScientificExperimentsTab);
            app.Q2d_completed = uilamp(app.ScientificExperimentsTab);
            app.Q3b_completed = uilamp(app.ScientificExperimentsTab);
            app.OperationSummaryReportOSRTab = uitab(app.TabGroup);
            app.OperationSummaryReportOSRTab.Title = 'Operation Summary Report (OSR)';
            app.ReportselectionPanel = uipanel(app.OperationSummaryReportOSRTab);
            app.ReportselectionPanel.Title = 'Report selection';
            app.Q1aListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1aListBoxLabel.Text = 'Q1.a';
            app.ListBox_Q1a = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1a.Items = {};
            app.ListBox_Q1a.Value = {};
            app.OpenReport_Q1a = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1a.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1aPushed, true);
            app.OpenReport_Q1a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1a.Text = '';
            app.AddReport_Q1a = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1a.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1aButtonPushed, true);
            app.AddReport_Q1a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1a.Text = '';
            app.DeleteReport_Q1a = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q1a.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q1aPushed, true);
            app.DeleteReport_Q1a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q1a.Text = '';
            app.OpenReport_Q1b = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1b.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1bPushed, true);
            app.OpenReport_Q1b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1b.Text = '';
            app.AddReport_Q1b = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1b.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1bPushed, true);
            app.AddReport_Q1b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1b.Text = '';
            app.DeleteReport_Q1b = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q1b.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q1bPushed, true);
            app.DeleteReport_Q1b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q1b.Text = '';
            app.Q1bListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1bListBoxLabel.Text = 'Q1.b';
            app.ListBox_Q1b = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1b.Items = {};
            app.ListBox_Q1b.Value = {};
            app.OpenReport_Q1c = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1c.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1cPushed, true);
            app.OpenReport_Q1c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1c.Text = '';
            app.AddReport_Q1c = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1c.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1cPushed, true);
            app.AddReport_Q1c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1c.Text = '';
            app.DeleteReport_Q1c = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q1c.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q1cPushed, true);
            app.DeleteReport_Q1c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q1c.Text = '';
            app.Q1cListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1cListBoxLabel.Text = 'Q1.c';
            app.ListBox_Q1c = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1c.Items = {};
            app.ListBox_Q1c.Value = {};
            app.OpenReport_Q1d = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1d.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1dPushed, true);
            app.OpenReport_Q1d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1d.Text = '';
            app.AddReport_Q1d = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1d.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1dPushed, true);
            app.AddReport_Q1d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1d.Text = '';
            app.DeleteReportQ1d = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReportQ1d.ButtonPushedFcn = createCallbackFcn(app, @DeleteReportQ1dPushed, true);
            app.DeleteReportQ1d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReportQ1d.Text = '';
            app.Q1dListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1dListBoxLabel.Text = 'Q1.d';
            app.ListBox_Q1d = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1d.Items = {};
            app.ListBox_Q1d.Value = {};
            app.OpenReport_Q1e = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1e.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1ePushed, true);
            app.OpenReport_Q1e.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1e.Text = '';
            app.AddReport_Q1e = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1e.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1ePushed, true);
            app.AddReport_Q1e.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1e.Text = '';
            app.DeleteReport_Q1e = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q1e.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q1ePushed, true);
            app.DeleteReport_Q1e.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q1e.Text = '';
            app.Q1eListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1eListBoxLabel.Text = 'Q1.e';
            app.ListBox_Q1e = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1e.Items = {};
            app.ListBox_Q1e.Value = {};
            app.OpenReport_Q1f = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q1f.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q1fPushed, true);
            app.OpenReport_Q1f.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q1f.Text = '';
            app.AddReport_Q1f = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q1f.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q1fPushed, true);
            app.AddReport_Q1f.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q1f.Text = '';
            app.DeleteReport_Q1f = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q1f.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q1fPushed, true);
            app.DeleteReport_Q1f.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q1f.Text = '';
            app.Q1fListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q1fListBoxLabel.Text = 'Q1.f';
            app.ListBox_Q1f = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q1f.Items = {};
            app.ListBox_Q1f.Value = {};
            app.OpenReport_Q2a = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q2a.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q2aPushed, true);
            app.OpenReport_Q2a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q2a.Text = '';
            app.AddReport_Q2a = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q2a.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q2aPushed, true);
            app.AddReport_Q2a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q2a.Text = '';
            app.DeleteReport_Q2a = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q2a.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q2aPushed, true);
            app.DeleteReport_Q2a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q2a.Text = '';
            app.OpenReport_Q2c = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q2c.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q2cPushed, true);
            app.OpenReport_Q2c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q2c.Text = '';
            app.AddReport_Q2c = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q2c.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q2cPushed, true);
            app.AddReport_Q2c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q2c.Text = '';
            app.DeleteReport_Q2c = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q2c.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q2cPushed, true);
            app.DeleteReport_Q2c.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q2c.Text = '';
            app.OpenReport_Q2d = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q2d.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q2dPushed, true);
            app.OpenReport_Q2d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q2d.Text = '';
            app.AddReport_Q2d = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q2d.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q2dPushed, true);
            app.AddReport_Q2d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q2d.Text = '';
            app.DeleteReport_Q2d = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q2d.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q2dPushed, true);
            app.DeleteReport_Q2d.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q2d.Text = '';
            app.Q2aListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q2aListBoxLabel.Text = 'Q2.a';
            app.ListBox_Q2a = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q2a.Items = {};
            app.ListBox_Q2a.Value = {};
            app.Q2cListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q2cListBoxLabel.Text = 'Q2.c';
            app.ListBox_Q2c = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q2c.Items = {};
            app.ListBox_Q2c.Value = {};
            app.Q2dListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q2dListBoxLabel.Text = 'Q2.d';
            app.ListBox_Q2d = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q2d.Items = {};
            app.ListBox_Q2d.Value = {};
            app.OpenReport_Q3a = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q3a.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q3aPushed, true);
            app.OpenReport_Q3a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q3a.Text = '';
            app.AddReport_Q3a = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q3a.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q3aPushed, true);
            app.AddReport_Q3a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q3a.Text = '';
            app.DeleteReport_Q3a = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q3a.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q3aPushed, true);
            app.DeleteReport_Q3a.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q3a.Text = '';
            app.OpenReport_Q3b = uibutton(app.ReportselectionPanel, 'push');
            app.OpenReport_Q3b.ButtonPushedFcn = createCallbackFcn(app, @OpenReport_Q3bPushed, true);
            app.OpenReport_Q3b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-visualizza-file-30.png');
            app.OpenReport_Q3b.Text = '';
            app.AddReport_Q3b = uibutton(app.ReportselectionPanel, 'push');
            app.AddReport_Q3b.ButtonPushedFcn = createCallbackFcn(app, @AddReport_Q3bPushed, true);
            app.AddReport_Q3b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-aggiungi-32.png');
            app.AddReport_Q3b.Text = '';
            app.DeleteReport_Q3b = uibutton(app.ReportselectionPanel, 'push');
            app.DeleteReport_Q3b.ButtonPushedFcn = createCallbackFcn(app, @DeleteReport_Q3bPushed, true);
            app.DeleteReport_Q3b.Icon = fullfile(pathToMLAPP, 'images', 'icons', 'icons8-cestino-48.png');
            app.DeleteReport_Q3b.Text = '';
            app.Q3aListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q3aListBoxLabel.Text = 'Q3.a';
            app.ListBox_Q3a = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q3a.Items = {};
            app.ListBox_Q3a.Value = {};
            app.Q3bListBoxLabel = uilabel(app.ReportselectionPanel);
            app.Q3bListBoxLabel.Text = 'Q3.b';
            app.ListBox_Q3b = uilistbox(app.ReportselectionPanel);
            app.ListBox_Q3b.Items = {};
            app.ListBox_Q3b.Value = {};
            app.Objective1Label = uilabel(app.ReportselectionPanel);
            app.Objective1Label.Text = 'Objective 1';
            app.Objective2Label = uilabel(app.ReportselectionPanel);
            app.Objective2Label.Text = 'Objective 2';
            app.Objective3Label = uilabel(app.ReportselectionPanel);
            app.Objective3Label.Text = 'Objective 3';
            app.RestorelistsButton = uibutton(app.ReportselectionPanel, 'push');
            app.RestorelistsButton.ButtonPushedFcn = createCallbackFcn(app, @RestorelistsButtonPushed, true);
            app.RestorelistsButton.Text = 'Restore lists';
            app.OPSSummaryReportOSRPanel = uipanel(app.OperationSummaryReportOSRTab);
            app.OPSSummaryReportOSRPanel.Title = 'OPS Summary Report (OSR)';
            app.GenerateReportButton_OSR = uibutton(app.OPSSummaryReportOSRPanel, 'push');
            app.GenerateReportButton_OSR.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButton_OSRPushed, true);
            app.GenerateReportButton_OSR.Tooltip = {'Generate word and pdf report associated to the current run.'};
            app.GenerateReportButton_OSR.Text = 'Generate OSR';
            app.OSRLamp = uilamp(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1a = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1a.Text = 'Q1.a';
            app.CheckBox_Q1b = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1b.Text = 'Q1.b';
            app.CheckBox_Q1d = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1d.Text = 'Q1.d';
            app.CheckBox_Q1e = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1e.Text = 'Q1.e';
            app.CheckBox_Q1f = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1f.Text = 'Q1.f';
            app.CheckBox_Q2a = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q2a.Text = 'Q2.a';
            app.CheckBox_Q2c = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q2c.Text = 'Q2.c';
            app.CheckBox_Q3a = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q3a.Text = 'Q3.a';
            app.CheckBox_Q3b = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q3b.Text = 'Q3.b';
            app.Objective3Label_3 = uilabel(app.OPSSummaryReportOSRPanel);
            app.Objective3Label_3.Text = 'Objective 3';
            app.Objective2Label_2 = uilabel(app.OPSSummaryReportOSRPanel);
            app.Objective2Label_2.Text = 'Objective 2';
            app.Objective1Label_2 = uilabel(app.OPSSummaryReportOSRPanel);
            app.Objective1Label_2.Text = 'Objective 1';
            app.EditField_Q1a_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1a_OSR.Enable = 'off';
            app.EditField_Q1b_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1b_OSR.Enable = 'off';
            app.EditField_Q1c_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1c_OSR.Enable = 'off';
            app.EditField_Q1e_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1e_OSR.Enable = 'off';
            app.EditField_Q1f_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1f_OSR.Enable = 'off';
            app.EditField_Q2a_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q2a_OSR.Enable = 'off';
            app.EditField_Q2c_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q2c_OSR.Enable = 'off';
            app.EditField_Q3a_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q3a_OSR.Enable = 'off';
            app.EditField_Q3b_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q3b_OSR.Enable = 'off';
            app.CheckBox_Q2d = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q2d.Text = 'Q2.d';
            app.EditField_Q2d_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q2d_OSR.Enable = 'off';
            app.EditField_Q1d_OSR = uieditfield(app.OPSSummaryReportOSRPanel, 'text');
            app.EditField_Q1d_OSR.Enable = 'off';
            app.CheckBox_Q1c = uicheckbox(app.OPSSummaryReportOSRPanel);
            app.CheckBox_Q1c.Text = 'Q1.c';
            app.OSR_operatorComments = uipanel(app.OPSSummaryReportOSRPanel);
            app.OSR_operatorComments.Title = 'Operator Comment (included in the OSR first page)';
            app.OSR_comments = uitextarea(app.OSR_operatorComments);
            app.OSR_comments.Value = {'No relevant comments.'};

            app.OperatorEditFieldLabel_3 = uilabel(app.OperationSummaryReportOSRTab);
            app.OperatorEditFieldLabel_3.Text = 'Operator';
            app.expOperatorField_2 = uieditfield(app.OperationSummaryReportOSRTab, 'text');
            app.expOperatorField_2.Editable = 'off';
            app.expOperatorField_2.Value = 'Default';
            app.OpsWindowLabel_2 = uilabel(app.OperationSummaryReportOSRTab);
            app.OpsWindowLabel_2.Text = 'Ops Window';
            app.expOpsWinField_2 = uieditfield(app.OperationSummaryReportOSRTab, 'text');
            app.expOpsWinField_2.Editable = 'off';
            app.expOpsWinField_2.Value = 'Default';
            app.LuGRENavigationAnalysisandReportingToolLabel_3 = uilabel(app.OperationSummaryReportOSRTab);
            app.LuGRENavigationAnalysisandReportingToolLabel_3.Text = 'LuGRE Navigation Analysis and Reporting Tool';
            app.VisualizationandAnalysisToolfortheLuGREMissionLabel_14 = uilabel(app.OperationSummaryReportOSRTab);
            app.VisualizationandAnalysisToolfortheLuGREMissionLabel_14.Text = 'NavSAS - Politecnico di Torino | Department of Electronics and Telecommunications';
            % Show the figure after all components are created
            app.AnalysisToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalysisToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisToolUIFigure)
        end
    end
end